classdef MicroXRF_APP_26_03_2026_WERSJA_LINIOWA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolyXCalibrationAppUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SetCalibrationTab              matlab.ui.container.Tab
        GridLayout32                   matlab.ui.container.GridLayout
        GridLayout39                   matlab.ui.container.GridLayout
        GridLayout40                   matlab.ui.container.GridLayout
        GridLayout44                   matlab.ui.container.GridLayout
        FANO2_sensitivity_tab_value    matlab.ui.control.EditField
        GAIN_1texteVLabel_6            matlab.ui.control.Label
        NOISE2_sensitivity_tab_value   matlab.ui.control.EditField
        GAIN_1texteVLabel_4            matlab.ui.control.Label
        GridLayout43                   matlab.ui.container.GridLayout
        FANO1_sensitivity_tab_value    matlab.ui.control.EditField
        GAIN_1texteVLabel_5            matlab.ui.control.Label
        NOISE1_sensitivity_tab_value   matlab.ui.control.EditField
        GAIN_1texteVLabel_3            matlab.ui.control.Label
        GridLayout42                   matlab.ui.container.GridLayout
        ZERO2_sensitivity_tab_value    matlab.ui.control.EditField
        ZERO_2texteVLabel              matlab.ui.control.Label
        GAIN2_sensitivity_tab_value    matlab.ui.control.EditField
        GAIN_1texteVLabel_2            matlab.ui.control.Label
        GridLayout41                   matlab.ui.container.GridLayout
        ZERO1_sensitivity_tab_value    matlab.ui.control.EditField
        ZERO_1eVLabel                  matlab.ui.control.Label
        GAIN1_sensitivity_tab_value    matlab.ui.control.EditField
        GAIN_1texteVLabel              matlab.ui.control.Label
        AvailableStandardsPanel        matlab.ui.container.Panel
        L_alpha_Pb                     matlab.ui.control.Button
        Lamp_Pb_3                      matlab.ui.control.Lamp
        L_beta_Pb                      matlab.ui.control.Button
        M_alpha_Pb                     matlab.ui.control.Button
        Lamp_Pb_4                      matlab.ui.control.Lamp
        Lamp_Pb_5                      matlab.ui.control.Lamp
        L_alpha_Au                     matlab.ui.control.Button
        Lamp_Au_3                      matlab.ui.control.Lamp
        L_beta_Au                      matlab.ui.control.Button
        M_alpha_Au                     matlab.ui.control.Button
        Lamp_Au_4                      matlab.ui.control.Lamp
        Lamp_Au_5                      matlab.ui.control.Lamp
        L_alpha_W                      matlab.ui.control.Button
        Lamp_W_3                       matlab.ui.control.Lamp
        L_beta_W                       matlab.ui.control.Button
        M_alpha_W                      matlab.ui.control.Button
        Lamp_W_4                       matlab.ui.control.Lamp
        Lamp_W_5                       matlab.ui.control.Lamp
        L_alpha_Yb                     matlab.ui.control.Button
        Lamp_Yb_3                      matlab.ui.control.Lamp
        L_beta_Yb                      matlab.ui.control.Button
        M_alpha_Yb                     matlab.ui.control.Button
        Lamp_Yb_4                      matlab.ui.control.Lamp
        Lamp_Yb_5                      matlab.ui.control.Lamp
        L_alpha_Dy                     matlab.ui.control.Button
        Lamp_Dy_3                      matlab.ui.control.Lamp
        L_beta_Dy                      matlab.ui.control.Button
        M_alpha_Dy                     matlab.ui.control.Button
        Lamp_Dy_4                      matlab.ui.control.Lamp
        Lamp_Dy_5                      matlab.ui.control.Lamp
        L_alpha_Sm                     matlab.ui.control.Button
        Lamp_Sm_3                      matlab.ui.control.Lamp
        L_beta_Sm                      matlab.ui.control.Button
        M_alpha_Sm                     matlab.ui.control.Button
        Lamp_Sm_4                      matlab.ui.control.Lamp
        Lamp_Sm_5                      matlab.ui.control.Lamp
        L_alpha_Ce                     matlab.ui.control.Button
        Lamp_Ce_3                      matlab.ui.control.Lamp
        L_beta_Ce                      matlab.ui.control.Button
        M_alpha_Ce                     matlab.ui.control.Button
        Lamp_Ce_4                      matlab.ui.control.Lamp
        Lamp_Ce_5                      matlab.ui.control.Lamp
        L_alpha_Cs                     matlab.ui.control.Button
        Lamp_Cs_3                      matlab.ui.control.Lamp
        L_beta_Cs                      matlab.ui.control.Button
        Lamp_Cs_4                      matlab.ui.control.Lamp
        L_alpha_I                      matlab.ui.control.Button
        Lamp_I_3                       matlab.ui.control.Lamp
        L_beta_I                       matlab.ui.control.Button
        Lamp_I_4                       matlab.ui.control.Lamp
        L_alpha_Te                     matlab.ui.control.Button
        Lamp_Te_3                      matlab.ui.control.Lamp
        L_beta_Te                      matlab.ui.control.Button
        Lamp_Te_4                      matlab.ui.control.Lamp
        L_alpha_In                     matlab.ui.control.Button
        Lamp_In_3                      matlab.ui.control.Lamp
        L_beta_In                      matlab.ui.control.Button
        Lamp_In_4                      matlab.ui.control.Lamp
        L_alpha_Rh                     matlab.ui.control.Button
        Lamp_Rh_3                      matlab.ui.control.Lamp
        L_beta_Rh                      matlab.ui.control.Button
        Lamp_Rh_4                      matlab.ui.control.Lamp
        K_alpha_Ga                     matlab.ui.control.Button
        Lamp_Ga_1                      matlab.ui.control.Lamp
        K_beta_Ga                      matlab.ui.control.Button
        L_alpha_Ga                     matlab.ui.control.Button
        Lamp_Ga_2                      matlab.ui.control.Lamp
        Lamp_Ga_3                      matlab.ui.control.Lamp
        K_alpha_As                     matlab.ui.control.Button
        Lamp_As_1                      matlab.ui.control.Lamp
        K_beta_As                      matlab.ui.control.Button
        L_alpha_As                     matlab.ui.control.Button
        Lamp_As_2                      matlab.ui.control.Lamp
        Lamp_As_3                      matlab.ui.control.Lamp
        K_alpha_Se                     matlab.ui.control.Button
        Lamp_Se_1                      matlab.ui.control.Lamp
        K_beta_Se                      matlab.ui.control.Button
        L_alpha_Se                     matlab.ui.control.Button
        Lamp_Se_2                      matlab.ui.control.Lamp
        Lamp_Se_3                      matlab.ui.control.Lamp
        K_alpha_Br                     matlab.ui.control.Button
        Lamp_Br_1                      matlab.ui.control.Lamp
        K_beta_Br                      matlab.ui.control.Button
        L_alpha_Br                     matlab.ui.control.Button
        Lamp_Br_2                      matlab.ui.control.Lamp
        Lamp_Br_3                      matlab.ui.control.Lamp
        K_alpha_Rb                     matlab.ui.control.Button
        Lamp_Rb_1                      matlab.ui.control.Lamp
        K_beta_Rb                      matlab.ui.control.Button
        L_alpha_Rb                     matlab.ui.control.Button
        Lamp_Rb_2                      matlab.ui.control.Lamp
        Lamp_Rb_3                      matlab.ui.control.Lamp
        K_alpha_Y                      matlab.ui.control.Button
        Lamp_Y_1                       matlab.ui.control.Lamp
        K_beta_Y                       matlab.ui.control.Button
        L_alpha_Y                      matlab.ui.control.Button
        Lamp_Y_2                       matlab.ui.control.Lamp
        Lamp_Y_3                       matlab.ui.control.Lamp
        K_alpha_Na                     matlab.ui.control.Button
        Lamp_Na_1                      matlab.ui.control.Lamp
        K_alpha_Mg                     matlab.ui.control.Button
        Lamp_Mg_1                      matlab.ui.control.Lamp
        K_alpha_Al                     matlab.ui.control.Button
        Lamp_Al_1                      matlab.ui.control.Lamp
        K_alpha_Si                     matlab.ui.control.Button
        Lamp_Si_1                      matlab.ui.control.Lamp
        K_alpha_P                      matlab.ui.control.Button
        Lamp_P_1                       matlab.ui.control.Lamp
        K_alpha_S                      matlab.ui.control.Button
        Lamp_S_1                       matlab.ui.control.Lamp
        K_alpha_Cl                     matlab.ui.control.Button
        Lamp_Cl_1                      matlab.ui.control.Lamp
        K_beta_Cl                      matlab.ui.control.Button
        Lamp_Cl_2                      matlab.ui.control.Lamp
        K_alpha_K                      matlab.ui.control.Button
        Lamp_K_1                       matlab.ui.control.Lamp
        K_beta_K                       matlab.ui.control.Button
        Lamp_K_2                       matlab.ui.control.Lamp
        K_alpha_Ca                     matlab.ui.control.Button
        Lamp_Ca_1                      matlab.ui.control.Lamp
        K_beta_Ca                      matlab.ui.control.Button
        Lamp_Ca_2                      matlab.ui.control.Lamp
        K_alpha_Ti                     matlab.ui.control.Button
        Lamp_Ti_1                      matlab.ui.control.Lamp
        K_beta_Ti                      matlab.ui.control.Button
        Lamp_Ti_2                      matlab.ui.control.Lamp
        K_alpha_Cr                     matlab.ui.control.Button
        Lamp_Cr_1                      matlab.ui.control.Lamp
        K_beta_Cr                      matlab.ui.control.Button
        Lamp_Cr_2                      matlab.ui.control.Lamp
        K_alpha_V                      matlab.ui.control.Button
        Lamp_V_1                       matlab.ui.control.Lamp
        K_beta_V                       matlab.ui.control.Button
        Lamp_V_2                       matlab.ui.control.Lamp
        K_alpha_Mn                     matlab.ui.control.Button
        Lamp_Mn_1                      matlab.ui.control.Lamp
        K_beta_Mn                      matlab.ui.control.Button
        Lamp_Mn_2                      matlab.ui.control.Lamp
        K_alpha_Fe                     matlab.ui.control.Button
        Lamp_Fe_1                      matlab.ui.control.Lamp
        K_beta_Fe                      matlab.ui.control.Button
        Lamp_Fe_2                      matlab.ui.control.Lamp
        K_alpha_Co                     matlab.ui.control.Button
        Lamp_Co_1                      matlab.ui.control.Lamp
        K_beta_Co                      matlab.ui.control.Button
        Lamp_Co_2                      matlab.ui.control.Lamp
        K_alpha_Ni                     matlab.ui.control.Button
        Lamp_Ni_1                      matlab.ui.control.Lamp
        K_beta_Ni                      matlab.ui.control.Button
        Lamp_Ni_2                      matlab.ui.control.Lamp
        K_alpha_Cu                     matlab.ui.control.Button
        Lamp_Cu_1                      matlab.ui.control.Lamp
        K_beta_Cu                      matlab.ui.control.Button
        Lamp_Cu_2                      matlab.ui.control.Lamp
        K_alpha_Zn                     matlab.ui.control.Button
        Lamp_Zn_1                      matlab.ui.control.Lamp
        K_beta_Zn                      matlab.ui.control.Button
        L_alpha_Zn                     matlab.ui.control.Button
        Lamp_Zn_2                      matlab.ui.control.Lamp
        Lamp_Zn_3                      matlab.ui.control.Lamp
        PbButton                       matlab.ui.control.Button
        TeButton                       matlab.ui.control.Button
        AuButton                       matlab.ui.control.Button
        InButton                       matlab.ui.control.Button
        WButton                        matlab.ui.control.Button
        RhButton                       matlab.ui.control.Button
        YButton                        matlab.ui.control.Button
        YbButton                       matlab.ui.control.Button
        DyButton                       matlab.ui.control.Button
        RbButton                       matlab.ui.control.Button
        SmButton                       matlab.ui.control.Button
        BrButton                       matlab.ui.control.Button
        SeButton                       matlab.ui.control.Button
        CeButton                       matlab.ui.control.Button
        CsButton                       matlab.ui.control.Button
        IButton                        matlab.ui.control.Button
        AsButton                       matlab.ui.control.Button
        GaButton                       matlab.ui.control.Button
        TiButton                       matlab.ui.control.Button
        NaButton                       matlab.ui.control.Button
        VButton                        matlab.ui.control.Button
        MgButton                       matlab.ui.control.Button
        CrButton                       matlab.ui.control.Button
        AlButton                       matlab.ui.control.Button
        MnButton                       matlab.ui.control.Button
        SiButton                       matlab.ui.control.Button
        PButton                        matlab.ui.control.Button
        FeButton                       matlab.ui.control.Button
        CoButton                       matlab.ui.control.Button
        SButton                        matlab.ui.control.Button
        NiButton                       matlab.ui.control.Button
        ClButton                       matlab.ui.control.Button
        CuButton                       matlab.ui.control.Button
        ZnButton                       matlab.ui.control.Button
        KButton                        matlab.ui.control.Button
        CaButton                       matlab.ui.control.Button
        PickalllinesPanel              matlab.ui.container.Panel
        pickALL_M_alpha_CheckBox       matlab.ui.control.CheckBox
        pickALL_L_beta_CheckBox        matlab.ui.control.CheckBox
        pickALL_L_alpha_CheckBox       matlab.ui.control.CheckBox
        pickALL_K_beta_CheckBox        matlab.ui.control.CheckBox
        pickALL_K_alpha_CheckBox       matlab.ui.control.CheckBox
        CalibrationAppPanel_6          matlab.ui.container.Panel
        GridLayout29                   matlab.ui.container.GridLayout
        GridLayout31                   matlab.ui.container.GridLayout
        UpdateEnergyFWHMcalibrationButton  matlab.ui.control.Button
        UpdateSensitivitycalibrationButton  matlab.ui.control.Button
        GridLayout45                   matlab.ui.container.GridLayout
        ROIwidthDropDown               matlab.ui.control.DropDown
        ROIwidthLabel                  matlab.ui.control.Label
        StartmeasurementButton         matlab.ui.control.Button
        InitialCoordinatesPanel        matlab.ui.container.Panel
        GridLayout34                   matlab.ui.container.GridLayout
        zmmPanel                       matlab.ui.container.Panel
        GridLayout35                   matlab.ui.container.GridLayout
        initial_z_coordintates         matlab.ui.control.NumericEditField
        xmmPanel                       matlab.ui.container.Panel
        GridLayout36                   matlab.ui.container.GridLayout
        initial_x_coordinates          matlab.ui.control.NumericEditField
        BeamenergyeVPanel              matlab.ui.container.Panel
        GridLayout33                   matlab.ui.container.GridLayout
        Beam_energy_edit_field         matlab.ui.control.EditField
        ChoosestartingpositionButton   matlab.ui.control.Button
        GridLayout30                   matlab.ui.container.GridLayout
        Image_10                       matlab.ui.control.Image
        Image_9                        matlab.ui.control.Image
        Polyx_theme_LOGO_5             matlab.ui.control.Image
        ViewDataTab                    matlab.ui.container.Tab
        Grid_Panel_reszta              matlab.ui.container.GridLayout
        GridLayout68                   matlab.ui.container.GridLayout
        CurrentFolderLabel             matlab.ui.control.Label
        UIAxes_det_1                   matlab.ui.control.UIAxes
        UIAxes_det_2                   matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout69                   matlab.ui.container.GridLayout
        Detector2Panel                 matlab.ui.container.Panel
        GridLayout58                   matlab.ui.container.GridLayout
        text_real_time_d2              matlab.ui.control.TextArea
        MeanPINLabel_7                 matlab.ui.control.Label
        text_OCR_sum_d2                matlab.ui.control.TextArea
        MeanPINLabel_12                matlab.ui.control.Label
        text_ICR_sum_d2                matlab.ui.control.TextArea
        MeanPINLabel_9                 matlab.ui.control.Label
        text_live_time_d2              matlab.ui.control.TextArea
        MeanPINLabel_8                 matlab.ui.control.Label
        GridLayout70                   matlab.ui.container.GridLayout
        text_mean_I0                   matlab.ui.control.TextArea
        MeanPINLabel                   matlab.ui.control.Label
        text_mean_PIN                  matlab.ui.control.TextArea
        MeanPINLabel_13                matlab.ui.control.Label
        Detector1Panel                 matlab.ui.container.Panel
        GridLayout67                   matlab.ui.container.GridLayout
        text_real_time_d1              matlab.ui.control.TextArea
        MeanPINLabel_3                 matlab.ui.control.Label
        text_OCR_sum_d1                matlab.ui.control.TextArea
        MeanPINLabel_11                matlab.ui.control.Label
        text_ICR_sum_d1                matlab.ui.control.TextArea
        MeanPINLabel_5                 matlab.ui.control.Label
        text_live_time_d1              matlab.ui.control.TextArea
        MeanPINLabel_4                 matlab.ui.control.Label
        CalibrationAppPanel            matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        ShowROIsCheckBox               matlab.ui.control.CheckBox
        SetscalePanel                  matlab.ui.container.Panel
        GridLayout66                   matlab.ui.container.GridLayout
        SetScaleToLog                  matlab.ui.control.StateButton
        SetScaleToLinear               matlab.ui.control.StateButton
        SetScaleToEnergy               matlab.ui.control.StateButton
        SetScaleToChannel              matlab.ui.control.StateButton
        GridLayout65                   matlab.ui.container.GridLayout
        Next_sp_button                 matlab.ui.control.Button
        Prev_sp_button                 matlab.ui.control.Button
        UpdateCalibrationButton        matlab.ui.control.Button
        LoadDirectoryButton            matlab.ui.control.Button
        GridLayout15                   matlab.ui.container.GridLayout
        Image_3                        matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        Polyx_theme_LOGO               matlab.ui.control.Image
        SensitivityResultsTab          matlab.ui.container.Tab
        GridLayout46                   matlab.ui.container.GridLayout
        GridLayout47                   matlab.ui.container.GridLayout
        UIAxes_Sensitivity4            matlab.ui.control.UIAxes
        UIAxes_Sensitivity1            matlab.ui.control.UIAxes
        UIAxes_Sensitivity3            matlab.ui.control.UIAxes
        UIAxes_Sensitivity2            matlab.ui.control.UIAxes
        CalibrationAppPanel_7          matlab.ui.container.Panel
        GridLayout18_2                 matlab.ui.container.GridLayout
        GridLayout20_2                 matlab.ui.container.GridLayout
        GridLayout21_2                 matlab.ui.container.GridLayout
        Next_SensitivityLine_results   matlab.ui.control.Button
        Previous_SensitivityLine_results  matlab.ui.control.Button
        GridLayout19_2                 matlab.ui.container.GridLayout
        Image_12                       matlab.ui.control.Image
        Image_11                       matlab.ui.control.Image
        Polyx_theme_LOGO_6             matlab.ui.control.Image
        EnergyCalibrationTab           matlab.ui.container.Tab
        GridPanel_reszta_etab          matlab.ui.container.GridLayout
        Grid_wykresy_energytab         matlab.ui.container.GridLayout
        GridLayout6_2                  matlab.ui.container.GridLayout
        E_Calib_Function_label_d2      matlab.ui.control.Label
        Calib_Function_label_d2        matlab.ui.control.Label
        Calib_Function_text_d2         matlab.ui.control.TextArea
        GridLayout6                    matlab.ui.container.GridLayout
        E_Calib_Function_label_d1      matlab.ui.control.Label
        Calib_Function_label_d1        matlab.ui.control.Label
        Calib_Function_text_d1         matlab.ui.control.TextArea
        UIAxes_E_cal_d2                matlab.ui.control.UIAxes
        UIAxes_error_d1                matlab.ui.control.UIAxes
        UIAxes_error_d2                matlab.ui.control.UIAxes
        UIAxes_E_cal_d1                matlab.ui.control.UIAxes
        CalibrationAppPanel_3          matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        GridLayout48                   matlab.ui.container.GridLayout
        simulatelivemeasurementButton  matlab.ui.control.Button
        GridLayout23                   matlab.ui.container.GridLayout
        Image_6                        matlab.ui.control.Image
        Image_5                        matlab.ui.control.Image
        Polyx_theme_LOGO_3             matlab.ui.control.Image
        FWHMCalibrationTab             matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        FWHM_Function_label_d2         matlab.ui.control.Label
        FWHM_Calib_Function_label_d2   matlab.ui.control.Label
        FWHM_Calib_Function_text_d2    matlab.ui.control.TextArea
        GridLayout9                    matlab.ui.container.GridLayout
        FWHM_Function_label_d1         matlab.ui.control.Label
        FWHM_Calib_Function_text_d1    matlab.ui.control.TextArea
        FWHM_Calib_Function_label_d1   matlab.ui.control.Label
        UIAxes_FWHM_cal_d2             matlab.ui.control.UIAxes
        UIAxes_FWHM_error_d1           matlab.ui.control.UIAxes
        UIAxes_FWHM_error_d2           matlab.ui.control.UIAxes
        UIAxes_FWHM_cal_d1             matlab.ui.control.UIAxes
        CalibrationAppPanel_4          matlab.ui.container.Panel
        GridLayout24                   matlab.ui.container.GridLayout
        GridLayout25                   matlab.ui.container.GridLayout
        Image_8                        matlab.ui.control.Image
        Image_7                        matlab.ui.control.Image
        Polyx_theme_LOGO_4             matlab.ui.control.Image
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    properties (Access = private)
        DataFolder
        HeaderData
        SnapshotData
        LastSequenceData
        SeqData
        PIN_map
        I0_map
        mean_PIN_map
        mean_I_0_map
        stats1line
        SeqFiles

        %% Detektor 1
        sum_real_time_1
        sum_live_time_1
        sum_ICR_1
        sum_OCR_1
        %% Detektor 2
        sum_real_time_2
        sum_live_time_2
        sum_ICR_2
        sum_OCR_2


        %% WIDMA!
        Det1Spectrum
        Det2Spectrum

        PeakChannelDet1 % Kanał dla wybranego w peakselection peaku dla energii K_alpha
        PeakChannelDet2

        CalibrationData % Struktura zawierająca peakchannel dla d1, d2 oraz energie
        CalibParams % Całe info o ficie -> współczynnniki dopasowania a i b dla detektora 1 i 2
        Det1SpectrumCalib %Struktura z energiami
        Det2SpectrumCalib
        IsCalibrated = false % Flaga do oznaczania kalibracji

        %Kalibracja FWHM
        FWHMData                       % Struct storing measured FWHM per standard
        FWHMParams                     % Calibration coefficients for FWHM vs energy


        %Przełączanie spektr
        AllDet1Spectra = {}
        AllDet2Spectra = {}
        CurrentSpectrumIndex = 0
        AllSpectrumFolderNames = {}
        %Przełączanie skalibrowanych spektr
        AllDet1SpectrumCalib = {}
        AllDet2SpectrumCalib = {}
        CurrentCalibratedSpectrumIndex = 0
        CalibratedSpectrumStandardNames = {};


        % Sensitivity Calibration Tab:
        Beam_E % Energia wiązki
        init_z % początkowe koordynaty w z (środek próbki)
        init_x % początkowe koordynaty w x (środek próbki)
        reference_row  % Wiersz na holderze wybrany jako pozycja referencyjna (1-5)
        reference_col


        PairCreationEnergy_eV = 3.85; %average energy required to produce an electron-hole pair in silicon
        SensitivityResults % Struktura do przechowywania wyników kalibracji czułościowej
        SensitivityROIs
        CurrentSensitivityLineIndex = 1 % Indeks aktualnie wyświetlanej linii
        SensitivityLineNames = {'K_alpha', 'K_beta', 'L_alpha', 'L_beta', 'M_alpha'} % Nazwy linii
        SensitivityPlotElements = {} % Lista pierwiastków do wyświetlania na wykresie

        z_active % Koordynaty środków w z, dla których wybrano jakiś pik do kalibracji
        x_active % czyli innymi słowy wszystkie koordynaty z/x, wybrane w sensitivity panel do kalib.

        active_elements
        elements_layout % To jak są ułożone pierwiastki na holderze

        x_current
        z_current


        x_lim_pos %% Limit switche
        x_lim_neg %% Limit switche
        z_lim_pos %% Limit switche
        z_lim_neg %% Limit switche


        Pending = struct( ... % bufor wierszy do Excela, jeden na folder
            'folder', {}, 'standardLabel', {}, 'Z', {}, ...
            'sum_real_time_1', {}, 'sum_live_time_1', {}, 'sum_ICR_1', {}, 'sum_OCR_1', {}, ...
            'sum_real_time_2', {}, 'sum_live_time_2', {}, 'sum_ICR_2', {}, 'sum_OCR_2', {}, ...
            'peakCh1', {}, 'peakCh2', {} );

        elements_slots          % {rows x cols} — w każdym polu: cellstr, np. {'Zn','Te'}
        symbol_to_pos           % containers.Map: 'Zn' -> [row,col]
        spacing_mm = 17;  % mm

        active_symbols_per_slot


        LastGoodGAIN_d1 = double(6.976405822086278) % Wartość w eV 
        LastGoodZERO_d1 = double(-691.1522661577436) % Wartość w eV
        LastGoodNOISE_d1 = double(1.007687076077960e+02) % Wartość w eV 
        LastGoodFANO_d1 = double(0.116021752017496) % Wartość w eV 
        LastGoodGAIN_d2 = double(7.007926204833944) % Wartość w eV 
        LastGoodZERO_d2 = double(-705.4344049232628) % Wartość w eV
        LastGoodNOISE_d2 = double(1.157006275942068e+02) % Wartość w eV 
        LastGoodFANO_d2 = double(0.124014872357139) % Wartość w eV

        CurrentScale = 'Channel'
        CurrentYScale = 'linear'
    end

    methods (Access = private)


        function saveCalibrationData_excel(app)

            if ~isstruct(app.CalibParams) || ~isfield(app.CalibParams, 'detector1') || ...
                    ~isfield(app.CalibParams, 'detector2') || ~isfield(app.CalibParams.detector1, 'a') || ...
                    ~isfield(app.CalibParams.detector2, 'a')
                return;
            end

            current_date = datestr(now, 'dd_mm_yyyy');
            fileName = ['MeasurementSummary_' current_date '.xlsx'];
            excelPath = fullfile(pwd, fileName);

            % energia
            a1 = app.CalibParams.detector1.a; b1 = app.CalibParams.detector1.b;
            a2 = app.CalibParams.detector2.a; b2 = app.CalibParams.detector2.b;
            calibFn1 = sprintf('y = %.6f·CH + %.6f', a1, b1);
            calibFn2 = sprintf('y = %.6f·CH + %.6f', a2, b2);

            % fwhm
            noise1_keV = app.FWHMParams.detector1.noise / 1000;
            k1_keV = app.FWHMParams.detector1.k / 1000;
            noise2_keV = app.FWHMParams.detector2.noise / 1000;
            k2_keV = app.FWHMParams.detector2.k / 1000;

            fwhmFn1 = sprintf('FWHM = √(%.6f² + %.6f·E) [keV]', noise1_keV, k1_keV);
            fwhmFn2 = sprintf('FWHM = √(%.6f² + %.6f·E) [keV]', noise2_keV, k2_keV);

            headers = {'Folder', ...
                'Z', 'RealTime_D1','LiveTime_D1','ICR_D1','OCR_D1','DeadTime_D1 [%]', ...
                'RealTime_D2','LiveTime_D2','ICR_D2','OCR_D2','DeadTime_D2 [%]', ...
                'Energy_D1 [keV]','Energy_D2 [keV]'};

            headerText = { ...
                ['Energy calibration function D1: ', calibFn1], ''; ...
                ['Energy calibration function D2: ', calibFn2], ''; ...
                ['FWHM calibration function D1: ', fwhmFn1],     ''; ...
                ['FWHM calibration function D2: ', fwhmFn2],     ''; ...
                '', ''};

            %% Nadpisujemy sobie dane po dodaniu standardu do kalibracji po to żeby zaktualizować wszystkie energie o poprawione funkcje
            % nagłówki kalibracji
            writecell(headerText, excelPath, 'Range', 'A1');

            % nagłówki danych
            writecell(headers, excelPath, 'Range', 'A7');

            % dane 
            nextRow = 8;

            for k = 1:numel(app.Pending)
                P = app.Pending(k);
                DT1 = NaN; DT2 = NaN;
                if P.sum_ICR_1 > 0, DT1 = 100*(1 - P.sum_OCR_1/P.sum_ICR_1); end
                if P.sum_ICR_2 > 0, DT2 = 100*(1 - P.sum_OCR_2/P.sum_ICR_2); end

                E1 = a1 * P.peakCh1 + b1;
                E2 = a2 * P.peakCh2 + b2;

                row = { P.folder, ...
                    P.Z, P.sum_real_time_1, P.sum_live_time_1, P.sum_ICR_1, P.sum_OCR_1, DT1, ...
                    P.sum_real_time_2, P.sum_live_time_2, P.sum_ICR_2, P.sum_OCR_2, DT2, ...
                    E1, E2 };

                writecell(row, excelPath, 'Range', ['A' num2str(nextRow)]);
                nextRow = nextRow + 1;
            end

            if isfile(excelPath)
                try
                    raw = readcell(excelPath);
                    if nextRow <= size(raw, 1)
                        clearRange = sprintf('A%d:Z%d', nextRow, size(raw, 1));
                        writecell(cell(1,1), excelPath, 'Range', clearRange); 
                    end
                catch

                end
            end
        end


        function saveSensitivityResultsToFiles(app)
            timestamp = char(datetime("now", 'Format', 'dd_MM_yyyy_HH_mm'));

            try
                energia_wiazki = app.Beam_E;
                energia_char = regexprep(num2str(energia_wiazki), '\.', '_');
            catch
                energia_char = 'unknown_E';
            end
            try
                kat_zolix = get_motor_pos('zolix');
                if inRange(kat_zolix, -1,1)
                    geom_str = 'backscatter';
                elseif inRange(kat_zolix, 44, 46)
                    geom_str = '45deg';
                else
                    geom_str = 'unknown_geom';
                end
                geometria = wm_zolix();
                geom_str = regexprep(char(geometria), '\.', '_');
            catch
                geom_str = 'unknown_geom';
            end
            try
                [alias, ~] = get_corex_conf;
                optyka_str = alias;
            catch
                optyka_str = 'unknown_optics';
            end

            excelFileName = sprintf('SensitivityResults_%s_E_%s_geom_%s_opt_%s.xlsx', ...
                timestamp, energia_char, geom_str, optyka_str);
            txtFileName   = sprintf('SensitivityResults_%s_E_%s_geom_%s_opt_%s.txt', ...
                timestamp, energia_char, geom_str, optyka_str);
            excelPath = fullfile(pwd, excelFileName);
            txtPath   = fullfile(pwd, txtFileName);


            calLines = { ...
                'Sensitivity Calibration Results'; ...
                ['Generated: ' timestamp]; ...
                ''; ...
                'Calibration Parameters:'; ...
                ['GAIN1:  ' char(app.GAIN1_sensitivity_tab_value.Value) ' eV/ch']; ...
                ['ZERO1:  ' char(app.ZERO1_sensitivity_tab_value.Value) ' eV']; ...
                ['GAIN2:  ' char(app.GAIN2_sensitivity_tab_value.Value) ' eV/ch']; ...
                ['ZERO2:  ' char(app.ZERO2_sensitivity_tab_value.Value) ' eV']; ...
                ['NOISE1: ' char(app.NOISE1_sensitivity_tab_value.Value) ' eV']; ...
                ['FANO1:  ' char(app.FANO1_sensitivity_tab_value.Value)]; ...
                ['NOISE2: ' char(app.NOISE2_sensitivity_tab_value.Value) ' eV']; ...
                ['FANO2:  ' char(app.FANO2_sensitivity_tab_value.Value)]; ...
                ''; ''};

            tableHeaders = { 'Element', 'Line', 'Z', 'E_theor', ...
                'Found_E1', 'Found_E2', 'Area_D1', 'Area_D2', ...
                'I_D1', 'I_D2', 'Sens_D1', 'Sens_D2', ...
                'Surf_Mass', 'I0', 'LiveT_D1', 'LiveT_D2', ...
                'Fit1', 'Fit2' };

            unitHeaders = { '', '', '', '[keV]', ...
                '[keV]', '[keV]', '[counts]', '[counts]', ...
                '[counts·s⁻¹·V⁻¹]', '[counts·s⁻¹·V⁻¹]', ...
                '[counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]', '[counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]', ...
                '[μg·cm⁻²]', '[V]', '[s]', '[s]', ...
                '', '' };


            % dane z app.SensitivityResults
            rows = {};
            lineNames = fieldnames(app.SensitivityResults);
            rcount = 1;
            for iLine = 1:numel(lineNames)
                ln = lineNames{iLine};
                elems = fieldnames(app.SensitivityResults.(ln));
                for ie = 1:numel(elems)
                    sym = elems{ie};
                    rec = app.SensitivityResults.(ln).(sym);

                    Z = safeGet(rec, 'Z', NaN);
                    expE = safeGet(rec, 'expectedEnergy', NaN);
                    fE1 = safeGet(rec, 'foundEnergy1', NaN);
                    fE2 = safeGet(rec, 'foundEnergy2', NaN);
                    pa1 = safeGet(rec, 'peakArea1', NaN);
                    pa2 = safeGet(rec, 'peakArea2', NaN);
                    i1 = safeGet(rec, 'intensity1', NaN);
                    i2 = safeGet(rec, 'intensity2', NaN);
                    s1 = safeGet(rec, 'sensitivity1', NaN);
                    s2 = safeGet(rec, 'sensitivity2', NaN);
                    surface_mass = safeGet(rec, 'surface_mass', NaN);
                    meanI0 = safeGet(rec, 'mean_I0', NaN);
                    lt1 = safeGet(rec, 'live_time_d1', NaN);
                    lt2 = safeGet(rec, 'live_time_d2', NaN);
                    fit1 = safeGet(rec, 'fitSuccess1', false);
                    fit2 = safeGet(rec, 'fitSuccess2', false);

                    rows(rcount, :) = { ...
                        sym, ln, double(Z), double(expE), ...
                        double(fE1), double(fE2), double(pa1), double(pa2), ...
                        double(i1), double(i2), double(s1), double(s2), ...
                        double(surface_mass), double(meanI0), double(lt1), double(lt2), ...
                        logical(fit1), logical(fit2) } ;
                    rcount = rcount + 1;
                end
            end

            % Zapis do Excela: najpierw nagłówek tekstowy, potem nagłówek tabeli, potem dane
            try
                writecell(calLines, excelPath, 'Range', 'A1');
                startHeaderRow = numel(calLines) + 1;

                % nagłówki (wiersz 1)
                headerRange = sprintf('A%d', startHeaderRow);
                writecell(tableHeaders, excelPath, 'Range', headerRange);

                % jednostki (wiersz 2)
                unitRange = sprintf('A%d', startHeaderRow + 1);
                writecell(unitHeaders, excelPath, 'Range', unitRange);

                dataStartRow = startHeaderRow + 2;
                dataRange = sprintf('A%d', dataStartRow);
                if ~isempty(rows)
                    writecell(rows, excelPath, 'Range', dataRange);
                end
            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end

            fid = fopen(txtPath, 'w');
            if fid == -1
                warning('Cannot create text file: %s', txtPath);
                return;
            end
            for k = 1:numel(calLines)
                fprintf(fid, '%s\n', calLines{k});
            end
            fprintf(fid, '%s\n', strjoin(tableHeaders, '\t'));
            fprintf(fid, '%s\n', strjoin(unitHeaders, '\t'));
            fprintf(fid, '%s\n', repmat('-',1,300));
            for r = 1:size(rows,1)
                rec = rows(r,:);
                % formatuj: strings bez zmiany, numery z ustaloną precyzją, logic do 0/1
                fprintf(fid, '%s\t%s\t%d\t%.4f\t%.4f\t%.4f\t%.0f\t%.0f\t%.6f\t%.6f\t%.6f\t%.6f\t%.4f\t%.4f\t%.2f\t%.2f\t%d\t%d\n', ...
                    char(rec{1}), ... % Element
                    char(rec{2}), ... % Line
                    int32(rec{3}), ... % Z
                    doubleOrNaN(rec{4}), doubleOrNaN(rec{5}), doubleOrNaN(rec{6}), ...
                    doubleOrNaN(rec{7}), doubleOrNaN(rec{8}), ...
                    doubleOrNaN(rec{9}), doubleOrNaN(rec{10}), ...
                    doubleOrNaN(rec{11}), doubleOrNaN(rec{12}), ...
                    doubleOrNaN(rec{13}), doubleOrNaN(rec{14}), ...
                    doubleOrNaN(rec{15}), doubleOrNaN(rec{16}), ...
                    logical(rec{17}), logical(rec{18}));
            end
            fclose(fid);

            fprintf('Sensitivity results saved to:\n%s\n%s\n', excelPath, txtPath);

            % --- helpery ---
            function v = safeGet(s, field, default)
                if isstruct(s) && isfield(s, field)
                    v = s.(field);
                else
                    v = default;
                end
            end
            function out = doubleOrNaN(x)
                if isempty(x) || (isnumeric(x) && isnan(x))
                    out = NaN;
                else
                    out = double(x);
                end
            end
        end


        %% Display of data from detector 1

        function d1_data_disp(app, real_time, live_time, ICR, OCR)
            if nargin == 1
                real_time = app.sum_real_time_1;
                live_time = app.sum_live_time_1;
                ICR = app.sum_ICR_1;
                OCR = app.sum_OCR_1;
            end

            app.text_live_time_d1.Value = {sprintf('%.2f', live_time)};
            app.text_real_time_d1.Value = {sprintf('%.2f', real_time)};
            app.text_ICR_sum_d1.Value = {sprintf('%.2f', ICR)};
            app.text_OCR_sum_d1.Value = {sprintf('%.2f', OCR)};
        end
        %% Display of data from detector 2

        function d2_data_disp(app, real_time, live_time, ICR, OCR)
            if nargin == 1
                real_time = app.sum_real_time_2;
                live_time = app.sum_live_time_2;
                ICR = app.sum_ICR_2;
                OCR = app.sum_OCR_2;
            end

            app.text_live_time_d2.Value = {sprintf('%.2f', live_time)};
            app.text_real_time_d2.Value = {sprintf('%.2f', real_time)};
            app.text_ICR_sum_d2.Value = {sprintf('%.2f', ICR)};
            app.text_OCR_sum_d2.Value = {sprintf('%.2f', OCR)};
        end

        %% Display panel values

        function mean_values_disp(app, mean_PIN, mean_I0)
            if nargin == 1
                mean_PIN = app.mean_PIN_map;
                mean_I0 = app.mean_I_0_map;
            end

            app.text_mean_PIN.Value = {sprintf('%.4f', mean_PIN)};
            app.text_mean_I0.Value = {sprintf('%.4f', mean_I0)};
        end


        %% Tab 2 function to plot spectra on the UI
        function plot_spectrum(app, totalDane1line)
            cla(app.UIAxes_det_1);
            cla(app.UIAxes_det_2);
            [num_detectors, ~, num_channels] = size(totalDane1line);
            counts = reshape(sum(totalDane1line, 2), [num_detectors, num_channels]);
            % Detector 1
            plot(app.UIAxes_det_1, 1:num_channels, counts(1,:), 'r', 'DisplayName', 'Detector 1', LineWidth=1.0);
            xlabel(app.UIAxes_det_1,'Channel');
            ylabel(app.UIAxes_det_1,'Counts');
            title(app.UIAxes_det_1,'Detector 1 - Count Spectrum', 'Color','w');
            grid(app.UIAxes_det_1, 'on');

            xlim(app.UIAxes_det_1, [1, num_channels]);
            % Detector 2
            plot(app.UIAxes_det_2, 1:num_channels, counts(2,:), 'b', 'DisplayName', 'Detector 2', LineWidth=1.0);
            xlabel(app.UIAxes_det_2, 'Channel');
            ylabel(app.UIAxes_det_2, 'Counts');
            title(app.UIAxes_det_2, 'Detector 2 - Count Spectrum', 'Color', 'w');
            grid(app.UIAxes_det_2, 'on');
            xlim(app.UIAxes_det_2, [1, num_channels]);

            app.UIAxes_det_1.YScale = app.CurrentYScale;
            app.UIAxes_det_2.YScale = app.CurrentYScale;

            try
                legend(app.UIAxes_det_2, 'off');
                legend(app.UIAxes_det_1, 'off');
            catch
            end

        end

        function refresh_current_display(app)
            if ~isempty(app.AllDet1Spectra) && app.CurrentSpectrumIndex > 0
                plot_stored_spectrum(app, app.CurrentSpectrumIndex);

                if isprop(app,'ShowROIsCheckBox') && logical(app.ShowROIsCheckBox.Value) && ...
                        strcmp(app.CurrentScale, 'Energy')
                    plot_sensitivity_for_index(app, app.CurrentSpectrumIndex);
                end
            end
        end

        function plot_stored_spectrum(app, index)
            if isempty(app.AllDet1Spectra)
                return;
            end
            numSpec = numel(app.AllDet1Spectra);
            numPages = numSpec + 1; % 1 na strone z wszystkimi spectrami
            index = max(1, min(index, numPages));
            app.CurrentSpectrumIndex = index;


            cla(app.UIAxes_det_1);
            cla(app.UIAxes_det_2);

            if index <= numSpec
                % Single spectrum
                app.Det1Spectrum = app.AllDet1Spectra{index};
                app.Det2Spectrum = app.AllDet2Spectra{index};
                num_channels = length(app.Det1Spectrum);

                if strcmp(app.CurrentScale, 'Channel')
                    % Plot in channel scale
                    tempData = zeros(2,1,num_channels);
                    tempData(1,1,:) = app.Det1Spectrum;
                    tempData(2,1,:) = app.Det2Spectrum;
                    plot_spectrum(app, tempData);
                    app.UIAxes_det_1.YScale = app.CurrentYScale;
                    app.UIAxes_det_2.YScale = app.CurrentYScale;
                else
                    % Plot in energy scale
                    if ~isempty(app.CalibParams)

                        if index <= numel(app.CalibrationData.Standards)
                            standardName = app.CalibrationData.Standards{index};
                        elseif index <= numel(app.Pending) && isfield(app.Pending(index), 'standardLabel')
                            standardName = app.Pending(index).standardLabel;
                        else
                            standardName = sprintf('Spectrum %d', index);
                        end


                        % Detector 1
                        energy1 = app.CalibParams.detector1.a * (1:num_channels) + app.CalibParams.detector1.b;
                        plot(app.UIAxes_det_1, energy1, app.Det1Spectrum, 'r', 'LineWidth', 1.0);
                        xlabel(app.UIAxes_det_1, 'Energy [keV]');
                        ylabel(app.UIAxes_det_1, 'Counts');
                        title(app.UIAxes_det_1, sprintf('Detector 1 - Calibrated Spectrum (%s)', standardName), 'Color','w');
                        grid(app.UIAxes_det_1, 'on');
                        app.UIAxes_det_1.YScale = app.CurrentYScale;

                        % Detector 2
                        energy2 = app.CalibParams.detector2.a * (1:num_channels) + app.CalibParams.detector2.b;
                        plot(app.UIAxes_det_2, energy2, app.Det2Spectrum, 'b', 'LineWidth', 1.0);
                        xlabel(app.UIAxes_det_2, 'Energy [keV]');
                        ylabel(app.UIAxes_det_2, 'Counts');
                        title(app.UIAxes_det_2, sprintf('Detector 2 - Calibrated Spectrum (%s)', standardName), 'Color','w');
                        grid(app.UIAxes_det_2, 'on');
                        app.UIAxes_det_2.YScale = app.CurrentYScale;
                        max_energy1 = max(energy1);
                        max_energy2 = max(energy2);

                        xlim(app.UIAxes_det_1, [0, max_energy1]);
                        xlim(app.UIAxes_det_2, [0, max_energy2]);

                        try
                            legend(app.UIAxes_det_2, 'off');
                            legend(app.UIAxes_det_1, 'off');
                        catch
                        end

                    end
                end

                % Update stats/display if pending available
                if index <= numel(app.Pending)
                    P = app.Pending(index);
                    app.sum_real_time_1 = P.sum_real_time_1;
                    app.sum_live_time_1 = P.sum_live_time_1;
                    app.sum_ICR_1 = P.sum_ICR_1;
                    app.sum_OCR_1 = P.sum_OCR_1;
                    app.sum_real_time_2 = P.sum_real_time_2;
                    app.sum_live_time_2 = P.sum_live_time_2;
                    app.sum_ICR_2 = P.sum_ICR_2;
                    app.sum_OCR_2 = P.sum_OCR_2;
                    app.mean_PIN_map = P.mean_PIN_map;
                    app.mean_I_0_map = P.mean_I_0_map;
                    d1_data_disp(app); d2_data_disp(app); mean_values_disp(app);
                end

                if index <= numel(app.AllSpectrumFolderNames)
                    app.CurrentFolderLabel.Text = ['Current Folder: ', app.AllSpectrumFolderNames{index}];
                end
            else

                % "All spectra" page
                names = cell(1, numSpec);
                for k = 1:numSpec
                    if k <= numel(app.AllSpectrumFolderNames) && ~isempty(app.AllSpectrumFolderNames{k})
                        names{k} = app.AllSpectrumFolderNames{k};
                    elseif k <= numel(app.Pending) && isfield(app.Pending(k),'standardLabel')
                        names{k} = app.Pending(k).standardLabel;
                    else
                        names{k} = sprintf('Spec %d', k);
                    end
                end


                colors = lines(max(1,numSpec));

                if strcmp(app.CurrentScale, 'Channel')
                    % Plot all in channel scale
                    hold(app.UIAxes_det_1,'on');
                    hDet1 = gobjects(numSpec,1);
                    for k = 1:numSpec
                        sp1 = app.AllDet1Spectra{k};
                        ch = 1:numel(sp1);
                        hDet1(k) = plot(app.UIAxes_det_1, ch, sp1, 'Color', colors(k,:), 'LineWidth', 0.8);
                    end
                    hold(app.UIAxes_det_1,'off');
                    title(app.UIAxes_det_1, sprintf('All Detector 1 spectra (%d)', numSpec), 'Color','w');
                    app.UIAxes_det_1.YScale = app.CurrentYScale;
                    xlabel(app.UIAxes_det_1, 'Channel');
                    ylabel(app.UIAxes_det_1, 'Counts');
                    grid(app.UIAxes_det_1,'on');

                    hold(app.UIAxes_det_2,'on');
                    hDet2 = gobjects(numSpec,1);
                    for k = 1:numSpec
                        sp2 = app.AllDet2Spectra{k};
                        ch = 1:numel(sp2);
                        hDet2(k) = plot(app.UIAxes_det_2, ch, sp2, 'Color', colors(k,:), 'LineWidth', 0.8);
                    end
                    hold(app.UIAxes_det_2,'off');
                    title(app.UIAxes_det_2, sprintf('All Detector 2 spectra (%d)', numSpec), 'Color','w');
                    xlabel(app.UIAxes_det_2, 'Channel');
                    ylabel(app.UIAxes_det_2, 'Counts');
                    grid(app.UIAxes_det_2,'on');
                    app.UIAxes_det_2.YScale = app.CurrentYScale;
                    if numSpec > 0
                        num_channels = numel(app.AllDet1Spectra{1});
                        xlim(app.UIAxes_det_1, [1, num_channels]);
                        xlim(app.UIAxes_det_2, [1, num_channels]);
                    end

                else
                    % Plot all in energy scale
                    if ~isempty(app.CalibParams)
                        max_energy1 = 0;
                        max_energy2 = 0;

                        names = cell(1, numSpec);
                        for k = 1:numSpec
                            if k <= numel(app.CalibrationData.Standards)
                                names{k} = app.CalibrationData.Standards{k};
                            elseif k <= numel(app.Pending) && isfield(app.Pending(k), 'standardLabel')
                                names{k} = app.Pending(k).standardLabel;
                            else
                                names{k} = sprintf('Spectrum %d', k);
                            end
                        end
                        hold(app.UIAxes_det_1,'on');
                        hDet1 = gobjects(numSpec,1);
                        for k = 1:numSpec
                            sp1 = app.AllDet1Spectra{k};
                            energy1 = app.CalibParams.detector1.a * (1:numel(sp1)) + app.CalibParams.detector1.b;
                            hDet1(k) = plot(app.UIAxes_det_1, energy1, sp1, 'Color', colors(k,:), 'LineWidth', 0.8);
                            max_energy1 = max(max_energy1, max(energy1));
                        end
                        hold(app.UIAxes_det_1,'off');
                        title(app.UIAxes_det_1, sprintf('All Detector 1 spectra (%d)', numSpec), 'Color','w');
                        xlabel(app.UIAxes_det_1, 'Energy (keV)');
                        ylabel(app.UIAxes_det_1, 'Counts');
                        grid(app.UIAxes_det_1,'on');
                        xlim(app.UIAxes_det_1, [0, max_energy1]);
                        app.UIAxes_det_1.YScale = app.CurrentYScale;

                        hold(app.UIAxes_det_2,'on');
                        hDet2 = gobjects(numSpec,1);
                        for k = 1:numSpec
                            sp2 = app.AllDet2Spectra{k};
                            energy2 = app.CalibParams.detector2.a * (1:numel(sp2)) + app.CalibParams.detector2.b;
                            hDet2(k) = plot(app.UIAxes_det_2, energy2, sp2, 'Color', colors(k,:), 'LineWidth', 0.8);
                            max_energy2 = max(max_energy2, max(energy2));
                        end
                        hold(app.UIAxes_det_2,'off');
                        title(app.UIAxes_det_2, sprintf('All Detector 2 spectra (%d)', numSpec), 'Color','w');
                        xlabel(app.UIAxes_det_2, 'Energy (keV)');
                        ylabel(app.UIAxes_det_2, 'Counts');
                        grid(app.UIAxes_det_2,'on');
                        xlim(app.UIAxes_det_2, [0, max_energy2]);
                        app.UIAxes_det_2.YScale = app.CurrentYScale;
                    end
                end


                try
                    namesTrim = cellfun(@strtrim, names, 'UniformOutput', false);
                    keep = true(numel(namesTrim),1);

                    pairs = { 'Zn','Te','ZnTe'; 'K' ,'Cl','KCl' ;  'Ga','As','GaAs' };

                    for p = 1:size(pairs,1)
                        a = pairs{p,1};
                        b = pairs{p,2};
                        mergedLabel = pairs{p,3};

                        ia = find(strcmpi(namesTrim, a), 1, 'first');
                        ib = find(strcmpi(namesTrim, b), 1, 'first');

                        if ~isempty(ia) && ~isempty(ib)
                            iKeep = min(ia, ib);
                            iDrop = max(ia, ib);
                            namesTrim{iKeep} = mergedLabel;
                            keep(iDrop) = false;
                        end
                    end
                    numShown = sum(keep);
                    title(app.UIAxes_det_1, sprintf('All Detector 1 spectra (%d)', numShown), 'Color','w');
                    title(app.UIAxes_det_2, sprintf('All Detector 2 spectra (%d)', numShown), 'Color','w');

                    legend(app.UIAxes_det_1, hDet1(keep), namesTrim(keep), 'Interpreter','none', 'Location','best');
                    legend(app.UIAxes_det_2, hDet2(keep), namesTrim(keep), 'Interpreter','none', 'Location','best');
                catch
                end

                app.CurrentFolderLabel.Text = sprintf('All spectra');

                % zbiorczy view
                app.text_live_time_d1.Value = {'-'};
                app.text_real_time_d1.Value = {'-'};
                app.text_ICR_sum_d1.Value   = {'-'};
                app.text_OCR_sum_d1.Value   = {'-'};

                app.text_live_time_d2.Value = {'-'};
                app.text_real_time_d2.Value = {'-'};
                app.text_ICR_sum_d2.Value   = {'-'};
                app.text_OCR_sum_d2.Value   = {'-'};

                app.text_mean_PIN.Value = {'-'};
                app.text_mean_I0.Value  = {'-'};


            end

            % Update navigation buttons
            app.Prev_sp_button.Enable = (app.CurrentSpectrumIndex > 1);
            app.Next_sp_button.Enable = (app.CurrentSpectrumIndex < numPages);
        end


        %% Database of standards for calibration
        function standards = GetStandardsDatabase(app)
            xrl = py.importlib.import_module('xraylib');
            elements = {'Na','Cl','Mg','Al','Si','Ga','P','In','S','K','Ca','Ti','V','Cr',...
                'Mn','Fe','Co','Ni','Cu','Zn','Te','As','Se','Cs','Br','Rb','I','Y','Rh',...
                'Ce','Sm','Dy','Yb','W','Au','Pb'};
            for i = 1:numel(elements)
                symbol = elements{i};
                Z = xrl.SymbolToAtomicNumber(symbol);
                standards.(symbol).energy = double(xrl.LineEnergy(Z, xrl.KA_LINE)) * 1000; % eV
            end
        end

        function standards = GetSurfaceMass(app)
            standards = GetStandardsDatabase(app);

            surface_masses = [3.1, 9.9, 2.9, 5.6, 2.86, 20.9, 2.79, 16.9, 4.2, 10.9, 28.5, 57.1, 54.8, 51.9, ...
                55.3, 53.2, 49.6, 46.8, 49.3, 16.4, 32.0, 24.6, 47.5, 30.1, 18.00, 20.6, 30.6, 26.9, 48.5,...
                38.9, 34.8, 38.00, 38.2, 40.9, 57.2, 48.1];

            elements = fieldnames(standards);

            for i = 1:numel(elements)
                symbol = elements{i};
                standards.(symbol).surface_mass = surface_masses(i);
            end
        end

        % prostu dopasowanie prostych dla d1 i d2 (kalibracja energetyczna) 
        function [calibParams, results] = CalculateCalibrationParameters(app, calibrationData)
            numStandards = length(calibrationData.Standards);

            channels_det1 = zeros(1, numStandards);
            channels_det2 = zeros(1, numStandards);
            energies = zeros(1, numStandards);

            for i = 1:numStandards
                channels_det1(i) = calibrationData.Channels{i}(1);
                channels_det2(i) = calibrationData.Channels{i}(2);
                energies(i) = calibrationData.Energies{i};
            end

            % Fity prostych
            if numStandards >= 2
                valid1 = isfinite(channels_det1) & isfinite(energies);
                valid2 = isfinite(channels_det2) & isfinite(energies);

                % --- detektor 1 ---
                if sum(valid1) >= 2
                    p1 = polyfit(channels_det1(valid1), energies(valid1), 1);
                    a1 = p1(1);  b1 = p1(2);
                elseif ~isempty(app.CalibParams) && isfield(app.CalibParams,'detector1') && ...
                        isfinite(app.CalibParams.detector1.a) && app.CalibParams.detector1.a > 0 && ...
                        isfinite(app.CalibParams.detector1.b)
                    a1 = app.CalibParams.detector1.a;
                    b1 = app.CalibParams.detector1.b;
                else
                    a1 = 0; b1 = 0;
                end

                % --- detektor 2 ---
                if sum(valid2) >= 2
                    p2 = polyfit(channels_det2(valid2), energies(valid2), 1);
                    a2 = p2(1);  b2 = p2(2);
                elseif ~isempty(app.CalibParams) && isfield(app.CalibParams,'detector2') && ...
                        isfinite(app.CalibParams.detector2.a) && app.CalibParams.detector2.a > 0 && ...
                        isfinite(app.CalibParams.detector2.b)
                    a2 = app.CalibParams.detector2.a;
                    b2 = app.CalibParams.detector2.b;
                else
                    a2 = 0; b2 = 0;
                end
            else
                a1 = 0; b1 = 0; a2 = 0; b2 = 0;
            end


            % Output structure
            calibParams = struct('detector1', struct('a', a1, 'b', b1), ...
                'detector2', struct('a', a2, 'b', b2));

            % Fit quality
            if numStandards >= 2
                fitted_energies_det1 = a1 * channels_det1 + b1;
                fitted_energies_det2 = a2 * channels_det2 + b2;

                errors_det1 = fitted_energies_det1 - energies;
                errors_det2 = fitted_energies_det2 - energies;

                rmse_det1 = sqrt(mean(errors_det1.^2));
                rmse_det2 = sqrt(mean(errors_det2.^2));

                rel_errors_det1 = abs(errors_det1 ./ energies) * 100; % percent
                rel_errors_det2 = abs(errors_det2 ./ energies) * 100; % percent

                max_rel_error_det1 = max(rel_errors_det1);
                max_rel_error_det2 = max(rel_errors_det2);

                mean_rel_error_det1 = mean(rel_errors_det1);
                mean_rel_error_det2 = mean(rel_errors_det2);
            else
                rmse_det1 = 0;
                rmse_det2 = 0;
                max_rel_error_det1 = 0;
                max_rel_error_det2 = 0;
                mean_rel_error_det1 = 0;
                mean_rel_error_det2 = 0;
                errors_det1 = [];
                errors_det2 = [];
                rel_errors_det1 = [];
                rel_errors_det2 = [];
            end

            % Results structure for display/analysis
            results = struct('numStandards', numStandards, ...
                'standards', {calibrationData.Standards}, ...
                'channels_det1', channels_det1, ...
                'channels_det2', channels_det2, ...
                'energies', energies, ...
                'errors_det1', errors_det1, ...
                'errors_det2', errors_det2, ...
                'rel_errors_det1', rel_errors_det1, ...
                'rel_errors_det2', rel_errors_det2, ...
                'rmse_det1', rmse_det1, ...
                'rmse_det2', rmse_det2, ...
                'max_rel_error_det1', max_rel_error_det1, ...
                'max_rel_error_det2', max_rel_error_det2, ...
                'mean_rel_error_det1', mean_rel_error_det1, ...
                'mean_rel_error_det2', mean_rel_error_det2);

            assignin('base', 'results', results);

        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function DisplayCalibrationResults(app, results)

            %%%%% GUI
            %% Detector 1
            cla(app.UIAxes_E_cal_d1);
            hold(app.UIAxes_E_cal_d1, 'on');
            plot(app.UIAxes_E_cal_d1, results.channels_det1, results.energies, 'ro', 'MarkerSize', 8);
            hold(app.UIAxes_E_cal_d1, 'on');

            % Plot fitted line
            if length(results.channels_det1) >= 2
                x_range = linspace(min(results.channels_det1) * 0.9, max(results.channels_det1) * 1.1, 100);
                y_range = app.CalibParams.detector1.a * x_range + app.CalibParams.detector1.b;
                plot(app.UIAxes_E_cal_d1, x_range, y_range, 'r-', 'LineWidth', 1.5);
            end

            xlabel(app.UIAxes_E_cal_d1,'Channel');
            ylabel(app.UIAxes_E_cal_d1,'Energy [keV]');
            title(app.UIAxes_E_cal_d1,'Detector 1 Calibration', 'Color','w');
            grid(app.UIAxes_E_cal_d1,'on');

            % Add element labels
            for i = 1:length(results.standards)
                text(app.UIAxes_E_cal_d1, results.channels_det1(i), results.energies(i), ['  ' results.standards{i}], ...
                    'FontSize', 13, 'VerticalAlignment', 'bottom');
            end
            %% Detector 2
            cla(app.UIAxes_E_cal_d2);
            hold(app.UIAxes_E_cal_d2, 'on');
            plot(app.UIAxes_E_cal_d2, results.channels_det2, results.energies, 'bo', 'MarkerSize', 8);
            hold(app.UIAxes_E_cal_d2, 'on');

            % Plot fitted line
            if length(results.channels_det2) >= 2
                x_range = linspace(min(results.channels_det2) * 0.9, max(results.channels_det2) * 1.1, 100);
                y_range = app.CalibParams.detector2.a * x_range + app.CalibParams.detector2.b;
                plot(app.UIAxes_E_cal_d2, x_range, y_range, 'b-', 'LineWidth', 1.5);
            end

            xlabel(app.UIAxes_E_cal_d2, 'Channel');
            ylabel(app.UIAxes_E_cal_d2, 'Energy [keV]');
            title(app.UIAxes_E_cal_d2, 'Detector 2 Calibration', 'Color', 'w');
            grid(app.UIAxes_E_cal_d2, 'on');

            % Add element labels
            for i = 1:length(results.standards)
                text(app.UIAxes_E_cal_d2,results.channels_det2(i), results.energies(i), ['  ' results.standards{i}], ...
                    'FontSize', 13, 'VerticalAlignment', 'bottom');
            end

            % Tekst dla detektora 1
            app.E_Calib_Function_label_d1.Interpreter = 'latex';
            app.E_Calib_Function_label_d1.Text = sprintf('$~ %.5f \\cdot CH %+.4f $ (RMSE: %.4f keV)', ...
                app.CalibParams.detector1.a, app.CalibParams.detector1.b, results.rmse_det1);

            % Tekst dla detektora 2
            app.E_Calib_Function_label_d2.Interpreter = 'latex';
            app.E_Calib_Function_label_d2.Text = sprintf('$~ %.5f \\cdot CH %+.4f $ (RMSE: %.4f keV)', ...
                app.CalibParams.detector2.a, app.CalibParams.detector2.b, results.rmse_det2);

            %Panel sensitivity
            app.ZERO1_sensitivity_tab_value.Value = num2str(app.CalibParams.detector1.b * 1000);
            app.ZERO2_sensitivity_tab_value.Value = num2str(app.CalibParams.detector2.b * 1000);
            app.GAIN1_sensitivity_tab_value.Value = num2str(app.CalibParams.detector1.a*1000);
            app.GAIN2_sensitivity_tab_value.Value = num2str(app.CalibParams.detector2.a*1000);


            % Residuals for detector 1
            if ~isempty(results.errors_det1)
                cla(app.UIAxes_error_d1);
                bar(app.UIAxes_error_d1,1:length(results.standards), results.errors_det1*1000, 'r');
                set(app.UIAxes_error_d1, 'XTick', 1:length(results.standards), 'XTickLabel', results.standards);
                ylabel(app.UIAxes_error_d1, '\DeltaE = E_{fit} - E_{ref} [eV]');
                title(app.UIAxes_error_d1, sprintf('Detector 1 Errors (Mean: %.2f%%)', results.mean_rel_error_det1), 'Color','w');
                grid(app.UIAxes_error_d1, 'on');
            else
                cla(app.UIAxes_error_d1);
                text(app.UIAxes_error_d1, 0.5, 0.5, 'Insufficient data', 'HorizontalAlignment', 'center');
                set(app.UIAxes_error_d1, 'XTick', [], 'YTick', []);
            end

            % Residuals for detector 2
            if ~isempty(results.errors_det2)
                cla(app.UIAxes_error_d2);
                bar(app.UIAxes_error_d2, 1:length(results.standards), results.errors_det2*1000, 'b');
                set(app.UIAxes_error_d2, 'XTick', 1:length(results.standards), 'XTickLabel', results.standards);
                ylabel(app.UIAxes_error_d2, '\DeltaE = E_{fit} - E_{ref} [eV]');
                title(app.UIAxes_error_d2, sprintf('Detector 2 Errors (Mean: %.2f%%)', results.mean_rel_error_det2), 'Color','w');
                grid(app.UIAxes_error_d2, "on");
            else
                cla(app.UIAxes_error_d2);
                text(app.UIAxes_error_d2, 0.5, 0.5, 'Insufficient data', 'HorizontalAlignment', 'center');
                set(app.UIAxes_error_d2, 'XTick', [], 'YTick', []);
            end

            %%% Koniec GUI %%-----

            calib_params = app.CalibParams;  % Copy to save
            save('energy_calibration_parameters.mat', 'calib_params');

            % Summary in command window
            disp('=============================================');
            disp('Energy Calibration Results:');
            disp('=============================================');
            disp('Detector 1:');
            disp(['Function: E [keV] = ' num2str(app.CalibParams.detector1.a, '%.6f') ...
                ' * Channel + ' num2str(app.CalibParams.detector1.b, '%.6f')]);
            disp(['RMSE: ' num2str(results.rmse_det1, '%.4f') ' keV']);
            disp(['Mean Relative Error: ' num2str(results.mean_rel_error_det1, '%.2f') '%']);
            disp(['Max Relative Error: ' num2str(results.max_rel_error_det1, '%.2f') '%']);
            disp('---------------------------------------------');
            disp('Detector 2:');
            disp(['Function: E [keV] = ' num2str(app.CalibParams.detector2.a, '%.6f') ...
                ' * Channel + ' num2str(app.CalibParams.detector2.b, '%.6f')]);
            disp(['RMSE: ' num2str(results.rmse_det2, '%.4f') ' keV']);
            disp(['Mean Relative Error: ' num2str(results.mean_rel_error_det2, '%.2f') '%']);
            disp(['Max Relative Error: ' num2str(results.max_rel_error_det2, '%.2f') '%']);
            disp('=============================================');

            % Flag
            app.IsCalibrated = true;


        end

        function peakSelection_d1(app, selectedElement)
            fig = figure('Name', ['Select ' selectedElement ' peak for Detector 1'], 'NumberTitle', 'off');

            spectrum = app.Det1Spectrum;

            function plotFullSpectrum()
                ax = gca;
                cla(ax, 'reset');
                plot(ax, spectrum, 'r', 'LineWidth', 1.5);
                title(ax, ['Auto-detection of ' selectedElement ' K\alpha peak for Detector 1']);
                xlabel(ax, 'Channel');
                ylabel(ax, 'Counts');
                grid(ax, 'on');
            end


            plotFullSpectrum();  % pierwsze wyświetlenie

            if ~isfield(app.FWHMData, 'detector1') || isempty(app.FWHMData.detector1)
                app.FWHMData.detector1 = [];
            end

            idx = numel(app.FWHMData.detector1) + 1;
            app.FWHMData.detector1(idx) = NaN;

            ignoreChannels = 300; % eliminujemy pik zero
            spectrumData = spectrum(ignoreChannels:end);

            [~, maxIdx] = max(spectrumData);
            maxIdx = maxIdx + ignoreChannels - 1;
            windowSize = 15;

            [searchRange, regionData] = app.getWindow(spectrum, maxIdx, windowSize);
            [simpleMaxChannel, centroidChannel] = app.computeCentroid(searchRange, regionData);

            % AUTO Gaussian fit
            [peakChannel, fitSuccessful, fittedCurve, fwhm] = tryGaussianFit( ...
                searchRange, regionData, max(regionData), centroidChannel, windowSize);

            if fitSuccessful && ~isnan(fwhm)
                app.FWHMData.detector1(idx) = fwhm;
            end

            % Plot zoomed region
            viewSize = 70;
            colors = struct('plot', 'r', 'mark1', 'go', 'mark2', 'mo', 'center', 'bo', 'fit', 'b--', 'line', 'b');
            app.plotZoomed(spectrum, peakChannel, viewSize, simpleMaxChannel, centroidChannel, fittedCurve, fitSuccessful, colors);

            % Detection method
            methodUsed = app.detectMethod(peakChannel, centroidChannel, fitSuccessful);

            % Subtitle info
            peakIdx = max(1, min(numel(spectrum), round(peakChannel)));

            if fitSuccessful && ~isempty(fittedCurve)
                yPeak = feval(fittedCurve, peakChannel);   % wartość w punkcie ułamkowym
            else
                yPeak = spectrum(peakIdx);                 % fallback na próbkę
            end

            app.updateSubtitle(peakChannel, yPeak, methodUsed);

            app.PeakChannelDet1 = peakChannel;

            while true
                [x, ~, button] = ginput(1);

                % Enter lub zamknięcie okna => koniec
                if isempty(x) || button == 13
                    break;
                end

                % prawy przycisk myszy => unzoom do pełnego widma
                if button == 3
                    plotFullSpectrum();
                    continue;
                end

                % Lewy klik => ręczny wybór i lokalny zoom
                manualChannel = round(x);
                manualChannel = max(1, min(length(spectrum), manualChannel));

                [searchRange, regionData] = app.getWindow(spectrum, manualChannel, windowSize);

                % MANUAL Gaussian fit
                [manualPeak, fitSuccessful, fittedCurve, fwhm] = tryGaussianFit( ...
                    searchRange, regionData, max(regionData), manualChannel, windowSize / 2);

                if fitSuccessful
                    methodUsed = 'Gaussian Fit (Manual)';
                    if ~isnan(fwhm)
                        app.FWHMData.detector1(idx) = fwhm;
                    else
                    end
                else
                    methodUsed = 'Manual Selection';
                    manualPeak = manualChannel;
                end

                app.PeakChannelDet1 = manualPeak;

                app.plotZoomed(spectrum, manualPeak, viewSize, manualChannel, [], fittedCurve, fitSuccessful, colors);
                
                manualIdx = max(1, min(numel(spectrum), round(manualPeak)));

                if fitSuccessful && ~isempty(fittedCurve)
                    yPeak = feval(fittedCurve, manualPeak);
                else
                    yPeak = spectrum(manualIdx);
                end

                app.updateSubtitle(manualPeak, yPeak, methodUsed);


                pause(0.1);
            end

            close(fig);

            %% Helper GaussianFit function
            function [peak, success, fitObj, fwhm] = tryGaussianFit(x, y, amp, mu, sigmaInit)
                try
                    x = double(x);
                    y = double(y);
                    model = fittype('a*exp(-((x-b)^2)/(2*c^2))', 'independent', 'x');
                    opts = fitoptions('Method', 'NonlinearLeastSquares', ...
                        'StartPoint', [amp, mu, sigmaInit], ...
                        'Lower', [0, min(x), 0], ...
                        'Upper', [amp*2, max(x), sigmaInit*2]);

                    [fitObj, gof] = fit(x', y', model, opts);

                    if gof.rsquare > 0.7
                        peak = fitObj.b;
                        peak = max(min(x), min(max(x), peak));
                        success = true;
                        fwhm = 2 * sqrt(2 * log(2)) * abs(fitObj.c);
                    else
                        peak = mu;
                        fitObj = [];
                        success = false;
                        fwhm = NaN;
                    end
                catch
                    peak = mu;
                    fitObj = [];
                    success = false;
                    fwhm = NaN;
                end
            end
        end


        function peakSelection_d2(app, selectedElement)
            fig = figure('Name', ['Select ' selectedElement ' peak for Detector 2'], 'NumberTitle', 'off');

            spectrum = app.Det2Spectrum;

            function plotFullSpectrum()
                ax = gca;
                cla(ax, 'reset');
                plot(ax, spectrum, 'b', 'LineWidth', 1.5);
                title(ax, ['Auto-detection of ' selectedElement ' K\alpha peak for Detector 2']);
                xlabel(ax, 'Channel');
                ylabel(ax, 'Counts');
                grid(ax, 'on');
            end

            plotFullSpectrum();


            if ~isfield(app.FWHMData, 'detector2') || isempty(app.FWHMData.detector2)
                app.FWHMData.detector2 = [];
            end

            idx = numel(app.FWHMData.detector2) + 1;
            app.FWHMData.detector2(idx) = NaN;

            ignoreChannels = 300;
            spectrumData = spectrum(ignoreChannels:end);

            [~, maxIdx] = max(spectrumData);
            maxIdx = maxIdx + ignoreChannels - 1;
            windowSize = 15;

            [searchRange, regionData] = app.getWindow(spectrum, maxIdx, windowSize);
            [simpleMaxChannel, centroidChannel] = app.computeCentroid(searchRange, regionData);

            [peakChannel, fitSuccessful, fittedCurve, fwhm] = tryGaussianFit( ...
                searchRange, regionData, max(regionData), centroidChannel, windowSize);

            if fitSuccessful && ~isnan(fwhm)
                app.FWHMData.detector2(idx) = fwhm;
            end

            % Plot zoomed region
            viewSize = 70;
            colors = struct('plot', 'b', 'mark1', 'go', 'mark2', 'mo', 'center', 'ro', 'fit', 'r--', 'line', 'r');
            app.plotZoomed(spectrum, peakChannel, viewSize, simpleMaxChannel, centroidChannel, fittedCurve, fitSuccessful, colors);

            % Detection method
            methodUsed = app.detectMethod(peakChannel, centroidChannel, fitSuccessful);

            % Subtitle info
            peakIdx = max(1, min(numel(spectrum), round(peakChannel)));

            if fitSuccessful && ~isempty(fittedCurve)
                yPeak = feval(fittedCurve, peakChannel);   % wartość w punkcie ułamkowym
            else
                yPeak = spectrum(peakIdx);                 % fallback 
            end

            app.updateSubtitle(peakChannel, yPeak, methodUsed);

            app.PeakChannelDet2 = peakChannel;

            while true
                [x, ~, button] = ginput(1);

                % Enter lub zamknięcie okna => koniec
                if isempty(x) || button == 13
                    break;
                end

                % prawy przycisk myszy => unzoom do pełnego widma
                if button == 3
                    plotFullSpectrum();
                    continue;
                end

                % Lewy klik => ręczny wybór i lokalny zoom
                manualChannel = round(x);
                manualChannel = max(1, min(length(spectrum), manualChannel));

                [searchRange, regionData] = app.getWindow(spectrum, manualChannel, windowSize);

                % MANUAL Gaussian fit
                [manualPeak, fitSuccessful, fittedCurve, fwhm] = tryGaussianFit( ...
                    searchRange, regionData, max(regionData), manualChannel, windowSize / 2);

                if fitSuccessful
                    methodUsed = 'Gaussian Fit (Manual)';
                    if ~isnan(fwhm)
                        app.FWHMData.detector2(idx) = fwhm;
                    else
                    end
                else
                    methodUsed = 'Manual Selection';
                    manualPeak = manualChannel;
                end

                app.PeakChannelDet2 = manualPeak;

                app.plotZoomed(spectrum, manualPeak, viewSize, manualChannel, [], fittedCurve, fitSuccessful, colors);
                manualIdx = max(1, min(numel(spectrum), round(manualPeak)));

                if fitSuccessful && ~isempty(fittedCurve)
                    yPeak = feval(fittedCurve, manualPeak);
                else
                    yPeak = spectrum(manualIdx);
                end

                app.updateSubtitle(manualPeak, yPeak, methodUsed);


                pause(0.1);
            end

            close(fig);

            %% Helper GaussianFit function
            function [peak, success, fitObj, fwhm] = tryGaussianFit(x, y, amp, mu, sigmaInit)
                try
                    x = double(x);
                    y = double(y);
                    model = fittype('a*exp(-((x-b)^2)/(2*c^2))', 'independent', 'x');
                    opts = fitoptions('Method', 'NonlinearLeastSquares', ...
                        'StartPoint', [amp, mu, sigmaInit], ...
                        'Lower', [0, min(x), 0], ...
                        'Upper', [amp*2, max(x), sigmaInit*2]);

                    [fitObj, gof] = fit(x', y', model, opts);

                    if gof.rsquare > 0.7
                        peak = fitObj.b;
                        peak = max(min(x), min(max(x), peak));
                        success = true;
                        fwhm = 2 * sqrt(2 * log(2)) * abs(fitObj.c);
                    else
                        peak = mu;
                        fitObj = [];
                        success = false;
                        fwhm = NaN;
                    end
                catch
                    peak = mu;
                    fitObj = [];
                    success = false;
                    fwhm = NaN;
                end
            end

        end


        %% Helpery do PeakSelection
        function [range, data] = getWindow(~, spectrum, center, width)
            % minC = max(1, center - width);
            % maxC = min(length(spectrum), center + width);
            % range = minC:maxC;
            % data = spectrum(range);
            cIdx = max(1, min(numel(spectrum), round(center)));
            minC = max(1, cIdx - width);
            maxC = min(numel(spectrum), cIdx + width);
            range = minC:maxC;
            data = spectrum(range);
        end

        function [maxC, centroidC] = computeCentroid(~, range, data)
            [~, iMax] = max(data);
            maxC = range(iMax);
            weights = data - min(data);
            weights(weights < 0) = 0;
            if sum(weights) > 0
                centroid = sum(range .* weights) / sum(weights);
                centroidC = round(centroid);
            else
                centroidC = maxC;
            end
        end

        function txt = detectMethod(~, peak, centroid, success)
            if success
                txt = 'Gaussian Fit';
            elseif peak == centroid
                txt = 'Centroid';
            else
                txt = 'Simple Maximum';
            end
        end


        function plotZoomed(~, spectrum, center, range, mark1, mark2, fitObj, success, colors)
            clf;

            n = numel(spectrum);
            centerIdx = max(1, min(n, round(center)));

            minV = max(1, floor(center - range));
            maxV = min(n, ceil(center + range));
            xRange = minV:maxV;

            plot(xRange, spectrum(xRange), colors.plot, 'LineWidth', 1.5); hold on;
            if ~isempty(mark1), plot(mark1, spectrum(mark1), colors.mark1, 'MarkerSize', 8); end
            if ~isempty(mark2), plot(mark2, spectrum(mark2), colors.mark2, 'MarkerSize', 8); end

            plot(center, spectrum(centerIdx), colors.center, 'MarkerSize', 12, 'LineWidth', 2);

            if success && ~isempty(fitObj)
                xFit = linspace(minV, maxV, 100);
                yFit = feval(fitObj, xFit);
                plot(xFit, yFit, colors.fit, 'LineWidth', 1.5);
            end

            line([center, center], [0, spectrum(centerIdx)], 'Color', colors.line, 'LineStyle', ':', 'LineWidth', 1.5);
            xlabel('Channel'); ylabel('Counts'); grid on;
        end


        function updateSubtitle(~, channel, count, method)
            idx = round(channel);
            txt = sprintf(['Peak at channel %.3f (Idx %d) | Count value: %.0f\n' ...
                'Method: %s\n' ...
                'Press ENTER to confirm or CLICK to select manually'], ...
                channel, idx, count, method);
            subtitle(txt, 'Interpreter', 'none');
        end


        %% Koniec helperów do PeakSelection


        function [fwhmParams, results] = CalculateFWHMCalibrationParameters(app, calibrationData)
            if nargin < 2 || isempty(calibrationData)
                calibrationData = app.CalibrationData;
            end

            numStandards = numel(calibrationData.Standards);

            nFwhm1 = numel(app.FWHMData.detector1);
            nFwhm2 = numel(app.FWHMData.detector2);
            numStandards = min([numStandards, nFwhm1, nFwhm2]);

            if numStandards < 1
                error('CalculateFWHMCalibrationParameters: no common standards between calibrationData and FWHMData.');
            end

            fwhm1 = zeros(1, numStandards);
            fwhm2 = zeros(1, numStandards);
            energy = zeros(1, numStandards);

            % Przeliczenie FWHM z kanałów na keV (przy użyciu gain) i pobranie energii
            for i = 1:numStandards
                energy(i) = calibrationData.Energies{i};
                fwhm1(i) = app.FWHMData.detector1(i) * app.CalibParams.detector1.a; % FWHM w keV
                fwhm2(i) = app.FWHMData.detector2(i) * app.CalibParams.detector2.a; % FWHM w keV
            end

            % Przeliczenie na eV
            energy_eV = energy * 1000;
            fwhm1_eV = fwhm1 * 1000;
            fwhm2_eV = fwhm2 * 1000;

            % Obliczenie kwadratów FWHM
            fwhm1_sq = fwhm1_eV .^ 2;
            fwhm2_sq = fwhm2_eV .^ 2;

            % Dopasowanie modelu: FWHM^2 = NOISE^2 + k * E
            % Dla detektora 1
            p1 = polyfit(energy_eV, fwhm1_sq, 1);
            noise1_eV = sqrt(max(p1(2), 0)); % NOISE w eV
            k1 = p1(1);                      % k w eV

            % Dla detektora 2
            p2 = polyfit(energy_eV, fwhm2_sq, 1);
            noise2_eV = sqrt(max(p2(2), 0)); % NOISE w eV
            k2 = p2(1);                      % k w eV

            %% Wyznaczenie FANO:
            w_eV = app.PairCreationEnergy_eV;
            C = (2.35482004503)^2 * w_eV;
            fano1 = max(k1 / C, 0);
            fano2 = max(k2 / C, 0);

            % Zapisz parametry w strukturach
            fwhmParams = struct(...
                'detector1', struct('noise', noise1_eV, 'k', k1, 'fano', fano1), ...
                'detector2', struct('noise', noise2_eV, 'k', k2, 'fano', fano2), ...
                'w_eV', w_eV);

            % Oblicz teoretyczne FWHM dla każdej energii
            fit1_sq = noise1_eV^2 + k1 * energy_eV;
            fit2_sq = noise2_eV^2 + k2 * energy_eV;
            fit1_eV = sqrt(fit1_sq);
            fit2_eV = sqrt(fit2_sq);
            fit1 = fit1_eV / 1000; % powrót do keV
            fit2 = fit2_eV / 1000;

            % RMSE w keV
            rmse1 = sqrt(mean((fit1 - fwhm1).^2));
            rmse2 = sqrt(mean((fit2 - fwhm2).^2));

            stdLabels = calibrationData.Standards(1:numStandards);
            results = struct('standards', {stdLabels}, 'energy', energy, ...
                'fwhm1', fwhm1, ...
                'fwhm2', fwhm2, ...
                'fit1', fit1, ...
                'fit2', fit2, ...
                'rmse1', rmse1, ...
                'rmse2', rmse2);

            assignin('base', 'results_FWHM', results);
            assignin('base', 'fwhmParams', fwhmParams);
        end


        function DisplayFWHMCalibrationResults(app, results)
            E = results.energy;
            fwhm1 = results.fwhm1;
            fwhm2 = results.fwhm2;
            fit1 = results.fit1;
            fit2 = results.fit2;
            n = numel(results.standards);

            % Detektor 1
            cla(app.UIAxes_FWHM_cal_d1);
            scatter(app.UIAxes_FWHM_cal_d1, E, fwhm1, 'ro');
            hold(app.UIAxes_FWHM_cal_d1, 'on');
            E_smooth = linspace(min(E), max(E), 200);
            E_smooth_eV = E_smooth * 1000;
            fit1_smooth_eV = sqrt(app.FWHMParams.detector1.noise^2 + app.FWHMParams.detector1.k * E_smooth_eV);
            fit1_smooth = fit1_smooth_eV / 1000;
            plot(app.UIAxes_FWHM_cal_d1, E_smooth, fit1_smooth, 'r-', 'LineWidth', 1.5);
            hold(app.UIAxes_FWHM_cal_d1, 'off');
            xlabel(app.UIAxes_FWHM_cal_d1, 'Energy [keV]');
            ylabel(app.UIAxes_FWHM_cal_d1, 'FWHM [keV]');
            title(app.UIAxes_FWHM_cal_d1, 'FWHM Calibration - Detector 1', 'Color', 'w');
            grid(app.UIAxes_FWHM_cal_d1, 'on');
            app.UIAxes_FWHM_cal_d1.XColor = 'w';
            app.UIAxes_FWHM_cal_d1.YColor = 'w';

            % Detektor 2
            cla(app.UIAxes_FWHM_cal_d2);
            scatter(app.UIAxes_FWHM_cal_d2, E, fwhm2, 'bo');
            hold(app.UIAxes_FWHM_cal_d2, 'on');
            fit2_smooth_eV = sqrt(app.FWHMParams.detector2.noise^2 + app.FWHMParams.detector2.k * E_smooth_eV);
            fit2_smooth = fit2_smooth_eV / 1000;
            plot(app.UIAxes_FWHM_cal_d2, E_smooth, fit2_smooth, 'b-', 'LineWidth', 1.5);
            hold(app.UIAxes_FWHM_cal_d2, 'off');
            xlabel(app.UIAxes_FWHM_cal_d2, 'Energy [keV]');
            ylabel(app.UIAxes_FWHM_cal_d2, 'FWHM [keV]');
            title(app.UIAxes_FWHM_cal_d2, 'FWHM Calibration - Detector 2', 'Color', 'w');
            grid(app.UIAxes_FWHM_cal_d2, 'on');
            app.UIAxes_FWHM_cal_d2.XColor = 'w';
            app.UIAxes_FWHM_cal_d2.YColor = 'w';

            % Residuals - Detektor 1
            cla(app.UIAxes_FWHM_error_d1);
            bar(app.UIAxes_FWHM_error_d1, 1:n, (results.fit1 - results.fwhm1)*1000 , 'r');
            app.UIAxes_FWHM_error_d1.XTick = 1:n;
            app.UIAxes_FWHM_error_d1.XTickLabel = results.standards;
            title(app.UIAxes_FWHM_error_d1, sprintf('Detector 1 Errors (RMSE: %.3f keV)', results.rmse1), 'Color', 'w');
            ylabel(app.UIAxes_FWHM_error_d1, '\DeltaFWHM = FWHM_{fit} - FWHM_{ref} [eV]');
            grid(app.UIAxes_FWHM_error_d1, 'on');
            app.UIAxes_FWHM_error_d1.XColor = 'w';
            app.UIAxes_FWHM_error_d1.YColor = 'w';

            % Residuals - Detektor 2
            cla(app.UIAxes_FWHM_error_d2);
            bar(app.UIAxes_FWHM_error_d2, 1:n, (results.fit2 - results.fwhm2)*1000, 'b');
            app.UIAxes_FWHM_error_d2.XTick = 1:n;
            app.UIAxes_FWHM_error_d2.XTickLabel = results.standards;
            title(app.UIAxes_FWHM_error_d2, sprintf('Detector 2 Errors (RMSE: %.3f keV)', results.rmse2), 'Color', 'w');
            ylabel(app.UIAxes_FWHM_error_d2, '\DeltaFWHM = FWHM_{fit} - FWHM_{ref} [eV]');
            grid(app.UIAxes_FWHM_error_d2, 'on');
            app.UIAxes_FWHM_error_d2.XColor = 'w';
            app.UIAxes_FWHM_error_d2.YColor = 'w';

            % Wyświetlanie równania kalibracji
            noise1_keV = app.FWHMParams.detector1.noise / 1000;
            k1_keV = app.FWHMParams.detector1.k / 1000;
            noise2_keV = app.FWHMParams.detector2.noise / 1000;
            k2_keV = app.FWHMParams.detector2.k / 1000;

          
            app.FWHM_Calib_Function_label_d1.Interpreter = 'latex';
            app.FWHM_Function_label_d1.Text = sprintf('$~ \\sqrt{%.6f^2 + %.6f \\cdot E} $', ...
                noise1_keV, k1_keV);

            app.FWHM_Calib_Function_label_d2.Interpreter = 'latex';
            app.FWHM_Function_label_d2.Text = sprintf('$~ \\sqrt{%.6f^2 + %.6f \\cdot E} $', ...
                noise2_keV, k2_keV);


            % SensitivityTab
            app.FANO1_sensitivity_tab_value.Value = num2str(app.FWHMParams.detector1.fano);
            app.NOISE1_sensitivity_tab_value.Value = num2str(app.FWHMParams.detector1.noise);
            app.FANO2_sensitivity_tab_value.Value = num2str(app.FWHMParams.detector2.fano);
            app.NOISE2_sensitivity_tab_value.Value = num2str(app.FWHMParams.detector2.noise);

            app.updateSensitivityButtonsForCalibratedElements(); % Zapalenie dużych przycisków
        end


        function [isOutOfLimits, message] = checkLimitsAndShowMessage(app, symbol)
            isOutOfLimits = false; message = '';

            if isempty(app.init_x) || isempty(app.init_z) || isempty(app.symbol_to_pos)
                return; 
            end
            if ~isKey(app.symbol_to_pos, symbol)
                return;
            end

            positon = app.symbol_to_pos(symbol);
            r = positon(1); c = positon(2);
            [element_x, element_z] = coords_for_rowcol(app, r, c);

            if isnan(element_x) || isnan(element_z)
                return;
            end
            
            halfScanX = 0.25;  % mm
            halfScanZ = 1;     %mm

            
            x_out_of_limits = ((element_x - halfScanX) < app.x_lim_neg) || ((element_x + halfScanX) > app.x_lim_pos);
            z_out_of_limits = ((element_z - halfScanZ) < app.z_lim_neg) || ((element_z + halfScanZ) > app.z_lim_pos);

            for lamp_idx = 1:5
                lampName = sprintf('Lamp_%s_%d', symbol, lamp_idx);

                switch lamp_idx
                    case 1, normal_color = [0.00, 0.63, 0.89];
                    case 2, normal_color = [0.33, 0.69, 0.23];
                    case 3, normal_color = [1.00, 0.80, 0.00];
                    case 4, normal_color = [0.89, 0.11, 0.18];
                    case 5, normal_color = [0.52, 0.17, 0.52];
                end
                if isprop(app, lampName)
                    if x_out_of_limits || z_out_of_limits
                        app.(lampName).Color = [0.17,0.16,0.16];
                        if strcmp(app.(lampName).Enable,'on'), app.(lampName).Enable = 'off'; end
                    else
                        app.(lampName).Color = normal_color;
                    end
                end
            end

            if x_out_of_limits
                message = sprintf('X = %.2f mm (scan: %.2f, %.2f) is outside limits for %s', ...
                    element_x, element_x - halfScanX, element_x + halfScanX, symbol);
                isOutOfLimits = true;
            elseif z_out_of_limits
                message = sprintf('Z = %.2f mm (scan: %.2f, %.2f) is outside limits for %s', ...
                    element_z, element_z - halfScanZ, element_z + halfScanZ, symbol);
                isOutOfLimits = true;
            end
        end


        % Aktualizuje kolory wszystkich lampek na podstawie aktualnych współrzędnych
        function updateAllLimitLamps(app)
            if isempty(app.symbol_to_pos)
                return;
            end
            keysList = keys(app.symbol_to_pos);
            for i = 1:numel(keysList)
                symbol = keysList{i};
                [~,~] = checkLimitsAndShowMessage(app, symbol);
            end
        end


        function markElementAsMeasured(app, symbol)
            bigBtnName = [symbol 'Button']; % np. 'ZnButton'

            if isprop(app, bigBtnName)
                try
                    app.(bigBtnName).FontColor = [0 0.8 0];  % zielona czcionka
                    app.(bigBtnName).Enable    = 'on';

                    app.(bigBtnName).ButtonPushedFcn = @(src, event) ...
                        toggleWszystkieLampki(app, symbol);
                catch
                end
            end
        end


        function updateSensitivityButtonsForCalibratedElements(app)
            if isempty(app.CalibrationData) || ~isfield(app.CalibrationData, 'Standards')
                return;
            end

            elems  = app.CalibrationData.Standards;
            fields = fieldnames(app);

            for i = 1:numel(elems)
                symbol        = elems{i};
                bigBtnName    = [symbol 'Button'];      % np. 'ZnButton'
                prefix_KA_btn = ['K_alpha_' symbol];    % mały przycisk Kα
                prefix_KB_btn = ['K_beta_'  symbol];    % mały przycisk Kβ

                if ismember(bigBtnName, fields)
                    markElementAsMeasured(app, symbol);
                    try
                        if isprop(app, prefix_KA_btn)
                            app.(prefix_KA_btn).Enable = 'on';
                            app.(prefix_KA_btn).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 1);  % Kα -> lampka 1
                        end

                        if isprop(app, prefix_KB_btn)
                            app.(prefix_KB_btn).Enable = 'on';
                            app.(prefix_KB_btn).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 2);  % Kβ -> lampka 2
                        end
                    catch
                    end
                end
            end
        end


        function sigma = calculateSigmaFromParams(~, E_keV, noise_keV, fano)
            sigma = sqrt((noise_keV/2.3548)^2 + 0.00385 * fano * E_keV);
        end

        function calculateSensitivityForAllStandards(app, activeLines)
            surfaceMassData = GetSurfaceMass(app);
            app.SensitivityResults = struct();
            app.SensitivityPlotElements = {};

            for stdIdx = 1:length(app.CalibrationData.Standards)
                symbol = app.CalibrationData.Standards{stdIdx};

                if stdIdx > length(app.Pending)
                    continue;
                end

                P = app.Pending(stdIdx);
                mean_I0 = P.mean_I_0_map;
                live_time_d1 = P.sum_live_time_1;
                live_time_d2 = P.sum_live_time_2;

                surface_mass = surfaceMassData.(symbol).surface_mass;

                spec1 = app.AllDet1SpectrumCalib{stdIdx};
                spec2 = app.AllDet2SpectrumCalib{stdIdx};

                for lineIdx = 1:length(activeLines)
                    line = activeLines{lineIdx};
                    if ~strcmp(line.symbol, symbol)
                        continue;
                    end

                    lineName = line.lineName;

                    % POPRAWKA: Bezpieczny dostęp do ROI
                    if ~isfield(app.SensitivityROIs, symbol) || ~isfield(app.SensitivityROIs.(symbol), lineName)
                        continue;
                    end

                    roi = app.SensitivityROIs.(symbol).(lineName);

                    % Peak Area dla d1 i d2
                    mask1 = (spec1.energy >= roi.det1_ROI(1)) & (spec1.energy <= roi.det1_ROI(2));
                    if sum(mask1) == 0
                        peakArea1 = 0;
                    else
                        peakArea1 = sum(spec1.counts(mask1));
                    end

                    mask2 = (spec2.energy >= roi.det2_ROI(1)) & (spec2.energy <= roi.det2_ROI(2));
                    if sum(mask2) == 0
                        peakArea2 = 0;
                    else
                        peakArea2 = sum(spec2.counts(mask2));
                    end

                    if mean_I0 > 0 && live_time_d1 > 0 && live_time_d2 > 0 && surface_mass > 0
                        intensity1 = peakArea1 / (mean_I0 * live_time_d1);
                        intensity2 = peakArea2 / (mean_I0 * live_time_d2);
                        sensitivity1 = intensity1 / surface_mass;
                        sensitivity2 = intensity2 / surface_mass;
                    else
                        intensity1 = NaN;
                        intensity2 = NaN;
                        sensitivity1 = NaN;
                        sensitivity2 = NaN;
                    end

                    % Inicjalizuj strukturę jeśli nie istnieje
                    if ~isfield(app.SensitivityResults, lineName)
                        app.SensitivityResults.(lineName) = struct();
                    end

                    app.SensitivityResults.(lineName).(symbol) = struct(...
                        'Z', line.Z, ...
                        'expectedEnergy', line.expectedEnergy, ...
                        'foundEnergy1', roi.foundEnergy1, ...
                        'foundEnergy2', roi.foundEnergy2, ...
                        'fitSuccess1', roi.fitSuccess1, ...
                        'fitSuccess2', roi.fitSuccess2, ...
                        'peakArea1', peakArea1, ...
                        'peakArea2', peakArea2, ...
                        'intensity1', intensity1, ...
                        'intensity2', intensity2, ...
                        'sensitivity1', sensitivity1, ...
                        'sensitivity2', sensitivity2, ...
                        'mean_I0', mean_I0, ...
                        'live_time_d1', live_time_d1, ...
                        'live_time_d2', live_time_d2, ...
                        'surface_mass', surface_mass, ...
                        'ROI1', roi.det1_ROI, ...
                        'ROI2', roi.det2_ROI ...
                        );
                end
            end
            assignin('base', 'SensitivityResults', app.SensitivityResults);
        end
        

        function plot_sensitivity_for_index(app, index)
            currentIndex = app.CurrentSpectrumIndex;
            if isempty(app.AllDet1Spectra)
                return;
            end

            currentIndex = max(1, min(currentIndex, numel(app.AllDet1Spectra)));

            % 1) Najpierw wyświetl widmo w aktualnej skali
            plot_stored_spectrum(app, currentIndex);

            % 2) Jeśli checkbox ROI jest zaznaczony, dodaj ROI
            if ~isprop(app,'ShowROIsCheckBox') || ~logical(app.ShowROIsCheckBox.Value)
                return;
            end
            if isempty(app.SensitivityROIs)
                return;
            end

            % Sprawdź czy aktualny indeks odpowiada standardowi kalibracji
            if currentIndex <= length(app.CalibrationData.Standards)
                symbol = app.CalibrationData.Standards{currentIndex};
            else
                return;
            end

            % Sprawdź czy istnieją ROI dla tego symbolu
            if ~isfield(app.SensitivityROIs, symbol)
                return;
            end

            rois = app.SensitivityROIs.(symbol);

            % 3) Dodaj ROI do wykresów
            % Pobierz dane spektralne dla aktualnego indeksu
            spec1 = app.AllDet1Spectra{currentIndex};
            spec2 = app.AllDet2Spectra{currentIndex};

            % Narysuj ROI na istniejących wykresach
            drawROIsOnAxes(app.UIAxes_det_1, spec1, rois, 'det1_ROI', 1);
            drawROIsOnAxes(app.UIAxes_det_2, spec2, rois, 'det2_ROI', 2);

            app.UIAxes_det_1.YScale = app.CurrentYScale;
            app.UIAxes_det_2.YScale = app.CurrentYScale;

            % Pomocnicza funkcja do rysowania ROI
            function drawROIsOnAxes(ax, spec, roisStruct, roiFieldName, detector)
                if isempty(spec) || ~isvalid(ax)
                    return;
                end
                lineNames = fieldnames(roisStruct);
                hold(ax,'on');

                originalYScale = ax.YScale;
                originalYLim = ylim(ax);

                ymax = originalYLim(2);

                for k = 1:numel(lineNames)
                    lineName = lineNames{k};
                    R = roisStruct.(lineName);
                    if ~isfield(R, roiFieldName) || ~isfield(R, 'color') || ~isfield(R, 'foundEnergy1') || ~isfield(R, 'foundEnergy2')
                        continue;
                    end

                    rr = R.(roiFieldName);  % [Emin Emax]
                    if detector == 1
                        energy = R.foundEnergy1;
                    else
                        energy = R.foundEnergy2;
                    end

                    % fallback żeby nie było dymów z log z 0
                    if strcmp(originalYScale, 'log')
                        positiveCounts = spec(spec > 0);
                        if ~isempty(positiveCounts)
                            minPositive = min(positiveCounts);
                            y_bottom = max(minPositive * 0.1, 1);
                        else
                            y_bottom = 1;
                        end
                    else
                        y_bottom = 0;
                    end

                    % fill pod krzywą w ROI  =====
                    hLines = findobj(ax, 'Type', 'Line');
                    if isempty(hLines)
                        continue;
                    end
                    lens = arrayfun(@(h) numel(h.XData), hLines);
                    [~, idxMax] = max(lens);
                    x = hLines(idxMax).XData;
                    y = hLines(idxMax).YData;

                    mask = (x >= rr(1)) & (x <= rr(2));

                    x_roi = x(mask);  x_roi = x_roi(:)';   
                    y_roi = y(mask);  y_roi = y_roi(:)';

                    Xpoly = [x_roi, fliplr(x_roi)];
                    Ypoly = [y_roi, y_bottom*ones(1, numel(y_roi))];

                    fill(ax, Xpoly, Ypoly, R.color, ...
                        'FaceAlpha', 0.25, 'EdgeColor', 'none', 'DisplayName', [lineName ' ROI']);

                    % fill(ax, [rr(1) rr(2) rr(2) rr(1)], [y_bottom y_bottom 0.7*ymax 0.7*ymax], R.color, ...
                    %     'FaceAlpha', 0.25, 'EdgeColor', 'none', 'DisplayName', [lineName ' ROI']);

                    plot(ax, [energy energy], [y_bottom 0.7*ymax], '--', 'Color', R.color, ...
                        'LineWidth', 1, 'DisplayName', [lineName ' Energy']);
                end

                hold(ax,'off');

                ax.YScale = originalYScale;
            end
        end

        function plotSensitivityResults(app) % Current page determined by app.CurrentSensitivityLineIndex
            % 1) sensitivity vs Z (detector 1)
            % 2) sensitivity vs Z (detector 2)
            % 3) sensitivity vs Found_E1 (detector 1)
            % 4) sensitivity vs Found_E2 (detector 2)

            % Clear axes if no data
            if isempty(app.SensitivityResults)
                cla(app.UIAxes_Sensitivity1);
                cla(app.UIAxes_Sensitivity2);
                cla(app.UIAxes_Sensitivity3);
                cla(app.UIAxes_Sensitivity4);
                return;
            end

            % Ensure index valid
            nLines = numel(app.SensitivityLineNames);
            if isempty(app.CurrentSensitivityLineIndex) || app.CurrentSensitivityLineIndex < 1
                app.CurrentSensitivityLineIndex = 1;
            elseif app.CurrentSensitivityLineIndex > nLines
                app.CurrentSensitivityLineIndex = nLines;
            end

            lineName = app.SensitivityLineNames{app.CurrentSensitivityLineIndex};

            % Extract data for this line (robust)
            Z = []; sens1 = []; sens2 = []; foundE1 = []; foundE2 = []; symbols = {};
            if isfield(app.SensitivityResults, lineName)
                S = app.SensitivityResults.(lineName);
                syms = fieldnames(S);
                for i = 1:numel(syms)
                    rec = S.(syms{i});
                    symbols{end+1} = syms{i};
                    if isfield(rec,'Z'), Z(end+1) = double(rec.Z); else Z(end+1) = NaN; end
                    if isfield(rec,'sensitivity1'), sens1(end+1) = rec.sensitivity1; else sens1(end+1) = NaN; end %#ok<AGROW>
                    if isfield(rec,'sensitivity2'), sens2(end+1) = rec.sensitivity2; else sens2(end+1) = NaN; end %#ok<AGROW>
                    if isfield(rec,'foundEnergy1'), foundE1(end+1) = double(rec.foundEnergy1); else foundE1(end+1) = NaN; end %#ok<AGROW>
                    if isfield(rec,'foundEnergy2'), foundE2(end+1) = double(rec.foundEnergy2); else foundE2(end+1) = NaN; end %#ok<AGROW>
                end
            end

            % If empty, clear and exit
            if isempty(Z)
                cla(app.UIAxes_Sensitivity1);
                cla(app.UIAxes_Sensitivity2);
                cla(app.UIAxes_Sensitivity3);
                cla(app.UIAxes_Sensitivity4);
                return;
            end

            % Sort by Z
            [Zs, idx] = sort(Z);
            sens1 = sens1(idx);
            sens2 = sens2(idx);
            foundE1 = foundE1(idx);
            foundE2 = foundE2(idx);
            symbols = symbols(idx);

            lineDispName = lineName;
            lineDispName = strrep(lineDispName, 'K_alpha', 'K_{\alpha}');
            lineDispName = strrep(lineDispName, 'K_beta', 'K_{\beta}');
            lineDispName = strrep(lineDispName, 'L_alpha', 'L_{\alpha}');
            lineDispName = strrep(lineDispName, 'L_beta', 'L_{beta}');
            lineDispName = strrep(lineDispName, 'M_alpha', 'M_{\alpha}');

            % Panel 1: sensitivity (detector1) vs Z
            cla(app.UIAxes_Sensitivity1); hold(app.UIAxes_Sensitivity1,'on');
            valid = isfinite(Zs) & isfinite(sens1);
            if any(valid)
                scatter(app.UIAxes_Sensitivity1, Zs(valid), sens1(valid), 50, 'filled', 'MarkerFaceColor', '#00B7EB', 'MarkerEdgeColor', '#19247C');
            end
            title(app.UIAxes_Sensitivity1, sprintf('%s - Sensitivity vs Z (Detector 1)', lineDispName), 'Color', 'w');
            xlabel(app.UIAxes_Sensitivity1, 'Atomic number (Z)');
            ylabel(app.UIAxes_Sensitivity1, 'Sensitivity [counts·s^{-1}·V^{-1}·(μg·cm^{-2})^{-1}]');
            grid(app.UIAxes_Sensitivity1,'on');
            hold(app.UIAxes_Sensitivity1,'off');

            % Panel 2: sensitivity (detector2) vs Z
            cla(app.UIAxes_Sensitivity2); hold(app.UIAxes_Sensitivity2,'on');
            valid2 = isfinite(Zs) & isfinite(sens2);
            if any(valid2)
                scatter(app.UIAxes_Sensitivity2, Zs(valid2), sens2(valid2), 50, 'filled', 'MarkerFaceColor', '#93F600', 'MarkerEdgeColor', '#326647');
            end
            title(app.UIAxes_Sensitivity2, sprintf('%s - Sensitivity vs Z (Detector 2)', lineDispName), 'Color', 'w');
            xlabel(app.UIAxes_Sensitivity2, 'Atomic number (Z)');
            ylabel(app.UIAxes_Sensitivity2, 'Sensitivity [counts·s^{-1}·V^{-1}·(μg·cm^{-2})^{-1}]');
            grid(app.UIAxes_Sensitivity2,'on');
            hold(app.UIAxes_Sensitivity2,'off');

            % Panel 3: sensitivity D1 vs Found_E1 (energy)
            cla(app.UIAxes_Sensitivity3); hold(app.UIAxes_Sensitivity3,'on');
            validE1 = isfinite(foundE1) & isfinite(sens1);
            if any(validE1)
                scatter(app.UIAxes_Sensitivity3, foundE1(validE1), sens1(validE1), 50, 'filled', 'MarkerFaceColor', '#00B7EB', 'MarkerEdgeColor', '#19247C');
            end
            title(app.UIAxes_Sensitivity3, sprintf('%s - Sensitivity vs E_{fluo} [keV] (Detector 1)', lineDispName), 'Color', 'w');
            xlabel(app.UIAxes_Sensitivity3, 'E_{fluo} [keV]');
            ylabel(app.UIAxes_Sensitivity3, 'Sensitivity [counts·s^{-1}·V^{-1}·(μg·cm^{-2})^{-1}]');
            grid(app.UIAxes_Sensitivity3,'on');
            hold(app.UIAxes_Sensitivity3,'off');

            % Panel 4: sensitivity D2 vs Found_E2 (energy)
            cla(app.UIAxes_Sensitivity4); hold(app.UIAxes_Sensitivity4,'on');
            validE2 = isfinite(foundE2) & isfinite(sens2);
            if any(validE2)
                scatter(app.UIAxes_Sensitivity4, foundE2(validE2), sens2(validE2), 50, 'filled', 'MarkerFaceColor', '#93F600', 'MarkerEdgeColor', '#326647');
            end
            title(app.UIAxes_Sensitivity4, sprintf('%s - Sensitivity vs E_{fluo} [keV] (Detector 2)', lineDispName), 'Color', 'w');
            xlabel(app.UIAxes_Sensitivity4, 'E_{fluo} [keV]');
            ylabel(app.UIAxes_Sensitivity4, 'Sensitivity [counts·s^{-1}·V^{-1}·(μg·cm^{-2})^{-1}]');
            grid(app.UIAxes_Sensitivity4,'on');
            hold(app.UIAxes_Sensitivity4,'off');
        end


        function processAutoMeasurement(app, folderPath, selectedElement)
            % Automatyczne przetwarzanie zmierzonego folderu

            if ~isfolder(folderPath)
                warning('Folder %s does not exist', folderPath);
                return;
            end

            % Inicjalizacja struktur
            if isempty(app.CalibrationData) || ~isfield(app.CalibrationData, 'Standards')
                app.CalibrationData = struct('Standards', {{}}, 'Channels', {{}}, 'Energies', {{}});
            end

            if isempty(app.AllDet1Spectra)
                app.AllDet1Spectra = {};
                app.AllDet2Spectra = {};
                app.AllSpectrumFolderNames = {};
                app.AllDet1SpectrumCalib = {};
                app.AllDet2SpectrumCalib = {};
                app.CalibratedSpectrumStandardNames = {};
            end

            try
                % Wczytaj i przetwórz dane
                app.DataFolder = folderPath;
                files = dir(fullfile(folderPath, '*.mat'));

                if isempty(files)
                    warning('No .mat files in folder: %s', folderPath);
                    return;
                end

                % Znajdywanie odpowiednich plików
                headerFile = files(contains({files.name}, 'HEADER'));
                snapshotFile = files(contains({files.name}, 'snapshot'));
                seqFiles = files(~contains({files.name}, {'HEADER', 'snapshot'}));

                % Sortowanie plikow sekwencji
                [~, sidx] = sort({seqFiles.name});
                seqFiles = seqFiles(sidx);

                if isempty(seqFiles)
                    warning('No sequence files found in folder: %s', folderPath);
                    return;
                end

                % Wczytujemy header i snapshot jeśli istnieją
                if ~isempty(headerFile)
                    app.HeaderData = load(fullfile(folderPath, headerFile(1).name));
                end
                if ~isempty(snapshotFile)
                    app.SnapshotData = load(fullfile(folderPath, snapshotFile(1).name));
                end

                % Sumowanie danych z wszystkich plików sekwencji
                firstFile = load(fullfile(folderPath, seqFiles(1).name));
                if ~isfield(firstFile, 'dane1line')
                    warning('Field dane1line not found in sequence files: %s', folderPath);
                    return;
                end

                [num_detectors, num_pixels, num_channels] = size(firstFile.dane1line);
                totalDane1line = zeros(num_detectors, num_pixels, num_channels, 'like', firstFile.dane1line);
                accStats = zeros(2, 4);

                for j = 1:numel(seqFiles)
                    sf = load(fullfile(folderPath, seqFiles(j).name));
                    if isfield(sf, 'dane1line')
                        totalDane1line = totalDane1line + sf.dane1line;
                    end
                    if isfield(sf, 'stats1line')
                        s = sum(sf.stats1line, 2);
                        s = squeeze(s);
                        accStats = accStats + double(s);
                    end
                end

                % PIN/I0 z ostatniego pliku
                lastSeqFile = load(fullfile(folderPath, seqFiles(end).name));
                if isfield(lastSeqFile, 'PIN_map')
                    app.mean_PIN_map = mean(lastSeqFile.PIN_map(:), 'omitnan');
                else
                    app.mean_PIN_map = NaN;
                end
                if isfield(lastSeqFile, 'I0_map')
                    app.mean_I_0_map = mean(lastSeqFile.I0_map(:), 'omitnan');
                else
                    app.mean_I_0_map = NaN;
                end

                % Aktualizacja wyświetlaczy
                mean_values_disp(app);

                % Statystyki detektorów (µs -> s)
                app.sum_real_time_1 = 1e-6 * accStats(1, 1);
                app.sum_live_time_1 = 1e-6 * accStats(1, 2);
                app.sum_ICR_1 = accStats(1, 3);
                app.sum_OCR_1 = accStats(1, 4);
                app.sum_real_time_2 = 1e-6 * accStats(2, 1);
                app.sum_live_time_2 = 1e-6 * accStats(2, 2);
                app.sum_ICR_2 = accStats(2, 3);
                app.sum_OCR_2 = accStats(2, 4);

                d1_data_disp(app);
                d2_data_disp(app);

                % Wyświetl widmo
                plot_spectrum(app, totalDane1line);

                % Zapisz widma do właściwości
                app.Det1Spectrum = reshape(sum(totalDane1line(1, :, :), 2), [1, num_channels]);
                app.Det2Spectrum = reshape(sum(totalDane1line(2, :, :), 2), [1, num_channels]);

              
                standards = GetStandardsDatabase(app);
                elementEnergy = standards.(selectedElement).energy / 1000; % eV -> keV
                
                % 0)
                existingIdx = find(strcmp(app.CalibrationData.Standards, selectedElement), 1);
                if ~isempty(existingIdx)
                    app.CalibrationData.Standards(existingIdx) = [];
                    app.CalibrationData.Channels(existingIdx)  = [];
                    app.CalibrationData.Energies(existingIdx)  = [];

                    if isfield(app,'FWHMData')
                        if isfield(app.FWHMData,'detector1') && numel(app.FWHMData.detector1) >= existingIdx
                            app.FWHMData.detector1(existingIdx) = [];
                        end
                        if isfield(app.FWHMData,'detector2') && numel(app.FWHMData.detector2) >= existingIdx
                            app.FWHMData.detector2(existingIdx) = [];
                        end
                    end
                end
                newIdx = numel(app.CalibrationData.Standards) + 1;
                
          
                % 1) PIERWSZE 2 standardy -> szukamy piku w kanałach
                if numel(app.CalibrationData.Standards) < 2
                    [ch1, ~, fwhmCh1] = autoFindPeakChannel(app, app.Det1Spectrum);
                    [ch2, ~, fwhmCh2] = autoFindPeakChannel(app, app.Det2Spectrum);

                    app.PeakChannelDet1 = ch1;
                    app.PeakChannelDet2 = ch2;

                else
                    % 2) Od 3-go standardu
                    [app.CalibParams, ~] = CalculateCalibrationParameters(app, app.CalibrationData);

                    gain1 = app.CalibParams.detector1.a;  zero1 = app.CalibParams.detector1.b;
                    gain2 = app.CalibParams.detector2.a;  zero2 = app.CalibParams.detector2.b;

                    ch = 1:length(app.Det1Spectrum);
                    spec1 = struct('energy', gain1*ch + zero1, 'counts', app.Det1Spectrum);
                    spec2 = struct('energy', gain2*ch + zero2, 'counts', app.Det2Spectrum);

                    [E1, ok1, fwhm1] = autoFindPeakEnergy(app, spec1, elementEnergy, 1);
                    [E2, ok2, fwhm2] = autoFindPeakEnergy(app, spec2, elementEnergy, 2);

                    % --- DET 1 ---
                    if ok1 && isfinite(E1)
                        app.PeakChannelDet1 = (E1 - zero1) / gain1;
                        if isfinite(fwhm1)
                            fwhmCh1 = fwhm1 / gain1;  % [ch]
                        end
                    else
                        [ch1, okCh1, fwhmCh1] = autoFindPeakChannel(app, app.Det1Spectrum);
                        if okCh1 && isfinite(ch1)
                            app.PeakChannelDet1 = round(ch1);
                        end
                    end

                    % --- DET 2 ---
                    if ok2 && isfinite(E2)
                        app.PeakChannelDet2 = (E2 - zero2) / gain2;
                        if isfinite(fwhm2)
                            fwhmCh2 = fwhm2 / gain2;  % [ch]
                        end
                    else
                        [ch2, okCh2, fwhmCh2] = autoFindPeakChannel(app, app.Det2Spectrum);
                        if okCh2 && isfinite(ch2)
                            app.PeakChannelDet2 = ch2;
                        end
                    end


                end

                lampKA = sprintf('Lamp_%s_1', selectedElement);
                useForEnergy = isprop(app, lampKA) && strcmp(app.(lampKA).Enable,'on');

                if ~useForEnergy
                    app.PeakChannelDet1 = NaN;
                    app.PeakChannelDet2 = NaN;
                    elementEnergy       = NaN;
                end


                % 3) Zapisz punkt kalibracyjny
                app.CalibrationData.Standards{newIdx} = selectedElement;
                app.CalibrationData.Channels{newIdx}  = [app.PeakChannelDet1, app.PeakChannelDet2];
                app.CalibrationData.Energies{newIdx}  = elementEnergy;

                % 4) Zapisz FWHM w kanałach (w tym samym indeksie co CalibrationData)
                if isempty(app.FWHMData) || ~isfield(app.FWHMData,'detector1') || ~isfield(app.FWHMData,'detector2')
                    app.FWHMData = struct('detector1', [], 'detector2', []);
                end
                app.FWHMData.detector1(newIdx) = NaN;
                app.FWHMData.detector2(newIdx) = NaN;
                if exist('fwhmCh1','var') && isfinite(fwhmCh1), app.FWHMData.detector1(newIdx) = fwhmCh1; end
                if exist('fwhmCh2','var') && isfinite(fwhmCh2), app.FWHMData.detector2(newIdx) = fwhmCh2; end

                % 5) Po zebraniu 2 punktów liczona jest pierwsza robocza kalibracja
                if numel(app.CalibrationData.Standards) >= 2
                    [app.CalibParams, ~] = CalculateCalibrationParameters(app, app.CalibrationData);

                    % zsynchronizuj pola w UI z roboczą kalibracją
                    app.GAIN1_sensitivity_tab_value.Value = num2str(app.CalibParams.detector1.a * 1000); % eV/ch
                    app.ZERO1_sensitivity_tab_value.Value = num2str(app.CalibParams.detector1.b * 1000); % eV
                    app.GAIN2_sensitivity_tab_value.Value = num2str(app.CalibParams.detector2.a * 1000);
                    app.ZERO2_sensitivity_tab_value.Value = num2str(app.CalibParams.detector2.b * 1000);

                    app.LastGoodGAIN_d1 = app.CalibParams.detector1.a * 1000;
                    app.LastGoodZERO_d1 = app.CalibParams.detector1.b * 1000;
                    app.LastGoodGAIN_d2 = app.CalibParams.detector2.a * 1000;
                    app.LastGoodZERO_d2 = app.CalibParams.detector2.b * 1000;
                end

                markElementAsMeasured(app, selectedElement);


                % === PRZYGOTOWANIE REKORDU PENDING ===
                try
                    xrl = py.importlib.import_module('xraylib');
                    Z = double(xrl.SymbolToAtomicNumber(selectedElement));
                catch
                    Z = NaN;
                end

                newPending = struct(...
                    'folder', folderPath, ...
                    'standardLabel', selectedElement, ...
                    'Z', Z, ...
                    'sum_real_time_1', app.sum_real_time_1, ...
                    'sum_live_time_1', app.sum_live_time_1, ...
                    'sum_ICR_1', app.sum_ICR_1, ...
                    'sum_OCR_1', app.sum_OCR_1, ...
                    'sum_real_time_2', app.sum_real_time_2, ...
                    'sum_live_time_2', app.sum_live_time_2, ...
                    'sum_ICR_2', app.sum_ICR_2, ...
                    'sum_OCR_2', app.sum_OCR_2, ...
                    'peakCh1', app.PeakChannelDet1, ...
                    'peakCh2', app.PeakChannelDet2, ...
                    'mean_PIN_map', app.mean_PIN_map, ...
                    'mean_I_0_map', app.mean_I_0_map);

                if isempty(app.Pending)
                    app.Pending = newPending;
                else
                    app.Pending(end + 1) = newPending;
                end

                % === AKTUALIZACJA HISTORII WIDM ===
                app.AllDet1Spectra{end + 1} = app.Det1Spectrum;
                app.AllDet2Spectra{end + 1} = app.Det2Spectrum;
                [~, folderName] = fileparts(folderPath);
                app.AllSpectrumFolderNames{end + 1} = folderName;

                % === KONWERSJA DO SKALI ENERGII UŻYWAJĄC PARAMETRÓW Z PÓL ===
                app.convertSpectraToEnergyScale();

                % === AKTUALIZACJA INTERFEJSU UŻYTKOWNIKA ===
                app.CurrentSpectrumIndex = length(app.AllDet1Spectra);

                % Aktualizuj przyciski nawigacji dla surowych widm
                numPages = length(app.AllDet1Spectra) + 1;
                app.Prev_sp_button.Enable = (app.CurrentSpectrumIndex > 1);
                app.Next_sp_button.Enable = (app.CurrentSpectrumIndex < numPages);

                if ~isempty(app.AllDet1SpectrumCalib)
                    app.CurrentCalibratedSpectrumIndex = length(app.AllDet1SpectrumCalib);
                    numCalibratedPages = length(app.AllDet1SpectrumCalib) + 1;
                    app.Prev_sp_button.Enable = (app.CurrentCalibratedSpectrumIndex > 1);
                    app.Next_sp_button.Enable = (app.CurrentCalibratedSpectrumIndex < numCalibratedPages);
                end

                % Zapisz do Excela
                app.saveCalibrationData_excel();

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
        end


        function [x_mm, z_mm] = coords_for_rowcol(app, row, col)
            % Zabezpieczenie przed wyjebką:
            if isempty(app.init_x) || isempty(app.init_z) || ...
                    isempty(app.reference_row) || isempty(app.reference_col)
                x_mm = NaN; z_mm = NaN; return;
            end
            dx_cols = (col - app.reference_col);
            dz_rows = (row - app.reference_row);
            x_mm = app.init_x - app.spacing_mm * dx_cols;
            z_mm = app.init_z + app.spacing_mm * dz_rows;
        end

        % Helper function
        function zapalLampke(app, symbol, index) % Indeks to linia (Ka=1, KB=2, LA=3, LB=4, MA1=5)
            [isOutOfLimits, message] = checkLimitsAndShowMessage(app, symbol);
            if isOutOfLimits
                disp(message);
                return
            end

            % Zapal wskazaną lampkę po wzorcu Lamp_<pierwiastek>_<numer linii>
            lampName = sprintf('Lamp_%s_%d', symbol, index);
            if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'off')
                app.(lampName).Enable = 'on';  % Zapalona
            else
                app.(lampName).Enable = 'off' ;
            end
            updatePickAllCheckboxes(app);
        end

        % Helper dla toggla dużym przyciskiem
        function toggleWszystkieLampki(app, symbol)

            [isOutOfLimits, message] = checkLimitsAndShowMessage(app, symbol);
            if isOutOfLimits
                disp(message);
                return
            end

            % 1) Małe przyciski linii dla tego pierwiastka
            lineBtns = { ...
                sprintf('K_alpha_%s', symbol), ...
                sprintf('K_beta_%s',  symbol), ...
                sprintf('L_alpha_%s', symbol), ...
                sprintf('L_beta_%s',  symbol), ...
                sprintf('M_alpha_%s',  symbol) ...
                };

            % 2) Zbierz DOSTĘPNE linie (tam gdzie mały przycisk jest Enable='on')
            availableIdx = [];
            for k = 1:5
                btnName = lineBtns{k};
                if isprop(app, btnName) && strcmp(app.(btnName).Enable, 'on')
                    availableIdx(end+1) = k; %#ok<AGROW>
                end
            end
            if isempty(availableIdx)
                return; % bezpieczeństwo – nie powinno wystąpić, skoro duży przycisk jest enabled
            end

            % 3) Czy JAKAKOLWIEK dostępna lampka już świeci
            anyOn = false;
            for k = availableIdx
                lampName = sprintf('Lamp_%s_%d', symbol, k);
                if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'on')
                    anyOn = true;
                    break;
                end
            end

            % 4) Jeżeli jakaś świeci -> gaś wszystkie; inaczej zapal wszystkie
            if anyOn
                newState = 'off';
            else
                newState = 'on';
            end

            for k = availableIdx
                lampName = sprintf('Lamp_%s_%d', symbol, k);
                if isprop(app, lampName)
                    app.(lampName).Enable = newState;
                end
            end
            updatePickAllCheckboxes(app);
        end


        function [foundChannel, success, fwhm] = autoFindPeakChannel(app, spectrum)

            success = false;
            foundChannel = NaN;
            fwhm = NaN;

            % Ignoruj niskie kanały (tło, szum)
            ignoreChannels = 300;
            if length(spectrum) <= ignoreChannels
                searchStart = 1;
            else
                searchStart = ignoreChannels;
            end

            searchSpectrum = spectrum(searchStart:end);

            % Znajdź maksimum w całym obszarze
            [maxCount, maxIdx] = max(searchSpectrum);
            candidateChannel = maxIdx + searchStart - 1;

            % Jeśli maksimum jest zbyt niskie, prawdopodobnie nie ma piku
            if maxCount < 10
                return;
            end

            % Dopasowanie Gaussa wokół kandydata
            windowSize = 20; % kanałów
            minChannel = max(1, candidateChannel - windowSize);
            maxChannel = min(length(spectrum), candidateChannel + windowSize);

            channels = minChannel:maxChannel;
            counts = spectrum(channels);

            try
                model = fittype('a*exp(-((x-b)^2)/(2*c^2))', 'independent', 'x');
                opts = fitoptions('Method', 'NonlinearLeastSquares', ...
                    'StartPoint', [maxCount, candidateChannel, windowSize/3], ...
                    'Lower', [0, min(channels), 0], ...
                    'Upper', [maxCount*5, max(channels), windowSize*2]);

                [fitObj, gof] = fit(channels', counts', model, opts);

                if gof.rsquare > 0.7
                    foundChannel = fitObj.b;
                    success = true;
                    fwhm = 2 * sqrt(2 * log(2)) * abs(fitObj.c); % FWHM w kanałach
                else
                    % Jeśli fit nieudany, użyj centroidu
                    centroidWindow = 10;
                    narrowMin = max(1, round(candidateChannel) - centroidWindow);
                    narrowMax = min(length(spectrum), round(candidateChannel) + centroidWindow);

                    narrowChannels = narrowMin:narrowMax;
                    narrowCounts = spectrum(narrowChannels);

                    if sum(narrowCounts) > 0
                        centroid = sum(narrowChannels .* narrowCounts) / sum(narrowCounts);
                        foundChannel = centroid;
                        success = true;
                        fwhm = NaN;
                    else
                        foundChannel = candidateChannel;
                        success = true;
                        fwhm = NaN;
                    end
                end
            catch
                % bład => maksimum
                foundChannel = candidateChannel;
                success = true;
                fwhm = NaN;
            end
        end


        function [foundEnergy, success, fwhm] = autoFindPeakEnergy(app, spectrum, expectedEnergy, detector)
            success = false;
            foundEnergy = NaN;
            fwhm = NaN;

            CONST_FWHM = 2 * sqrt(2 * log(2));
                
            if expectedEnergy < 3.0
                searchRange = 0.18;
            elseif expectedEnergy < 4.5
                searchRange = 0.2;
            elseif expectedEnergy < 5
                searchRange = 0.36;
            elseif expectedEnergy < 8
                searchRange = 0.72;
            else
                searchRange = 1.2;
            end

            energyRange = [expectedEnergy - searchRange, expectedEnergy + searchRange];
            % Indeksy w zakresie
            energyIdx = spectrum.energy >= energyRange(1) & spectrum.energy <= energyRange(2);
            if sum(energyIdx) < 5
                expandedRange = searchRange * 1.5;
                energyIdx = spectrum.energy >= (expectedEnergy - expandedRange) & ...
                    spectrum.energy <= (expectedEnergy + expandedRange);
                if sum(energyIdx) < 5
                    return;
                end
            end

            subEnergy = spectrum.energy(energyIdx);
            subCounts = spectrum.counts(energyIdx);

            if length(subCounts) > 7
                smoothedCounts = movmean(subCounts, 3);
            else
                smoothedCounts = subCounts;
            end

            % Znajdowanie lokalnych maksimów
            if length(smoothedCounts) > 3
                try
                    [peaks, peakLocs] = findpeaks(smoothedCounts, 'MinPeakProminence', max(smoothedCounts)*0.05);
                    if ~isempty(peaks)
                        peakEnergies = subEnergy(peakLocs);
                        localMaxima = peakLocs;
                    else
                        localMaxima = [];
                    end
                catch
                    localMaxima = find(diff(sign(diff(smoothedCounts))) < 0) + 1;
                end
            else
                localMaxima = [];
            end

            if isempty(localMaxima)
                % nie ma local => bierzemy global max
                [maxCount, maxIdx] = max(smoothedCounts);
                candidateIdx = maxIdx;
                candidateEnergy = subEnergy(maxIdx);
            else
                candidateEnergies = subEnergy(localMaxima);
                candidateHeights = smoothedCounts(localMaxima);

                scores = zeros(size(candidateEnergies));
                for i = 1:length(candidateEnergies)
                    energyDiff = abs(candidateEnergies(i) - expectedEnergy);
                    energyScore = max(0, 1 - energyDiff / searchRange);
                    heightScore = candidateHeights(i) / max(candidateHeights);
                    scores(i) = 0.7 * energyScore + 0.3 * heightScore;
                end

                [~, bestIdx] = max(scores);
                candidateIdx = localMaxima(bestIdx);
                candidateEnergy = candidateEnergies(bestIdx);
                maxCount = candidateHeights(bestIdx);
            end

            if expectedEnergy < 3
                windowSizeFit = 0.08; % Mniejsze okno dla niskich energii
            elseif expectedEnergy < 6
                windowSizeFit = 0.12;
            else
                windowSizeFit = 0.15;
            end

            fitRange = subEnergy >= (candidateEnergy - windowSizeFit) & ...
                subEnergy <= (candidateEnergy + windowSizeFit);

            fitSuccessful = false;
            fitCenter = candidateEnergy;
            fitSigma = NaN;

            if sum(fitRange) >= 4
                try
                    x = subEnergy(fitRange);
                    y = subCounts(fitRange);

                    model = fittype('a*exp(-((x-b)^2)/(2*c^2))', 'independent', 'x');

                    startPoint = [maxCount, candidateEnergy, windowSizeFit/3];

                    % granice dopasowania
                    lowerBounds = [0, min(x), 0.005];  % minimalna sigma
                    upperBounds = [maxCount*5, max(x), windowSizeFit*2];

                    opts = fitoptions('Method', 'NonlinearLeastSquares', 'StartPoint', startPoint, ...
                        'Lower', lowerBounds, 'Upper', upperBounds,'MaxIter', 400, ...
                        'TolFun', 1e-8,  'TolX', 1e-8);
                    [fitObj, gof] = fit(x', y', model, opts);

                    if expectedEnergy < 3
                        minRSquare = 0.5;
                    else
                        minRSquare = 0.7;
                    end

                    if gof.rsquare > minRSquare && fitObj.b >= min(x) && fitObj.b <= max(x) && fitObj.c > 0
                        fitSuccessful = true;
                        fitCenter = fitObj.b;
                        fitSigma = abs(fitObj.c);
                    else
                        % Fit nieudany, ale zachowujemy sigma z fita jeśli jest dostępna
                        if exist('fitObj', 'var') && ~isempty(fitObj) && fitObj.c > 0
                            fitSigma = abs(fitObj.c);
                        end
                    end

                catch ME
                end
            end

            if fitSuccessful
                foundEnergy = fitCenter;
                success = true;
                fwhm = CONST_FWHM * fitSigma;
            else
                % Fallback na centroid
                centroidWindow = windowSizeFit * 0.7;
                narrowRange = subEnergy >= (candidateEnergy - centroidWindow) & ...
                    subEnergy <= (candidateEnergy + centroidWindow);

                if sum(narrowRange) >= 3
                    narrowEnergy = subEnergy(narrowRange);
                    narrowCounts = subCounts(narrowRange);

                    if sum(narrowCounts) > 0
                        centroid = sum(narrowEnergy .* narrowCounts) / sum(narrowCounts);
                        foundEnergy = centroid;
                        success = true;
                    else
                        foundEnergy = candidateEnergy;
                        success = true;
                    end
                else
                    foundEnergy = candidateEnergy;
                    success = true;
                end


                if ~isnan(fitSigma) && fitSigma > 0
                    fwhm = CONST_FWHM * fitSigma;
                else
                    fwhm = NaN;
                end
            end

            % weryfikacja wyników
            if ~isnan(foundEnergy)
                energyDiff = abs(foundEnergy - expectedEnergy);

                if expectedEnergy < 3
                    maxAllowedDiff = 0.2;
                elseif expectedEnergy < 6
                    maxAllowedDiff = 0.3;
                else
                    maxAllowedDiff = 0.4;
                end

                if energyDiff > maxAllowedDiff
                    % Lepsza próba ponownego znalezienia
                    retryRange = [expectedEnergy - 0.1, expectedEnergy + 0.1];
                    retryIdx = spectrum.energy >= retryRange(1) & spectrum.energy <= retryRange(2);

                    if sum(retryIdx) >= 3
                        retryEnergy = spectrum.energy(retryIdx);
                        retryCounts = spectrum.counts(retryIdx);

                        % Szukanie maksimum
                        if length(retryCounts) > 5
                            smoothedRetry = movmean(retryCounts, 3);
                            [retryMaxCount, retryMaxIdx] = max(smoothedRetry);
                        else
                            [retryMaxCount, retryMaxIdx] = max(retryCounts);
                        end

                        retryFoundEnergy = retryEnergy(retryMaxIdx);

                        % retry jeśli jest bliżej oczekiwanej energii
                        retryEnergyDiff = abs(retryFoundEnergy - expectedEnergy);
                        currentEnergyDiff = abs(foundEnergy - expectedEnergy);

                        if retryEnergyDiff < currentEnergyDiff && retryMaxCount > maxCount * 0.3
                            foundEnergy = retryFoundEnergy;
                            fwhm = NaN;
                        end
                    end
                end
            end

            % Walidacja FWHM
            if success && ~isnan(fwhm)
                if fwhm < 0.01 || fwhm > 1.0
                    fwhm = NaN;
                end
            end
        end


        function convertSpectraToEnergyScale(app)
            % Przelicza wszystkie widma na skalę energii używając parametrów z pól
            gain1 = str2double(app.GAIN1_sensitivity_tab_value.Value);
            zero1 = str2double(app.ZERO1_sensitivity_tab_value.Value);
            gain2 = str2double(app.GAIN2_sensitivity_tab_value.Value);
            zero2 = str2double(app.ZERO2_sensitivity_tab_value.Value);

            rebuildNames = isempty(app.CalibratedSpectrumStandardNames) || ...
                length(app.CalibratedSpectrumStandardNames) ~= length(app.AllDet1Spectra);

            if rebuildNames
                app.CalibratedSpectrumStandardNames = {};
            end

            app.AllDet1SpectrumCalib = {};
            app.AllDet2SpectrumCalib = {};

            for i = 1:length(app.AllDet1Spectra)
                sp1 = app.AllDet1Spectra{i};
                sp2 = app.AllDet2Spectra{i};
                ch = 1:length(sp1);

                % Przelicz na energię używając parametrów z pól
                e1 = gain1/1000 * ch + zero1/1000; % konwersja eV -> keV
                e2 = gain2/1000 * ch + zero2/1000;

                app.AllDet1SpectrumCalib{end + 1} = struct('energy', e1, 'counts', sp1);
                app.AllDet2SpectrumCalib{end + 1} = struct('energy', e2, 'counts', sp2);

                if rebuildNames
                    if i <= length(app.Pending)
                        standardName = app.Pending(i).standardLabel;
                    else
                        standardName = sprintf('Index %d', i);
                    end
                    app.CalibratedSpectrumStandardNames{end + 1} = standardName;
                end
            end
        end


        function updatePickAllCheckboxes(app)
            lineTypes = {'K_alpha', 'K_beta', 'L_alpha', 'L_beta', 'M_alpha'};
            lampIndices = [1, 2, 3, 4, 5];
            checkboxNames = {'pickALL_K_alpha_CheckBox', 'pickALL_K_beta_CheckBox', ...
                'pickALL_L_alpha_CheckBox', 'pickALL_L_beta_CheckBox', ...
                'pickALL_M_alpha_CheckBox'};

            elements = keys(app.symbol_to_pos); % Wszystkie pierwiastki na holderze
            for i = 1:numel(lineTypes)
                lampIndex = lampIndices(i);
                checkboxName = checkboxNames{i};

                all_available_are_on = true;
                any_available_lamp_exists = false;

                for j = 1:numel(elements)
                    symbol = elements{j};
                    lampName = sprintf('Lamp_%s_%d', symbol, lampIndex);
                    lineBtnName = sprintf('%s_%s', lineTypes{i}, symbol);

                    % Sprawdzamy tylko te lampki, które są w ogóle dostępne (mają włączony mały przycisk)
                    if isprop(app, lineBtnName) && strcmp(app.(lineBtnName).Enable, 'on')
                        any_available_lamp_exists = true;
                        % Jeśli dostępna lampka jest wyłączona, to warunek "wszystkie włączone" nie jest spełniony
                        if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'off')
                            all_available_are_on = false;
                            break; % Nie ma sensu dalej sprawdzać dla tego typu linii
                        end
                    end
                end

                % Ustaw stan checkboxa bez wywoływania jego callbacku
                % Zaznacz tylko, jeśli istnieje jakakolwiek dostępna lampka i wszystkie są włączone
                app.(checkboxName).Value = any_available_lamp_exists && all_available_are_on;
            end
        end


        function handlePickAllCheckbox(app, checkbox, lampIndex, lineType)
            isChecked = checkbox.Value;
            elements = keys(app.symbol_to_pos);

            for i = 1:numel(elements)
                symbol = elements{i};

                [outOfLimits, ~] = checkLimitsAndShowMessage(app, symbol);
                if outOfLimits
                    continue;
                end

                lampName = sprintf('Lamp_%s_%d', symbol, lampIndex);
                lineBtnName = sprintf('%s_%s', lineType, symbol);

                if isprop(app, lineBtnName) && strcmp(app.(lineBtnName).Enable, 'on')
                    if isprop(app, lampName)
                        app.(lampName).Enable = isChecked;
                    end
                end
            end
        end


        function updateSensitivityPlots(app)
            % Aktualizuje wykresy kalibracji czułościowej po każdym pomiarze
            try
                % Sprawdzamy czy zakładka Sensitivity jest aktywna
                if isprop(app, 'TabGroup') && isvalid(app.TabGroup)
                    currentTab = app.TabGroup.SelectedTab;
                    if currentTab == app.SetCalibrationTab
                        % Przelicz widma na skalę energii
                        app.convertSpectraToEnergyScale();

                        % Aktualizuj wykresy skalibrowanych widm
                        if ~isempty(app.AllDet1SpectrumCalib)
                            currentIdx = min(app.CurrentCalibratedSpectrumIndex, length(app.AllDet1SpectrumCalib));
                            plot_stored_spectrum(app, currentIdx);
                        end

                        % Jeśli mamy zdefiniowane ROI, przelicz czułości
                        if ~isempty(app.SensitivityROIs) && ~isempty(fieldnames(app.SensitivityROIs))
                            % Zbierz aktywne linie
                            activeLines = {};
                            standardsList = app.CalibrationData.Standards;
                            xrl = py.importlib.import_module('xraylib');

                            for i = 1:numel(standardsList)
                                symbol = standardsList{i};
                                for lineType = 1:5
                                    lampName = sprintf('Lamp_%s_%d', symbol, lineType);
                                    if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'on')
                                        Z = xrl.SymbolToAtomicNumber(symbol);
                                        switch lineType
                                            case 1
                                                expectedE = double(xrl.LineEnergy(Z, xrl.KA_LINE));
                                                lineName = 'K_alpha';
                                            case 2
                                                expectedE = double(xrl.LineEnergy(Z, xrl.KB_LINE));
                                                lineName = 'K_beta';
                                            case 3
                                                expectedE = double(xrl.LineEnergy(Z, xrl.LA_LINE));
                                                lineName = 'L_alpha';
                                            case 4
                                                expectedE = double(xrl.LineEnergy(Z, xrl.LB_LINE));
                                                lineName = 'L_beta';
                                            case 5
                                                expectedE = double(xrl.LineEnergy(Z, xrl.MA1_LINE));
                                                lineName = 'M_alpha';
                                            otherwise
                                                continue;
                                        end
                                        activeLines{end+1} = struct('symbol', symbol, 'Z', Z, 'lineType', lineType, ...
                                            'lineName', lineName, 'expectedEnergy', expectedE);
                                    end
                                end
                            end

                            if ~isempty(activeLines)
                                calculateSensitivityForAllStandards(app, activeLines);
                                plotSensitivityResults(app);
                            end
                        end

                        % odświeżenie interfejsu
                        drawnow limitrate;
                    end
                end
            catch ME
                % żeby progam nie wywalał sie przy jakichś szybkich
                % zmianach kart w trakcie pomiaru
                disp('Warning: Could not update sensitivity plots - tab might have changed');
            end
        end


        function updateSensitivityCalibration(app, skipSave)
            % Aktualizuje kalibrację czułości po każdym pomiarze / można
            % zmieniać bezpiecznie karty w trakcie pomiaru
            if nargin < 2
                skipSave = false;
            end


            try
                % Check czy mamy wystarczająco danych
                if isempty(app.AllDet1SpectrumCalib) || length(app.AllDet1SpectrumCalib) < 1
                    return;
                end

                % Pełna kalibracja czułościowa
                if skipSave
                    app.UpdateSensitivitycalibrationButtonPushed(struct('SkipSave', true));
                else
                    app.UpdateSensitivitycalibrationButtonPushed([]);
                end

                % odświeżenie interfejsu
                drawnow limitrate;

            catch ME
                % to samo co wyżej  - żeby nie wywalało przy zmianie kart 
                disp('Warning: Could not update sensitivity calibration - tab might have changed');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
        end


        function newFolderPath = copyMeasurementFolder(~, sourceFolderPath, newFolderName)
            % Kopiuje folder pomiarowy do nowej lokalizacji z nową nazwą

            if ~isfolder(sourceFolderPath)
                error('Source folder does not exist: %s', sourceFolderPath);
            end

            % Nowa ścieżka w tym samym katalogu nadrzędnym
            [parentDir, ~] = fileparts(sourceFolderPath);
            newFolderPath = fullfile(parentDir, newFolderName);

            % Jeśli folder już istnieje, usuwamy go
            if isfolder(newFolderPath)
                rmdir(newFolderPath, 's');
            end

            % kopiowanie folderu
            try
                copyfile(sourceFolderPath, newFolderPath);
                fprintf('Copied measurement data: %s -> %s\n', sourceFolderPath, newFolderName);
            catch ME
                error('Failed to copy folder: %s', ME.message);
            end
        end


    end %methods (Access = private)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PolyXCalibrationAppUIFigure.WindowState = 'maximized';

            %% Skale
            app.CurrentScale = 'Channel';
            app.CurrentYScale = 'linear';
            app.SetScaleToChannel.Value = true;
            app.SetScaleToEnergy.Value = false;
            app.SetScaleToLinear.Value = true;
            app.SetScaleToLog.Value = false;


            if isempty(app.ZERO1_sensitivity_tab_value.Value) || isnan(str2double(app.ZERO1_sensitivity_tab_value.Value))
                app.ZERO1_sensitivity_tab_value.Value = num2str(app.LastGoodZERO_d1);
            end
            if isempty(app.ZERO2_sensitivity_tab_value.Value) || isnan(str2double(app.ZERO2_sensitivity_tab_value.Value))
                app.ZERO2_sensitivity_tab_value.Value = num2str(app.LastGoodZERO_d2);
            end
            if isempty(app.GAIN1_sensitivity_tab_value.Value) || isnan(str2double(app.GAIN1_sensitivity_tab_value.Value))
                app.GAIN1_sensitivity_tab_value.Value = num2str(app.LastGoodGAIN_d1);
            end
            if isempty(app.GAIN2_sensitivity_tab_value.Value) || isnan(str2double(app.GAIN2_sensitivity_tab_value.Value))
                app.GAIN2_sensitivity_tab_value.Value = num2str(app.LastGoodGAIN_d2);
            end
            if isempty(app.NOISE1_sensitivity_tab_value.Value) || isnan(str2double(app.NOISE1_sensitivity_tab_value.Value))
                app.NOISE1_sensitivity_tab_value.Value = num2str(app.LastGoodNOISE_d1);
            end
            if isempty(app.FANO1_sensitivity_tab_value.Value) || isnan(str2double(app.FANO1_sensitivity_tab_value.Value))
                app.FANO1_sensitivity_tab_value.Value = num2str(app.LastGoodFANO_d1);
            end
            if isempty(app.NOISE2_sensitivity_tab_value.Value) || isnan(str2double(app.NOISE2_sensitivity_tab_value.Value))
                app.NOISE2_sensitivity_tab_value.Value = num2str(app.LastGoodNOISE_d2);
            end
            if isempty(app.FANO2_sensitivity_tab_value.Value) || isnan(str2double(app.FANO2_sensitivity_tab_value.Value))
                app.FANO2_sensitivity_tab_value.Value = num2str(app.LastGoodFANO_d2);
            end

            %% Xray lib to perform calibration
            clc;
            xrl = py.importlib.import_module('xraylib');
            app.CurrentFolderLabel.Text = 'Current Folder: Not Loaded';

            app.PeakChannelDet1 = NaN;
            app.PeakChannelDet2 = NaN;

            app.Prev_sp_button.Enable = "off";
            app.Next_sp_button.Enable = "off";


            app.elements_layout = { ...
                'Na',     'Mg',     'Al',   'Si', 'P',      'In / S'; ...
                'K / Cl', 'Ca',     'Ti',   'V',  'Cr',     'Mn';     ...
                'Fe',     'Co',     'Ni',   'Cu', 'Zn / Te','Ga / As';...
                'Se',     'Cs / Br','Rb / I','Y', 'Rh',     'Ce';     ...
                'Sm',     'Dy',     'Yb',   'W',  'Au',     'Pb'      };

            % --- Parsowanie do slotów symboli ---
            [rows, cols] = size(app.elements_layout);
            app.elements_slots = cell(rows, cols);
            symbols_all = {};
            for r = 1:rows
                for c = 1:cols
                    raw = app.elements_layout{r,c};
                    parts = regexp(raw,'/','split');
                    parts = strtrim(parts);               % usuń spacje wokół
                    % pozwól na zapis z lub bez spacji po slashu
                    if numel(parts)==1
                        % split po " / "
                        parts = regexp(raw,'\s*/\s*','split'); parts = strtrim(parts);
                    end
                    app.elements_slots{r,c} = parts;      % np. {'Zn','Te'} albo {'Na'}
                    symbols_all = [symbols_all, parts];
                end
            end
            symbols_all = unique(symbols_all, 'stable');

            % --- Mapa symbol -> (row,col) ---
            app.symbol_to_pos = containers.Map('KeyType','char','ValueType','any');
            for r = 1:rows
                for c = 1:cols
                    for k = 1:numel(app.elements_slots{r,c})
                        sym = app.elements_slots{r,c}{k};
                        app.symbol_to_pos(sym) = [r,c];
                    end
                end
            end

            % Domyślna referencja dopóki user nie kliknie
            if isempty(app.reference_row) || isempty(app.reference_col)
                app.reference_row = 1; app.reference_col = 1;  % Na
            end


            try
                app.Beam_E = wmE();
            catch ME
                app.Beam_E = app.Beam_energy_edit_field.Value;
            end
            app.Beam_energy_edit_field.Value = num2str(app.Beam_E);

            wywolanie_event = struct('Value', num2str(app.Beam_E));
            app.Beam_energy_edit_fieldValueChanging(wywolanie_event);


            % Definicje limitów
            % app.x_lim_pos = 300;
            % app.x_lim_neg = -200;
            % app.z_lim_pos = 36;
            % app.z_lim_neg = -39;


            try
                limts_x = get_motor_software_limits('sampx');
                limits_z = get_motor_software_limits('sampz');

                app.x_lim_neg = limts_x.lim_neg;
                app.x_lim_pos = limts_x.lim_pos;

                app.z_lim_neg = limits_z.lim_neg;
                app.z_lim_pos = limits_z.lim_pos;

            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end

            % Display resolution: 2560 x 1440 at SOLARIS
            %
        end

        % Button pushed function: UpdateCalibrationButton
        function UpdateCalibrationButtonPushed(app, event)
            %=== 1) Wybór folderu ZE STANDARDEM (pojedynczy) ===
            stdDir = uigetdir(pwd, 'Select folder of a single standard (e.g. Cr_seq10)');
            if isequal(stdDir, 0)
                return;
            end

            [~, stdFolderName] = fileparts(stdDir);
            app.CurrentFolderLabel.Text = ['Current Folder: ', stdFolderName];
            drawnow limitrate;

            %=== 2) Inicjalizacja struktur jeśli pierwsze uruchomienie ===
            if isempty(app.CalibrationData) || ~isfield(app.CalibrationData, 'Standards')
                app.CalibrationData = struct('Standards', {{}}, 'Channels', {{}}, 'Energies', {{}});
            end
            if isempty(app.FWHMData) || ~isfield(app.FWHMData, 'detector1')
                app.FWHMData = struct('detector1', [], 'detector2', []);
            end
            if isempty(app.AllDet1Spectra)
                app.AllDet1Spectra = {};
                app.AllDet2Spectra = {};
                app.AllSpectrumFolderNames = {};
                app.AllDet1SpectrumCalib = {};
                app.AllDet2SpectrumCalib = {};
                app.CalibratedSpectrumStandardNames = {};
            end

            %=== 3) Wczytanie i walidacja plików ===
            try
                files = dir(fullfile(stdDir, '*.mat'));
                if isempty(files)
                    uialert(app.PolyXCalibrationAppUIFigure, 'No .mat files in selected folder.', 'Error', 'Icon', 'error');
                    return;
                end

                % Znajdź odpowiednie pliki
                headerFile = files(contains({files.name}, 'HEADER'));
                snapshotFile = files(contains({files.name}, 'snapshot'));
                seqFiles = files(~contains({files.name}, {'HEADER', 'snapshot'}));

                % Sortuj pliki sekwencji
                [~, sidx] = sort({seqFiles.name});
                seqFiles = seqFiles(sidx);

                if isempty(seqFiles)
                    uialert(app.PolyXCalibrationAppUIFigure, 'No sequence files found.', 'Error', 'Icon', 'error');
                    return;
                end

            catch ME
                uialert(app.PolyXCalibrationAppUIFigure, sprintf('Error reading files: %s', ME.message), 'Error', 'Icon', 'error');
                return;
            end

            %=== 4) Przetwarzanie danych ===
            try
                % Wczytaj header i snapshot jeśli istnieją
                if ~isempty(headerFile)
                    app.HeaderData = load(fullfile(stdDir, headerFile(1).name));
                end
                if ~isempty(snapshotFile)
                    app.SnapshotData = load(fullfile(stdDir, snapshotFile(1).name));
                end

                % Sumowanie danych z wszystkich plików sekwencji
                firstFile = load(fullfile(stdDir, seqFiles(1).name));
                if ~isfield(firstFile, 'dane1line')
                    uialert(app.PolyXCalibrationAppUIFigure, 'Field dane1line not found in sequence files.', 'Error', 'Icon', 'error');
                    return;
                end

                [num_detectors, num_pixels, num_channels] = size(firstFile.dane1line);
                totalDane1line = zeros(num_detectors, num_pixels, num_channels, 'like', firstFile.dane1line);
                accStats = zeros(2, 4);

                for j = 1:numel(seqFiles)
                    sf = load(fullfile(stdDir, seqFiles(j).name));
                    if isfield(sf, 'dane1line')
                        totalDane1line = totalDane1line + sf.dane1line;
                    end
                    if isfield(sf, 'stats1line')
                        s = sum(sf.stats1line, 2);
                        s = squeeze(s);
                        accStats = accStats + double(s);
                    end
                end

                % PIN/I0 z ostatniego pliku
                lastSeqFile = load(fullfile(stdDir, seqFiles(end).name));
                if isfield(lastSeqFile, 'PIN_map')
                    app.mean_PIN_map = mean(lastSeqFile.PIN_map(:), 'omitnan');
                else
                    app.mean_PIN_map = NaN;
                end
                if isfield(lastSeqFile, 'I0_map')
                    app.mean_I_0_map = mean(lastSeqFile.I0_map(:), 'omitnan');
                else
                    app.mean_I_0_map = NaN;
                end

                % Aktualizacja wyświetlaczy
                mean_values_disp(app);

                % Statystyki detektorów (µs → s)
                app.sum_real_time_1 = 1e-6 * accStats(1, 1);
                app.sum_live_time_1 = 1e-6 * accStats(1, 2);
                app.sum_ICR_1 = accStats(1, 3);
                app.sum_OCR_1 = accStats(1, 4);
                app.sum_real_time_2 = 1e-6 * accStats(2, 1);
                app.sum_live_time_2 = 1e-6 * accStats(2, 2);
                app.sum_ICR_2 = accStats(2, 3);
                app.sum_OCR_2 = accStats(2, 4);

                d1_data_disp(app);
                d2_data_disp(app);

                % Wyświetl widmo
                plot_spectrum(app, totalDane1line);

                % Zapisz widma do właściwości
                app.Det1Spectrum = reshape(sum(totalDane1line(1, :, :), 2), [1, num_channels]);
                app.Det2Spectrum = reshape(sum(totalDane1line(2, :, :), 2), [1, num_channels]);

            catch ME
                uialert(app.PolyXCalibrationAppUIFigure, sprintf('Error processing data: %s', ME.message), 'Error', 'Icon', 'error');
                return;
            end

            %=== 5) Wybór standardu ===
            standards = GetStandardsDatabase(app);
            elementSymbols = fieldnames(standards);

            [selectedIdx, tf] = listdlg('PromptString', sprintf('Select standard element for folder: %s', stdFolderName), ...
                'SelectionMode', 'single', ...
                'ListString', elementSymbols, ...
                'Name', 'Standard Selection', ...
                'ListSize', [300, 400]);

            if ~tf
                app.CurrentFolderLabel.Text = 'Standard selection cancelled';
                return;
            end

            selectedElement = elementSymbols{selectedIdx};
            elementEnergy = standards.(selectedElement).energy / 1000; % eV -> keV

            %=== 6) Interaktywny wybór pików ===
            try
                drawnow;
                peakSelection_d1(app, selectedElement);
                drawnow;
                peakSelection_d2(app, selectedElement);
            catch ME
                uialert(app.PolyXCalibrationAppUIFigure, sprintf('Error in peak selection: %s', ME.message), 'Error', 'Icon', 'error');
                return;
            end

            %=== 7) Obsługa duplikatów standardów ===
            existingIdx = find(strcmp(app.CalibrationData.Standards, selectedElement));
            if ~isempty(existingIdx)
                choice = questdlg(sprintf('Standard "%s" already exists. Choose action:', selectedElement), ...
                    'Duplicate Standard', ...
                    'Replace', 'Keep Both', 'Cancel', 'Replace');

                switch choice
                    case 'Replace'
                        % Usuń istniejący wpis
                        app.CalibrationData.Standards(existingIdx) = [];
                        app.CalibrationData.Channels(existingIdx) = [];
                        app.CalibrationData.Energies(existingIdx) = [];

                        % Usuń również z FWHMData jeśli istnieje
                        if length(app.FWHMData.detector1) >= existingIdx
                            app.FWHMData.detector1(existingIdx) = [];
                        end
                        if length(app.FWHMData.detector2) >= existingIdx
                            app.FWHMData.detector2(existingIdx) = [];
                        end

                    case 'Keep Both'
                        % Kontynuuj - zachowaj oba
                    case 'Cancel'
                        app.CurrentFolderLabel.Text = 'Operation cancelled';
                        return;
                    otherwise
                        return;
                end
            end

            %=== 8) Aktualizacja danych kalibracyjnych ===
            newIdx = length(app.CalibrationData.Standards) + 1;
            app.CalibrationData.Standards{newIdx} = selectedElement;
            app.CalibrationData.Channels{newIdx} = [app.PeakChannelDet1, app.PeakChannelDet2];
            app.CalibrationData.Energies{newIdx} = elementEnergy;
            markElementAsMeasured(app, selectedElement);
            %=== 9) Przygotowanie rekordu Pending ===
            try
                xrl = py.importlib.import_module('xraylib');
                Z = double(xrl.SymbolToAtomicNumber(selectedElement));
            catch
                Z = NaN;
            end

            newPending = struct(...
                'folder', stdFolderName, ...
                'standardLabel', selectedElement, ...
                'Z', Z, ...
                'sum_real_time_1', app.sum_real_time_1, ...
                'sum_live_time_1', app.sum_live_time_1, ...
                'sum_ICR_1', app.sum_ICR_1, ...
                'sum_OCR_1', app.sum_OCR_1, ...
                'sum_real_time_2', app.sum_real_time_2, ...
                'sum_live_time_2', app.sum_live_time_2, ...
                'sum_ICR_2', app.sum_ICR_2, ...
                'sum_OCR_2', app.sum_OCR_2, ...
                'peakCh1', app.PeakChannelDet1, ...
                'peakCh2', app.PeakChannelDet2, ...
                'mean_PIN_map', app.mean_PIN_map, ...
                'mean_I_0_map', app.mean_I_0_map);

            % Inicjalizacja Pending jeśli potrzeba
            if isempty(app.Pending)
                app.Pending = newPending;
            else
                app.Pending(end + 1) = newPending;
            end

            %=== 10) Aktualizacja historii widm ===
            app.AllDet1Spectra{end + 1} = app.Det1Spectrum;
            app.AllDet2Spectra{end + 1} = app.Det2Spectrum;
            app.AllSpectrumFolderNames{end + 1} = stdFolderName;

            %=== 11) Aktualizacja interfejsu użytkownika ===
            app.CurrentSpectrumIndex = length(app.AllDet1Spectra);

            % Aktualizacja przycisków nawigacji dla nieskalibrowanych widm
            if isempty(app.AllDet1Spectra)
                app.Prev_sp_button.Enable = 'off';
                app.Next_sp_button.Enable = 'off';
            else
                app.Prev_sp_button.Enable = (app.CurrentSpectrumIndex > 1);
                app.Next_sp_button.Enable = (app.CurrentSpectrumIndex < length(app.AllDet1Spectra));
            end

            % Wyświetl aktualne widmo
            plot_stored_spectrum(app, app.CurrentSpectrumIndex);

            %=== 12) Kalibracja jeśli mamy wystarczająco standardów ===
            if length(app.CalibrationData.Standards) >= 2
                try
                    [app.CalibParams, calibrationResults] = CalculateCalibrationParameters(app, app.CalibrationData);
                    [app.FWHMParams, fwhmResults] = CalculateFWHMCalibrationParameters(app, app.CalibrationData);

                    % Wyświetl wyniki kalibracji
                    DisplayCalibrationResults(app, calibrationResults);
                    DisplayFWHMCalibrationResults(app, fwhmResults);
                    app.IsCalibrated = true;
                    app.SetScaleToEnergy.Enable = 'on';

                    %=== Generuj skalibrowane widma ===
                    app.AllDet1SpectrumCalib = {};
                    app.AllDet2SpectrumCalib = {};
                    app.CalibratedSpectrumStandardNames = {};

                    for i = 1:length(app.AllDet1Spectra)
                        sp1 = app.AllDet1Spectra{i};
                        sp2 = app.AllDet2Spectra{i};
                        ch = 1:length(sp1);

                        % Zastosuj kalibrację
                        e1 = app.CalibParams.detector1.a * ch + app.CalibParams.detector1.b;
                        e2 = app.CalibParams.detector2.a * ch + app.CalibParams.detector2.b;

                        app.AllDet1SpectrumCalib{end + 1} = struct('energy', e1, 'counts', sp1);
                        app.AllDet2SpectrumCalib{end + 1} = struct('energy', e2, 'counts', sp2);

                        % Pobierz nazwę standardu
                        if i <= length(app.Pending)
                            standardName = app.Pending(i).standardLabel;
                        else
                            standardName = sprintf('Index %d', i);
                        end
                        app.CalibratedSpectrumStandardNames{end + 1} = standardName;
                    end

                    % Wyświetl aktualne skalibrowane widmo
                    if ~isempty(app.AllDet1SpectrumCalib)
                        app.CurrentCalibratedSpectrumIndex = length(app.AllDet1SpectrumCalib);

                        % Aktualizacja przycisków nawigacji dla skalibrowanych widm
                        if isempty(app.AllDet1SpectrumCalib)
                            app.Prev_sp_button_2.Enable = 'off';
                            app.Next_sp_button_2.Enable = 'off';
                        else
                            app.Prev_sp_button.Enable = (app.CurrentCalibratedSpectrumIndex > 1);
                            app.Next_sp_button.Enable = (app.CurrentCalibratedSpectrumIndex < length(app.AllDet1SpectrumCalib));
                        end

                        plot_stored_spectrum(app, app.CurrentCalibratedSpectrumIndex);
                    end

                    % Zapisz do Excela
                    saveCalibrationData_excel(app);

                    % Komunikat sukcesu
                    uialert(app.PolyXCalibrationAppUIFigure, ...
                        sprintf('Calibration updated successfully with %d standards.', length(app.CalibrationData.Standards)), ...
                        'Success', 'Icon', 'success');

                catch ME
                    uialert(app.PolyXCalibrationAppUIFigure, ...
                        sprintf('Error in calibration calculation: %s', ME.message), ...
                        'Calibration Error', 'Icon', 'error');
                    app.IsCalibrated = false;
                end
            else
                app.IsCalibrated = false;
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    sprintf('Standard "%s" added. %d more standard needed for calibration.', ...
                    selectedElement, 2 - length(app.CalibrationData.Standards)), ...
                    'Info', 'Icon', 'info');
            end

            app.CurrentFolderLabel.Text = sprintf('Update complete - %s', stdFolderName);
        end

        % Button pushed function: LoadDirectoryButton
        function LoadDirectoryButtonPushed(app, event)
            %==== 0) Inicjalizacja wspólnych struktur (bufor + dane kalibracyjne) ====
            app.Pending = struct('folder',{},'standardLabel',{},'Z',{}, ...
                'sum_real_time_1',{},'sum_live_time_1',{},'sum_ICR_1',{},'sum_OCR_1',{}, ...
                'sum_real_time_2',{},'sum_live_time_2',{},'sum_ICR_2',{},'sum_OCR_2',{}, ...
                'peakCh1',{},'peakCh2',{}, ...
                'mean_PIN_map',{}, 'mean_I_0_map',{});
            app.CalibrationData = struct('Standards', {{}}, 'Channels', {{}}, 'Energies', {{}});
            app.FWHMData = struct('detector1', [], 'detector2', []);

            %==== 1) Wybór katalogu nadrzędnego ====
            parentDir = uigetdir(pwd, 'Select parent directory containing multiple measurement folders');
            if parentDir == 0
                disp('Directory selection cancelled');
                return;
            end

            dirInfo = dir(parentDir);
            dirInfo = dirInfo([dirInfo.isdir]);
            dirInfo = dirInfo(~ismember({dirInfo.name}, {'.', '..'}));

            if isempty(dirInfo)
                uialert(app.PolyXCalibrationAppUIFigure, 'No subdirectories found in the selected directory.', ...
                    'Warning', 'Icon', 'warning');
                return;
            end

            % kolejność jak wczytywania
            [~,sidx] = sort({dirInfo.name});
            dirInfo = dirInfo(sidx);

            numFolders = numel(dirInfo);

            confirmation = questdlg(sprintf('Found %d measurement folders. Process them all for calibration?', numFolders), ...
                'Confirm Directory Processing', 'Yes', 'No', 'Yes');
            if ~strcmp(confirmation, 'Yes')
                disp('Processing cancelled');
                return;
            end

            % Zachowaj stan
            originalDataFolder = app.DataFolder;
            originalDet1Spectrum = app.Det1Spectrum;
            originalDet2Spectrum = app.Det2Spectrum;

            %==== 2) Baza standardów — pobierz raz ====
            standards = GetStandardsDatabase(app);
            % energia tablicowa w [eV]

            % liczniki
            processedCount = 0; % ile folderów faktycznie weszło do kalibracji
            finalizedOnce = false;

            %==== 3) Pętla po podkatalogach — I/O, sumowanie, wybór piku i buforowanie ====
            for i = 1:numFolders
                currentDir = fullfile(parentDir, dirInfo(i).name);

                % Status w UI
                app.CurrentFolderLabel.Text = ['Current Folder: ', dirInfo(i).name];
                drawnow limitrate;

                try
                    app.DataFolder = currentDir;
                    files = dir(fullfile(currentDir, '*.mat'));
                    if isempty(files)
                        warning('No .mat files found in folder: %s. Skipping...', dirInfo(i).name);
                        continue;
                    end

                    headerFile = files(contains({files.name}, 'HEADER'));
                    snapshotFile = files(contains({files.name}, 'snapshot'));
                    if isempty(headerFile) || isempty(snapshotFile)
                        warning('Missing required files in folder: %s. Skipping...', dirInfo(i).name);
                        continue;
                    end

                    % Sekwencja (wszystkie pliki .mat poza HEADER/snapshot)
                    seqFiles = files(~contains({files.name}, {'HEADER', 'snapshot'}));
                    [~, idx] = sort({seqFiles.name});
                    seqFiles = seqFiles(idx);

                    if isempty(seqFiles)
                        warning('No sequence files found in folder: %s. Skipping...', dirInfo(i).name);
                        continue;
                    end

                    % Wczytanie nagłówków (dla UI)
                    app.HeaderData = load(fullfile(currentDir, headerFile(1).name));
                    app.SnapshotData = load(fullfile(currentDir, snapshotFile(1).name));

                    % === SUMOWANIE po WSZYSTKICH plikach sekwencji ===
                    firstFile = load(fullfile(currentDir, seqFiles(1).name));
                    if ~isfield(firstFile, 'dane1line')
                        warning('Field dane1line not found in folder: %s. Skipping...', dirInfo(i).name);
                        continue;
                    end

                    [num_detectors, num_pixels, num_channels] = size(firstFile.dane1line);
                    totalDane1line = zeros(num_detectors, num_pixels, num_channels, 'like', firstFile.dane1line);

                    % akumulator statystyk: [2 detektory x 4 metryki]
                    accStats = zeros(2,4);

                    for j = 1:numel(seqFiles)
                        sf = load(fullfile(currentDir, seqFiles(j).name));
                        if isfield(sf, 'dane1line')
                            totalDane1line = totalDane1line + sf.dane1line;
                        end
                        if isfield(sf, 'stats1line')
                            s = sum(sf.stats1line, 2); % [2 x 1 x 4]
                            s = squeeze(s); % [2 x 4]
                            accStats = accStats + double(s);
                        end
                    end

                    % PIN/I0 z ostatniego pliku (jeśli są)
                    lastSeqFile = seqFiles(end);
                    seqMapsData = load(fullfile(currentDir, lastSeqFile.name));
                    if isfield(seqMapsData,'PIN_map'), app.mean_PIN_map = mean(seqMapsData.PIN_map(:)); end
                    if isfield(seqMapsData,'I0_map'), app.mean_I_0_map = mean(seqMapsData.I0_map(:)); end

                    mean_values_disp(app);

                    % Sumy statystyk (sekundy = µs * 1e-6)
                    app.sum_real_time_1 = 1e-6 * accStats(1,1);
                    app.sum_live_time_1 = 1e-6 * accStats(1,2);
                    app.sum_ICR_1 = accStats(1,3);
                    app.sum_OCR_1 = accStats(1,4);
                    app.sum_real_time_2 = 1e-6 * accStats(2,1);
                    app.sum_live_time_2 = 1e-6 * accStats(2,2);
                    app.sum_ICR_2 = accStats(2,3);
                    app.sum_OCR_2 = accStats(2,4);

                    d1_data_disp(app);
                    d2_data_disp(app);

                    % Rysowanie widm Kanały vs Zliczenia
                    plot_spectrum(app, totalDane1line);
                    app.Det1Spectrum = reshape(sum(totalDane1line(1,:,:), 2), [1, num_channels]);
                    app.Det2Spectrum = reshape(sum(totalDane1line(2,:,:), 2), [1, num_channels]);

                    %=== Wybór standardu dla TEGO folderu ===
                    elementSymbols = fieldnames(standards);
                    [standardElement, tf] = listdlg('PromptString', ...
                        ['Select standard element for folder: ' dirInfo(i).name], ...
                        'SelectionMode', 'single', 'ListString', elementSymbols, ...
                        'Name', 'Standard Selection', 'ListSize' ,[300, 400]);

                    if ~tf
                        disp(['Standard selection cancelled for folder: ' dirInfo(i).name]);
                        continue; % nic nie dodajemy do bazy dla tego folderu
                    end

                    selectedElement = elementSymbols{standardElement};

                    % Energia z bazy (przeliczamy na keV)
                    elementEnergy = standards.(selectedElement).energy;
                    elementEnergy = elementEnergy / 1000;

                    % Interaktywny wybór piku dla D1/D2
                    drawnow;
                    peakSelection_d1(app, selectedElement);
                    drawnow;
                    peakSelection_d2(app, selectedElement);

                    % Obsługa duplikatu standardu (Replace/Keep Both/Cancel)
                    existingIndex = find(strcmp(app.CalibrationData.Standards, selectedElement));
                    if ~isempty(existingIndex)
                        choice = questdlg(['Standard ' selectedElement ' already exists in calibration. Replace it?'], ...
                            'Standard Exists', 'Replace', 'Keep Both', 'Cancel', 'Replace');
                        switch choice
                            case 'Replace'
                                app.CalibrationData.Standards(existingIndex) = [];
                                app.CalibrationData.Channels(existingIndex) = [];
                                app.CalibrationData.Energies(existingIndex) = [];
                            case 'Keep Both'
                                % nic — dodamy drugi wpis
                            case 'Cancel'
                                % user rezygnuje – NIE dodajemy i NIE buforujemy
                                continue;
                        end
                    end

                    % Dodaj standard do wspólnej bazy (bez fitów)
                    idx = numel(app.CalibrationData.Standards) + 1;
                    app.CalibrationData.Standards{idx} = selectedElement;
                    app.CalibrationData.Channels{idx} = [app.PeakChannelDet1, app.PeakChannelDet2];
                    app.CalibrationData.Energies{idx} = elementEnergy; % [keV]
                    markElementAsMeasured(app, selectedElement);
                    % Zbuforuj rekord (E1/E2 policzymy po fitach)
                    P.folder = dirInfo(i).name;
                    P.standardLabel = selectedElement;
                    try
                        xrl = py.importlib.import_module('xraylib');
                        P.Z = double(xrl.SymbolToAtomicNumber(selectedElement));
                    catch
                        P.Z = NaN;
                    end
                    P.sum_real_time_1 = app.sum_real_time_1;
                    P.sum_live_time_1 = app.sum_live_time_1;
                    P.sum_ICR_1 = app.sum_ICR_1;
                    P.sum_OCR_1 = app.sum_OCR_1;
                    P.sum_real_time_2 = app.sum_real_time_2;
                    P.sum_live_time_2 = app.sum_live_time_2;
                    P.sum_ICR_2 = app.sum_ICR_2;
                    P.sum_OCR_2 = app.sum_OCR_2;
                    P.peakCh1 = app.PeakChannelDet1;
                    P.peakCh2 = app.PeakChannelDet2;
                    P.mean_PIN_map = app.mean_PIN_map;
                    P.mean_I_0_map = app.mean_I_0_map;
                    app.Pending(end+1) = P;

                    % Historia do przewijania — DLA WYBRANYCH STANDARDÓW
                    app.AllDet1Spectra{end+1} = app.Det1Spectrum;
                    app.AllDet2Spectra{end+1} = app.Det2Spectrum;
                    app.AllSpectrumFolderNames{end+1} = dirInfo(i).name;
                    app.CurrentSpectrumIndex = numel(app.AllDet1Spectra);
                    if app.CurrentSpectrumIndex > 1
                        app.Prev_sp_button.Enable = 'on';
                        app.Next_sp_button.Enable = 'on';
                    else
                        app.Prev_sp_button.Enable = 'off';
                        app.Next_sp_button.Enable = 'off';
                    end

                    processedCount = processedCount + 1;
                    disp(['Added standard ' selectedElement ' from folder: ' dirInfo(i).name]);

                    %=== jeżeli to OSTATNI folder, finalizuj TERAZ, po wyborze pików ===
                    if i == numFolders
                        doFinalize();
                        finalizedOnce = true;
                    end
                catch ME
                    warning('Error processing folder %s: %s', dirInfo(i).name, ME.message);
                    continue;
                end
            end

            % Odśwież widmo aktualnego indeksu (niekalibrowane)
            if ~isempty(app.AllDet1Spectra)
                plot_stored_spectrum(app, app.CurrentSpectrumIndex);
            end

            % Przywróć oryginalny folder i spektra (stan)
            app.DataFolder = originalDataFolder;
            if ~isempty(originalDet1Spectrum), app.Det1Spectrum = originalDet1Spectrum; end
            if ~isempty(originalDet2Spectrum), app.Det2Spectrum = originalDet2Spectrum; end

            %==== 4) Error check jeśli z jakiegoś powodu nie sfinalizowaliśmy w ostatniej iteracji ====
            if ~finalizedOnce && processedCount >= 2
                doFinalize();
                finalizedOnce = true;
            elseif processedCount < 2
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'Insufficient standards for calibration. Please add at least two.', ...
                    'Warning','Icon','warning');
                app.IsCalibrated = false;
            end

            %==== 5) Finał ====
            app.CurrentFolderLabel.Text = 'Directory Processing Complete';

            %========== Zagnieżdżona funkcja finalizująca ==========
            function doFinalize()
                % 4.1) Fity (raz)
                [app.CalibParams, calibrationResults] = CalculateCalibrationParameters(app, app.CalibrationData);
                [app.FWHMParams, fwhmResults] = CalculateFWHMCalibrationParameters(app, app.CalibrationData);


                % 4.2) Wizualizacje (raz)
                DisplayCalibrationResults(app, calibrationResults);
                DisplayFWHMCalibrationResults(app, fwhmResults);
                app.IsCalibrated = true;
                app.SetScaleToEnergy.Enable = 'on';

                % 4.3) Zbuduj listy skalibrowanych widm
                app.AllDet1SpectrumCalib = {};
                app.AllDet2SpectrumCalib = {};
                app.CalibratedSpectrumStandardNames = {};
                for k = 1:numel(app.AllDet1Spectra)
                    sp1 = app.AllDet1Spectra{k};
                    sp2 = app.AllDet2Spectra{k};
                    ch = 1:numel(sp1);
                    e1 = app.CalibParams.detector1.a * ch + app.CalibParams.detector1.b;
                    e2 = app.CalibParams.detector2.a * ch + app.CalibParams.detector2.b;
                    app.AllDet1SpectrumCalib{end+1} = struct('energy', e1, 'counts', sp1);
                    app.AllDet2SpectrumCalib{end+1} = struct('energy', e2, 'counts', sp2);
                    if k <= numel(app.Pending)
                        app.CalibratedSpectrumStandardNames{end+1} = app.Pending(k).standardLabel;
                    else
                        app.CalibratedSpectrumStandardNames{end+1} = sprintf('Index %d', k);
                    end
                end

                % pokaż ostatnie skalibrowane widmo
                if ~isempty(app.AllDet1SpectrumCalib)
                    plot_stored_spectrum(app, numel(app.AllDet1SpectrumCalib));
                end

                % 4.4) Zrzut do Excela (raz)
                saveCalibrationData_excel(app)
            end
        end

        % Button pushed function: Prev_sp_button
        function Prev_sp_buttonButtonPushed(app, event)
            if isempty(app.AllDet1Spectra), return; end
            numPages = numel(app.AllDet1Spectra) + 1;
            if isempty(app.CurrentSpectrumIndex), app.CurrentSpectrumIndex = 1; end
            newIndex = max(1, app.CurrentSpectrumIndex - 1);
            app.CurrentSpectrumIndex = newIndex;

            % najpierw wyświetl widmo
            plot_stored_spectrum(app, newIndex);

            % Jeśli ROI są checkbox zaznaczony to je dodajemy
            if isprop(app,'ShowROIsCheckBox') && logical(app.ShowROIsCheckBox.Value)
                plot_sensitivity_for_index(app, newIndex);
            end


        end

        % Button pushed function: Next_sp_button
        function Next_sp_buttonButtonPushed(app, event)
            if isempty(app.AllDet1Spectra), return; end
            numPages = numel(app.AllDet1Spectra) + 1;
            if isempty(app.CurrentSpectrumIndex), app.CurrentSpectrumIndex = 1; end
            newIndex = min(numPages, app.CurrentSpectrumIndex + 1);
            app.CurrentSpectrumIndex = newIndex;
            plot_stored_spectrum(app, newIndex);
            if isprop(app,'ShowROIsCheckBox') && logical(app.ShowROIsCheckBox.Value)
                plot_sensitivity_for_index(app, newIndex);
            end
        end

        % Value changed function: initial_z_coordintates
        function initial_z_coordintatesValueChanged(app, event)
            app.init_z = app.initial_z_coordintates.Value;
            updateAllLimitLamps(app);
        end

        % Value changed function: initial_x_coordinates
        function initial_x_coordinatesValueChanged(app, event)
            app.init_x = app.initial_x_coordinates.Value;
            updateAllLimitLamps(app);
        end

        % Button pushed function: StartmeasurementButton
        function StartmeasurementButtonPushed(app, event)
            xrl = py.importlib.import_module('xraylib');
            if isempty(app.reference_row) || isempty(app.reference_col) || ...
                    isempty(app.init_x) || isempty(app.init_z)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'Set starting position first ("Choose starting position").', ...
                    'No Reference Position','Icon','warning');
                return;
            end

            rows = size(app.elements_layout,1);
            cols = size(app.elements_layout,2);

            % --- pełna tablica współrzędnych (dla Excela) ---
            x_coords = zeros(rows, cols);
            z_coords = zeros(rows, cols);
            for r = 1:rows
                for c = 1:cols
                    [x_coords(r,c), z_coords(r,c)] = coords_for_rowcol(app, r, c);
                end
            end

            % --- zbierz aktywne symbole (lampki on) ---
            active_symbols = {};
            keysList = keys(app.symbol_to_pos);
            for i = 1:numel(keysList)
                symbols = keysList{i};
                lamp_on = false;
                for k = 1:5
                    lampName = sprintf('Lamp_%s_%d', symbols, k);
                    if isprop(app, lampName) && strcmp(app.(lampName).Enable,'on')
                        lamp_on = true; break;
                    end
                end
                if lamp_on
                    active_symbols{end+1} = symbols;
                end
            end
            if isempty(active_symbols)
                uialert(app.PolyXCalibrationAppUIFigure,'No active lines selected. Turn lamps on.','Nothing To Measure','Icon','info');
                return;
            end

            % --- zredukuj do unikatowych slotów (r,c) ---
            visited = false(rows, cols);
            active_positions = {};    % wejdą struktury: struct('syms',{...}, 'coord',[x,z])
            for i = 1:numel(active_symbols)
                symbol = active_symbols{i};
                position = app.symbol_to_pos(symbol);  r = position(1); c = position(2);
                if ~visited(r,c)
                    visited(r,c) = true;
                    slot_all_syms = app.elements_slots{r,c};  % np. {'Zn','Te'}
                    slot_active_syms = intersect(slot_all_syms, active_symbols, 'stable');
                    [x,z] = coords_for_rowcol(app, r, c);

                    % Oblicz maksymalne Z dla tego slotu
                    Z_values = [];
                    for s = 1:numel(slot_active_syms)
                        try
                            Z_val = double(xrl.SymbolToAtomicNumber(slot_active_syms{s}));
                            Z_values(end+1) = Z_val;
                        catch
                            % W przypadku błędu, pomińmy symbol
                        end
                    end

                    if ~isempty(Z_values)
                        maxZ = max(Z_values);
                    else
                        maxZ = 0;
                    end

                    % Dodaj pozycję z maksymalnym Z
                    active_positions{end+1} = struct('syms', {slot_active_syms}, 'coord', [x,z], 'maxZ', maxZ);
                end
            end

            % --- SORTOWANIE według Z malejąco ---
            if ~isempty(active_positions)
                % Utwórz tablicę wartości maxZ
                maxZ_values = zeros(1, numel(active_positions));
                for i = 1:numel(active_positions)
                    maxZ_values(i) = active_positions{i}.maxZ;
                end

                % Posortuj malejąco według Z
                [~, sortOrder] = sort(maxZ_values, 'descend');
                active_positions = active_positions(sortOrder);
            end

            % --- finalizacja wektorów app.x_active, app.z_active + etykiet ---
            npos = numel(active_positions);
            app.x_active = zeros(1,npos);
            app.z_active = zeros(1,npos);
            app.active_elements = cell(1,npos);
            app.active_symbols_per_slot = cell(1,npos);

            for i = 1:npos
                syms_i = active_positions{i}.syms;
                app.active_symbols_per_slot{i} = syms_i;
                app.active_elements{i} = strjoin(syms_i,'/');
                app.x_active(i) = active_positions{i}.coord(1);
                app.z_active(i) = active_positions{i}.coord(2);
            end

            try
                [~, mt] = wmE();
            catch ME
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end

            % --- POMIAR RAZ NA SLOT + przetworzenie dla każdego aktywnego symbolu w slocie ---
            for i = 1:npos
                x0 = app.x_active(i);
                z0 = app.z_active(i);
                slot_label = app.active_elements{i};
                slot_label_fs = strrep(slot_label, '/', '-'); % dla nazwy folderu

                % Pobierz symbole w tym slocie
                syms_here = app.active_symbols_per_slot{i};

                % Nazwa bazowego folderu pomiarowego
                current_date = datestr(now, 'dd_mm_yyyy_HH_MM_SS');
                base_filename = sprintf('MAP_%s_%s', slot_label_fs, current_date);

                % Parametry skanowania (na podstawie najlżejszego pierwiastka)
                xrl = py.importlib.import_module('xraylib');
                Zs = zeros(1, numel(syms_here));
                for k = 1:numel(syms_here)
                    Zs(k) = double(xrl.SymbolToAtomicNumber(syms_here{k}));
                end
                Z = min(Zs);

                % Calculate dt
                try
                    [~, mt] = wmE();
                catch
                    mt = 'Ni'; % default
                end

                if strcmp(mt, 'Ni') || strcmp(mt, 'Mo')
                    if Z >= 30
                        dt = 0.2;
                    else
                        dt = (1.1e8 * Z^(-5)) / 25;
                    end
                elseif strcmp(mt, 'Si')
                    if Z >= 30
                        dt = 2.0;
                    else
                        dt = (1.1e8 * Z^(-5) * 10) / 25;
                    end
                else
                    dt = 0.5;
                end

                XscanStart = x0 - 0.5;
                XscanStop  = x0 + 0.5;
                XscanStep  = 0.25;
                XscanVel   = XscanStep / dt;

                Zstartpos  = z0 - 1;
                Zendpos    = z0 + 1;
                ZscanStep  = 0.5;

                boards = [0 1];

                % --- AKWIZYCJA (raz na slot) ---
                fprintf('\n=== Measuring slot %d/%d: %s ===\n', i, npos, slot_label);
                app.CurrentFolderLabel.Text = sprintf('Measuring: %s (%d/%d)', slot_label, i, npos);
                drawnow limitrate;

                try
                    disconnectDante;
                    initDante;
                    dante_stop();
                    dante_clear_board(1);
                    dante_clear_board(2);
                    xrfmap_linescan_script(base_filename, XscanStart, XscanStop, XscanStep, XscanVel, ...
                        Zstartpos, Zendpos, ZscanStep, boards);
                catch ME
                    warning('Acquisition failed for %s: %s', slot_label, ME.message);
                    continue;
                end

                % osobne foldery i przetwarzanie
                for s = 1:numel(syms_here)
                    current_symbol = syms_here{s};

                    % nazwa folderu dla konkretnego pierwiastka
                    symbol_folder_name = sprintf('%s_%s', current_symbol, current_date);

                    try
                        % Skopiuj dane do folderu specyficznego dla pierwiastka
                        symbol_folder_path = app.copyMeasurementFolder(base_filename, symbol_folder_name);

                        % Przetwórz dane dla tego pierwiastka
                        app.processAutoMeasurement(symbol_folder_path, current_symbol);

                        fprintf('Processed %s from folder: %s\n', current_symbol, symbol_folder_name);

                    catch ME
                        warning('Processing failed for %s in slot %s: %s', current_symbol, slot_label, ME.message);
                    end
                end

                % tymczasowy folder
                try
                    if isfolder(base_filename)
                        rmdir(base_filename, 's');
                    end
                catch
                    warning('Could not remove temporary folder: %s', base_filename);
                end

                % kalibracja czulosci po przetworzeniu symboli w slocie
                try
                    app.UpdateEnergyFWHMcalibrationButtonPushed(struct('SilentMode', true));
                    app.updateSensitivityCalibration(true);
                catch ME
                    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                end
            end
            app.saveSensitivityResultsToFiles();

            %% --- Excel: cała siatka + lista aktywnych pozycji ---
            col_headers = {'Col1', 'Col2', 'Col3', 'Col4', 'Col5', 'Col6'};
            row_headers = {'Row1'; 'Row2'; 'Row3'; 'Row4'; 'Row5'};

            all_coords_tab = cell(rows+1, cols+1);
            all_coords_tab(1,2:end) = col_headers;
            all_coords_tab(2:end,1) = row_headers;
            for r = 1:rows
                for c = 1:cols
                    all_coords_tab{r+1,c+1} = sprintf('%.2f, %.2f', x_coords(r,c), z_coords(r,c));
                end
            end

            combined_table = cell(npos+1, 3);
            combined_table(1,:) = {'Element(s)', 'X [mm]', 'Z [mm]'};
            for i = 1:npos
                combined_table{i+1,1} = app.active_elements{i};
                combined_table{i+1,2} = app.x_active(i);
                combined_table{i+1,3} = app.z_active(i);
            end
            excelPath = fullfile(pwd, 'Coordinates_Tables.xlsx');
            try
                writecell(all_coords_tab, excelPath, 'Sheet','Coordinates','Range','A1');
                offset_row = rows + 3;  % przerwa 2 wiersze
                range_str = sprintf('A%d', offset_row);
                writecell(combined_table, excelPath, 'Sheet','Coordinates','Range',range_str);
            catch
                disp('Error with saving data');
            end

            assignin('base','x_active',app.x_active(:));
            assignin('base','z_active',app.z_active(:));
        end

        % Button pushed function: ChoosestartingpositionButton
        function ChoosestartingpositionButtonPushed(app, event)
            rows = size(app.elements_layout,1);
            cols = size(app.elements_layout,2);


            %% Rysowanie schematu holdera
            fig = figure('Name','Select Your Current Position on Holder', ...
                'NumberTitle','off','Position',[100,100,800,600]);
            ax = axes(fig); hold(ax,'on');

            for r = 1:rows
                for c = 1:cols
                    rectangle(ax,'Position',[c-0.4, rows-r+0.6, 0.8, 0.8], ...
                        'FaceColor',[0.9 0.9 0.9],'EdgeColor','k','LineWidth',2, ...
                        'Curvature',[1 1]);
                    label = app.elements_layout{r,c};
                    text(ax, c, rows-r+1, label, 'HorizontalAlignment','center', ...
                        'VerticalAlignment','middle','FontSize',14,'FontWeight','bold');
                end
            end
            xlim(ax,[0.5, cols+0.5]);
            ylim(ax,[0.5, rows+0.5]);
            axis(ax,'equal');
            grid(ax,'on');
            title(ax,'Click your current sample position', 'FontSize',16);
            set(ax,'YDir','normal');

            [xc, yc] = ginput(1); % kliknięcie użytkownika
            if isempty(xc), close(fig);
                return;
            end

            clicked_col = round(xc);
            clicked_row = rows - round(yc) + 1;
            if clicked_col<1 || clicked_col>cols || clicked_row<1 || clicked_row>rows
                uialert(app.PolyXCalibrationAppUIFigure,'Invalid position. Try again.','Error','Icon','error');
                close(fig);
                return;
            end

            rectangle(ax,'Position',[clicked_col-0.4, rows-clicked_row+0.6, 0.8, 0.8], ...
                'FaceColor',[0 1 0 0.3],'EdgeColor','g','LineWidth',3,'Curvature',[1 1]);
            txt = app.elements_layout{clicked_row,clicked_col};
            title(ax, sprintf('Selected: %s (Row %d, Col %d)', txt, clicked_row, clicked_col), ...
                'FontSize',16,'Color','g');
            pause(0.8); close(fig);

            % Zapamiętaj referencję
            app.reference_row = clicked_row;
            app.reference_col = clicked_col;

            try
                app.x_current = get_motor_pos('sampx'); % x dla WYBRANEJ pozycji
                app.z_current = get_motor_pos('sampz'); % z dla WYBRANEJ pozycji
            catch
                % jeśli brak komendy to zostawiamy pola bez zmian
            end
            if ~isempty(app.x_current)
                app.init_x = app.x_current;
                app.initial_x_coordinates.Value = app.x_current;
            end
            if ~isempty(app.z_current)
                app.init_z = app.z_current;
                app.initial_z_coordintates.Value = app.z_current;
            end

            updateAllLimitLamps(app); % Aktualizujemy lampki (bo limit switche mogą wejść)
        end

        % Button pushed function: UpdateSensitivitycalibrationButton
        function UpdateSensitivitycalibrationButtonPushed(app, event)
            xrl = py.importlib.import_module('xraylib');

            skipSave = false;
            if isstruct(event) && isfield(event, 'SkipSave')
                skipSave = event.SkipSave;
            end


            % Przelicz widma na skalę energii używając parametrów z pól
            convertSpectraToEnergyScale(app);

            % Pobierz wartość nSigma z dropdown
            roiWidthOption = app.ROIwidthDropDown.Value;
            switch roiWidthOption
                case '±0.5·σ'
                    nSigma = 0.5;
                case '± σ'
                    nSigma = 1;
                case '± 2·σ'
                    nSigma = 2;
                case '± 3·σ'
                    nSigma = 3;
                otherwise
                    nSigma = 1;
            end

            % czy są jakieś standardy
            if isempty(app.AllDet1SpectrumCalib)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'No spectra found. Please load measurement data first.', ...
                    'No Data', 'Icon', 'warning');
                return;
            end

            % Zbierz aktywne linie
            standardsList = app.CalibrationData.Standards;
            activeLines = {};

            for i = 1:numel(standardsList)
                symbol = standardsList{i};
                for lineType = 1:5
                    lampName = sprintf('Lamp_%s_%d', symbol, lineType);
                    if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'on')
                        Z = xrl.SymbolToAtomicNumber(symbol);
                        switch lineType
                            case 1
                                expectedE = double(xrl.LineEnergy(Z, xrl.KA_LINE)); % keV
                                lineName = 'K_alpha';
                            case 2
                                expectedE = double(xrl.LineEnergy(Z, xrl.KB_LINE)); % keV
                                lineName = 'K_beta';
                            case 3
                                expectedE = double(xrl.LineEnergy(Z, xrl.LA_LINE)); % keV
                                lineName = 'L_alpha';
                            case 4
                                expectedE = double(xrl.LineEnergy(Z, xrl.LB_LINE)); % keV
                                lineName = 'L_beta';
                            case 5
                                expectedE = double(xrl.LineEnergy(Z, xrl.MA1_LINE)); % keV
                                lineName = 'M_alpha';
                            otherwise
                                expectedE = 0;
                                lineName = 'Unknown';
                        end
                        activeLines{end+1} = struct('symbol', symbol, 'Z', Z, 'lineType', lineType, ...
                            'lineName', lineName, 'expectedEnergy', expectedE);
                    end
                end
            end

            if isempty(activeLines)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'No active lines found. Please enable at least one lamp for a standard element.', ...
                    'No Active Lines', 'Icon', 'warning');
                return;
            end

            app.SensitivityROIs = struct();

            % Dla każdej aktywnej linii znajdź dokładną energię i oblicz ROI
            for j = 1:length(activeLines)
                line = activeLines{j};
                symbol = line.symbol;

                % Znajdź indeks standardu
                stdIdx = find(strcmp(app.CalibrationData.Standards, symbol), 1);
                if isempty(stdIdx) || stdIdx > length(app.AllDet1SpectrumCalib)
                    continue;
                end

                % Znajdź dokładną energię dla obu detektorów
                spec1 = app.AllDet1SpectrumCalib{stdIdx};
                spec2 = app.AllDet2SpectrumCalib{stdIdx};

                [foundE1, success1, ~] = autoFindPeakEnergy(app, spec1, line.expectedEnergy, 1);
                [foundE2, success2, ~] = autoFindPeakEnergy(app, spec2, line.expectedEnergy, 2);

                % Oblicz ROI używając parametrów z pól (NOISE i FANO)
                noise1 = str2double(app.NOISE1_sensitivity_tab_value.Value) / 1000; % eV -> keV
                fano1 = str2double(app.FANO1_sensitivity_tab_value.Value);
                noise2 = str2double(app.NOISE2_sensitivity_tab_value.Value) / 1000;
                fano2 = str2double(app.FANO2_sensitivity_tab_value.Value);

                S1 = app.calculateSigmaFromParams(foundE1, noise1, fano1);
                S2 = app.calculateSigmaFromParams(foundE2, noise2, fano2);

                E_min1 = foundE1 - nSigma * S1;
                E_max1 = foundE1 + nSigma * S1;
                E_min2 = foundE2 - nSigma * S2;
                E_max2 = foundE2 + nSigma * S2;

                % Zapisz ROI do struktury - używając symbolu jako klucza
                if ~isfield(app.SensitivityROIs, symbol)
                    app.SensitivityROIs.(symbol) = struct();
                end

                app.SensitivityROIs.(symbol).(line.lineName) = struct(...
                    'expectedEnergy', line.expectedEnergy, ...
                    'foundEnergy1', foundE1, ...
                    'foundEnergy2', foundE2, ...
                    'det1_ROI', [E_min1, E_max1], ...
                    'det2_ROI', [E_min2, E_max2], ...
                    'sigma_det1', S1, ...
                    'sigma_det2', S2, ...
                    'nSigma', nSigma, ...
                    'color', getLineColor(line.lineName), ...
                    'fitSuccess1', success1, ...
                    'fitSuccess2', success2 ...
                    );
            end

            assignin('base', 'ROIS', app.SensitivityROIs);

            % Oblicz czułości
            calculateSensitivityForAllStandards(app, activeLines);

            % Aktualizuj wykresy
            idx = app.CurrentCalibratedSpectrumIndex;
            if idx < 1 || idx > length(app.AllDet1SpectrumCalib)
                idx = 1;
            end
            plot_sensitivity_for_index(app, idx);
            app.plotSensitivityResults();

            % Zapisz wyniki
            % saveSensitivityResultsToFiles(app);
            % uialert(app.PolyXCalibrationAppUIFigure, ...
            %                 'Sensitivity calibration completed successfully!', ...
            %                 'Success', 'Icon', 'success');
            if ~skipSave
                saveSensitivityResultsToFiles(app);
            end


            function color = getLineColor(lineName)
                switch lineName
                    case 'K_alpha', color = [0, 0, 0];     % czarny
                    case 'K_beta',  color = [0, 0.5, 0];   % zielony
                    case 'L_alpha', color = [0, 0, 1];     % niebieski
                    case 'L_beta',  color = [0.8, 0, 0.8]; % magenta
                    case 'M_alpha', color = [1, 0.5, 0];   % pomarańczowy
                    otherwise,      color = [0.5, 0.5, 0.5]; % szary dla wypadków
                end
            end
        end

        % Button pushed function: Previous_SensitivityLine_results
        function Previous_SensitivityLine_resultsButtonPushed(app, event)
            if isempty(app.SensitivityResults) || ~isstruct(app.SensitivityResults)
                return;
            end

            availableLines = fieldnames(app.SensitivityResults);
            if numel(availableLines) <= 1
                return;
            end

            currentLineName = app.SensitivityLineNames{app.CurrentSensitivityLineIndex};
            currentIdxInAvailable = find(strcmp(availableLines, currentLineName));

            if isempty(currentIdxInAvailable) || currentIdxInAvailable == 1
                nextIdxInAvailable = numel(availableLines);
            else
                nextIdxInAvailable = currentIdxInAvailable - 1;
            end

            nextLineName = availableLines{nextIdxInAvailable};
            app.CurrentSensitivityLineIndex = find(strcmp(app.SensitivityLineNames, nextLineName));

            plotSensitivityResults(app);
        end

        % Button pushed function: Next_SensitivityLine_results
        function Next_SensitivityLine_resultsButtonPushed(app, event)
            if isempty(app.SensitivityResults) || ~isstruct(app.SensitivityResults) || numel(fieldnames(app.SensitivityResults)) <= 1
                return;
            end
            availableLines = fieldnames(app.SensitivityResults);
            currentLineName = app.SensitivityLineNames{app.CurrentSensitivityLineIndex};
            currentIdxInAvailable = find(strcmp(availableLines, currentLineName));
            nextIdxInAvailable = mod(currentIdxInAvailable, numel(availableLines)) + 1;
            nextLineName = availableLines{nextIdxInAvailable};
            app.CurrentSensitivityLineIndex = find(strcmp(app.SensitivityLineNames, nextLineName));
            plotSensitivityResults(app);
        end

        % Value changed function: ShowROIsCheckBox
        function ShowROIsCheckBoxValueChanged(app, event)
            if app.ShowROIsCheckBox.Value
                if ~strcmp(app.CurrentScale, 'Energy')
                    app.CurrentScale = 'Energy';
                    app.SetScaleToChannel.Value = false;
                    app.SetScaleToEnergy.Value = true;
                end
                plot_sensitivity_for_index(app, app.CurrentSpectrumIndex);
            else % Odśwież bez ROI
                plot_stored_spectrum(app, app.CurrentSpectrumIndex);
            end
        end

        % Value changing function: ZERO1_sensitivity_tab_value
        function ZERO1_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;

            % Zamiana przecinka na kropkę i konwersja na double
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);

            % jeśli nie liczba, przywracamy poprzednią wartość
            if isnan(newValue)
                app.ZERO1_sensitivity_tab_value.Value = num2str(app.LastGoodZERO_d1);
            else
                % Poprawna wartość jako nową domyślną do wykorzystania w przyszłości
                app.LastGoodZERO_d1 = newValue;
            end
        end

        % Value changing function: GAIN1_sensitivity_tab_value
        function GAIN1_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.GAIN1_sensitivity_tab_value.Value = num2str(app.LastGoodGAIN_d1);
            else
                app.LastGoodGAIN_d1 = newValue;
            end
        end

        % Value changing function: GAIN2_sensitivity_tab_value
        function GAIN2_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.GAIN2_sensitivity_tab_value.Value = num2str(app.LastGoodGAIN_d2);
            else
                app.LastGoodGAIN_d2 = newValue;
            end
        end

        % Value changing function: ZERO2_sensitivity_tab_value
        function ZERO2_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.ZERO2_sensitivity_tab_value.Value = num2str(app.LastGoodZERO_d2);
            else
                app.LastGoodZERO_d2 = newValue;
            end
        end

        % Value changing function: NOISE1_sensitivity_tab_value
        function NOISE1_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.NOISE1_sensitivity_tab_value.Value = num2str(app.LastGoodNOISE_d1);
            else
                app.LastGoodNOISE_d1 = newValue;
            end
        end

        % Value changing function: FANO1_sensitivity_tab_value
        function FANO1_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.FANO1_sensitivity_tab_value.Value = num2str(app.LastGoodFANO_d1);
            else
                app.LastGoodFANO_d1 = newValue;
            end
        end

        % Value changing function: NOISE2_sensitivity_tab_value
        function NOISE2_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.NOISE2_sensitivity_tab_value.Value = num2str(app.LastGoodNOISE_d2);
            else
                app.LastGoodNOISE_d2 = newValue;
            end
        end

        % Value changing function: FANO2_sensitivity_tab_value
        function FANO2_sensitivity_tab_valueValueChanging(app, event)
            changingValue = event.Value;
            changingValue = strrep(changingValue, ',', '.');
            newValue = str2double(changingValue);
            if isnan(newValue)
                app.FANO2_sensitivity_tab_value.Value = num2str(app.LastGoodFANO_d2);
            else
                app.LastGoodFANO_d2 = newValue;
            end
        end

        % Value changed function: pickALL_K_alpha_CheckBox
        function pickALL_K_alpha_CheckBoxValueChanged(app, event)
            handlePickAllCheckbox(app, app.pickALL_K_alpha_CheckBox, 1, 'K_alpha');
        end

        % Value changed function: pickALL_K_beta_CheckBox
        function pickALL_K_beta_CheckBoxValueChanged(app, event)
            handlePickAllCheckbox(app, app.pickALL_K_beta_CheckBox, 2, 'K_beta');
        end

        % Value changed function: pickALL_L_alpha_CheckBox
        function pickALL_L_alpha_CheckBoxValueChanged(app, event)
            handlePickAllCheckbox(app, app.pickALL_L_alpha_CheckBox, 3, 'L_alpha');
        end

        % Value changed function: pickALL_L_beta_CheckBox
        function pickALL_L_beta_CheckBoxValueChanged(app, event)
            handlePickAllCheckbox(app, app.pickALL_L_beta_CheckBox, 4, 'L_beta');
        end

        % Value changed function: pickALL_M_alpha_CheckBox
        function pickALL_M_alpha_CheckBoxValueChanged(app, event)
            handlePickAllCheckbox(app, app.pickALL_M_alpha_CheckBox, 5, 'M_alpha');
        end

        % Button pushed function: UpdateEnergyFWHMcalibrationButton
        function UpdateEnergyFWHMcalibrationButtonPushed(app, event)
            silentMode = false; % To jest ciche wywołanie updateu po zmierzonej mapie
            if isstruct(event) && isfield(event, 'SilentMode')
                silentMode = event.SilentMode;
            end

            try
                if isempty(app.FWHMData) || ~isfield(app.FWHMData, 'detector1') || ~isfield(app.FWHMData, 'detector2')
                    app.FWHMData = struct('detector1', [], 'detector2', []);
                end

                %Czy mamy standardy z włączonymi lampkami K_alpha
                standardsWithKalpha = {};
                for i = 1:numel(app.CalibrationData.Standards)
                    symbol = app.CalibrationData.Standards{i};
                    lampName = sprintf('Lamp_%s_1', symbol);
                    if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'on')
                        standardsWithKalpha{end+1} = symbol;
                    end
                end

                if numel(standardsWithKalpha) < 2
                    return;
                end

                % tymczasowa str calibrationData tylko z wybranymi standardami
                tempCalibrationData = struct('Standards', {{}}, 'Channels', {{}}, 'Energies', {{}});

                for i = 1:numel(standardsWithKalpha)
                    symbol = standardsWithKalpha{i};
                    originalIdx = find(strcmp(app.CalibrationData.Standards, symbol));
                    if ~isempty(originalIdx)
                        tempCalibrationData.Standards{end+1} = symbol;
                        tempCalibrationData.Channels{end+1} = app.CalibrationData.Channels{originalIdx};
                        tempCalibrationData.Energies{end+1} = app.CalibrationData.Energies{originalIdx};
                    end
                end

                %  kalib energetyczna używając wybranych standardów
                [app.CalibParams, calibrationResults] = CalculateCalibrationParameters(app, tempCalibrationData);

                % prep dane FWHM dla wybranych standardów
                tempFWHMData = struct('detector1', [], 'detector2', []);

                for i = 1:numel(standardsWithKalpha)
                    symbol = standardsWithKalpha{i};
                    originalIdx = find(strcmp(app.CalibrationData.Standards, symbol));

                    % === tupoprawione tez ===
                    if ~isempty(originalIdx) && ...
                            originalIdx <= length(app.FWHMData.detector1) && ...
                            originalIdx <= length(app.FWHMData.detector2) && ...
                            ~isempty(app.FWHMData.detector1) && ...
                            ~isempty(app.FWHMData.detector2)

                        tempFWHMData.detector1(end+1) = app.FWHMData.detector1(originalIdx);
                        tempFWHMData.detector2(end+1) = app.FWHMData.detector2(originalIdx);
                    else
                        % na pałe
                        warning('No available FWHM data for %s.', symbol);
                        tempFWHMData.detector1(end+1) = 50; % fwhm z czapy
                        tempFWHMData.detector2(end+1) = 50; % domyślna wartość FWHM
                    end
                end

                originalFWHMData = app.FWHMData;
                app.FWHMData = tempFWHMData;

                %kalib FWHM
                [app.FWHMParams, fwhmResults] = CalculateFWHMCalibrationParameters(app, tempCalibrationData);


                % Przywróć oryginalne dane FWHM
                app.FWHMData = originalFWHMData;

                % Wyświetl wyniki
                DisplayCalibrationResults(app, calibrationResults);
                DisplayFWHMCalibrationResults(app, fwhmResults);

                % Zaktualizuj skalibrowane widma z nową kalibracją
                app.convertSpectraToEnergyScale();

                if ~silentMode
                    saveCalibrationData_excel(app);
                end

                % Komunikat sukcesu
                if ~silentMode
                    uialert(app.PolyXCalibrationAppUIFigure, ...
                        sprintf('Energy and FWHM calibration updated successfully using %d standards with Kα lines.', ...
                        numel(standardsWithKalpha)), 'Success', 'Icon', 'success');
                else
                    fprintf('✓ E and FWHM calibration updated (%d standards)\n', numel(standardsWithKalpha));
                end

            catch ME
                if ~silentMode
                    uialert(app.PolyXCalibrationAppUIFigure, ...
                        sprintf('Error updating calibration: %s', ME.message), ...
                        'Error', 'Icon', 'error');
                    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                else
                    fprintf('Warning: E/FWHM update failed: %s\n', ME.message);
                end
            end

        end

        % Button pushed function: simulatelivemeasurementButton
        function simulatelivemeasurementButtonPushed(app, event)
            % Wybierz katalog ze standardami
            parentDir = uigetdir(pwd, 'Select directory containing measurement folders for simulation');
            if parentDir == 0
                return;
            end

            % Znajdź wszystkie podfoldery
            dirInfo = dir(parentDir);
            dirInfo = dirInfo([dirInfo.isdir]);
            dirInfo = dirInfo(~ismember({dirInfo.name}, {'.', '..'}));

            if isempty(dirInfo)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'No subdirectories found.', 'Error', 'Icon', 'error');
                return;
            end

            % Sortuj alfabetycznie
            [~, sidx] = sort({dirInfo.name});
            dirInfo = dirInfo(sidx);

            numFolders = numel(dirInfo);

            % Sprawdź referencję
            if isempty(app.reference_row) || isempty(app.reference_col) || ...
                    isempty(app.init_x) || isempty(app.init_z)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'Set starting position first.', 'No Reference', 'Icon', 'warning');
                return;
            end

            % Zbierz aktywne symbole (lampki włączone)
            active_symbols = {};
            keysList = keys(app.symbol_to_pos);
            for i = 1:numel(keysList)
                symbols = keysList{i};
                lamp_on = false;
                for k = 1:5
                    lampName = sprintf('Lamp_%s_%d', symbols, k);
                    if isprop(app, lampName) && strcmp(app.(lampName).Enable, 'on')
                        lamp_on = true;
                        break;
                    end
                end
                if lamp_on
                    active_symbols{end+1} = symbols;
                end
            end

            if isempty(active_symbols)
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'No active lines selected.', 'Nothing To Process', 'Icon', 'info');
                return;
            end

            fprintf('\n=== SIMULATION MODE ===\n');
            fprintf('Active symbols: %s\n', strjoin(active_symbols, ', '));
            fprintf('Found %d folders to process\n\n', numFolders);

            % Przypisz foldery do symboli
            % Zakładamy że nazwy folderów zawierają symbole pierwiastków
            folder_assignments = cell(numFolders, 1);
            for i = 1:numFolders
                folderName = dirInfo(i).name;
                % Znajdź który symbol pasuje do nazwy folderu
                matched_symbol = '';
                for j = 1:numel(active_symbols)
                    if contains(folderName, active_symbols{j}, 'IgnoreCase', true)
                        matched_symbol = active_symbols{j};
                        break;
                    end
                end

                folder_assignments{i} = matched_symbol;

                fprintf('Folder "%s" -> Element: %s\n', folderName, matched_symbol);
            end

            fprintf('\n');

            % Start sortowania po Z 
            xrl = py.importlib.import_module('xraylib');

            Zvals = nan(numFolders,1);
            for k = 1:numFolders
                sym = folder_assignments{k};
                if ~isempty(sym)
                    try
                        Zvals(k) = double(xrl.SymbolToAtomicNumber(sym));
                    catch
                        Zvals(k) = NaN;
                    end
                end
            end

            Zsort = Zvals;
            Zsort(isnan(Zsort)) = -Inf;

            [~, order] = sort(Zsort, 'descend');

            dirInfo = dirInfo(order);
            folder_assignments = folder_assignments(order);
            Zvals = Zvals(order);
            %%% - koniec sortowania po Z 


            % Przetwórz foldery
            processed_count = 0;
            for i = 1:numFolders
                if isempty(folder_assignments{i})
                    continue; % pomiń nieprzypisane
                end

                folderPath = fullfile(parentDir, dirInfo(i).name);
                current_symbol = folder_assignments{i};

                app.CurrentFolderLabel.Text = sprintf('Simulating: %s (Folder %d/%d)', ...
                    current_symbol, i, numFolders);
                drawnow limitrate;

                % Symuluj czas pomiaru (10 sekund) - ale nie dla pierwszego
                if processed_count > 0
                    fprintf('\n--- Waiting 10 seconds before next measurement ---\n');
                    for t = 0.5:-1:1
                        app.CurrentFolderLabel.Text = sprintf('Next in %d s: %s (%d/%d)', ...
                            t, current_symbol, i, numFolders);
                        drawnow limitrate;
                        pause(1);
                    end
                end

                fprintf('\n=== Processing folder %d/%d: %s -> %s ===\n', ...
                    i, numFolders, dirInfo(i).name, current_symbol);

                % Przetwórz folder
                try
                    app.processAutoMeasurement(folderPath, current_symbol);
                    processed_count = processed_count + 1;

                    if mod(processed_count, 1) == 0 || i == numFolders
                        try
                            app.UpdateEnergyFWHMcalibrationButtonPushed(struct('SilentMode', true));

                            % Aktualizuj czułość
                            app.updateSensitivityCalibration(true);
                        catch ME
                            disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                        end
                    end

                catch ME
                    warning('Processing failed for %s: %s', current_symbol, ME.message);
                    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
                end
            end
            app.saveSensitivityResultsToFiles();

            app.CurrentFolderLabel.Text = sprintf('Simulation Complete - Processed %d folders', processed_count);

            uialert(app.PolyXCalibrationAppUIFigure, ...
                sprintf('Successfully processed %d measurements.', processed_count), ...
                'Simulation Complete', 'Icon', 'success');
        end

        % Value changing function: Beam_energy_edit_field
        function Beam_energy_edit_fieldValueChanging(app, event)
            changingValue = event.Value;
            numValue = str2double(changingValue);

            elements = keys(app.symbol_to_pos);   % cellstr z unikalnymi symbolami z holdera
            elements = elements(:)';              % na wiersz

            xrl = py.importlib.import_module('xraylib');

            try
                app.Beam_E = wmE() ;
            catch ME
                app.Beam_E =numValue;
            end
            app.Beam_energy_edit_field.Value = num2str(app.Beam_E);

            assignin('base', 'Beam_E', app.Beam_E)


            % Logika do zaświecania odpowiednich lampeczek w zależności od
            % tego, jaką energię wiązki mamy

            % Pobieramy wartości energii poszczególnych linii dla
            % pierwiastków z holdera

            for i = 1:numel(elements)
                symbol = elements{i};
                Z = xrl.SymbolToAtomicNumber(symbol);
                standards.(symbol).energy_KA = double(xrl.LineEnergy(Z, xrl.KA_LINE)) * 1000; % eV
                if Z > 12
                    standards.(symbol).energy_KB = double(xrl.LineEnergy(Z, xrl.KB_LINE)) * 1000; % eV
                end
                if Z >= 30
                    standards.(symbol).energy_LA = double(xrl.LineEnergy(Z, xrl.LA_LINE)) * 1000; % eV
                    standards.(symbol).energy_LB = double(xrl.LineEnergy(Z, xrl.LB_LINE)) * 1000; % eV
                end
                if Z >= 56
                    standards.(symbol).energy_MA1 = double(xrl.LineEnergy(Z, xrl.MA1_LINE)) * 1000; % eV
                end

                standards.(symbol).edge_K = double(xrl.EdgeEnergy(Z, xrl.K_SHELL)) * 1000; % eV
                if Z >= 30
                    standards.(symbol).edge_L3 = double(xrl.EdgeEnergy(Z, xrl.L3_SHELL)) * 1000; % eV
                    standards.(symbol).edge_L2 = double(xrl.EdgeEnergy(Z, xrl.L2_SHELL)) * 1000; % eV
                end
                if Z >= 56
                    standards.(symbol).edge_M5 = double(xrl.EdgeEnergy(Z, xrl.M5_SHELL)) * 1000; % eV
                end

            end

            fields = fieldnames(app);

            for i = 1:numel(elements)
                symbol = elements{i};
                prefixBase = [symbol 'Button'];
                prefix_KA_button = ['K_alpha_' symbol];
                prefix_KB_button = ['K_beta_' symbol];
                prefix_LA_button = ['L_alpha_' symbol];
                prefix_LB_button = ['L_beta_' symbol];
                prefix_MA1_button = ['M_alpha_' symbol];

                % Sprawdzamy czy jakakolwiek linia jest dostępna dla tego pierwiastka
                any_line_available = false;

                for j = 1:numel(fields)
                    fieldName = fields{j};

                    if startsWith(fieldName, prefix_KA_button)
                        if app.Beam_E > standards.(symbol).edge_K
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 1); % Ka -> lampka 1
                            any_line_available = true;
                        else
                            app.(fieldName).Enable = 'off';
                        end
                    end

                    if startsWith(fieldName, prefix_KB_button) && isfield(standards.(symbol), 'energy_KB')
                        if app.Beam_E > standards.(symbol).edge_K
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 2); % KB -> lampka 2
                            any_line_available = true;
                        else
                            app.(fieldName).Enable = 'off';
                        end
                    end

                    if startsWith(fieldName, prefix_LA_button) && isfield(standards.(symbol), 'energy_LA')
                        if app.Beam_E > standards.(symbol).edge_L3
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 3); % LA -> lampka 3
                            any_line_available = true;
                        else
                            app.(fieldName).Enable = 'off';
                        end
                    end

                    if startsWith(fieldName, prefix_LB_button) && isfield(standards.(symbol), 'energy_LB')
                        if app.Beam_E > standards.(symbol).edge_L2
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 4); % LB -> lampka 4
                            any_line_available = true;
                        else
                            app.(fieldName).Enable = 'off';
                        end
                    end

                    if startsWith(fieldName, prefix_MA1_button) && isfield(standards.(symbol), 'energy_MA1')
                        if app.Beam_E > standards.(symbol).edge_M5
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                zapalLampke(app, symbol, 5); % MA1 -> lampka 5
                            any_line_available = true;
                        else
                            app.(fieldName).Enable = 'off';
                        end
                    end
                end

                % Włączamy duży przycisk jeśli jakakolwiek linia jest dostępna
                for j = 1:numel(fields)
                    fieldName = fields{j};
                    if startsWith(fieldName, prefixBase)
                        if any_line_available
                            app.(fieldName).Enable = 'on';
                            app.(fieldName).ButtonPushedFcn = @(src, event) ...
                                toggleWszystkieLampki(app, symbol);
                        else
                            app.(fieldName).Enable = 'off';
                            for idx = 1:5 % Sprzątamy lampki
                                lampName = sprintf('Lamp_%s_%d', symbol, idx);
                                if isprop(app, lampName), app.(lampName).Enable = 'off'; end
                            end
                        end
                        break; % Znaleźliśmy przycisk, więc można wyjść z pętli
                    end
                end
            end

        end

        % Value changed function: SetScaleToChannel
        function SetScaleToChannelValueChanged(app, event)
            app.CurrentScale = 'Channel';
            app.SetScaleToChannel.Value = true;
            app.SetScaleToEnergy.Value = false;

            plot_stored_spectrum(app, app.CurrentSpectrumIndex);
        end

        % Value changed function: SetScaleToEnergy
        function SetScaleToEnergyValueChanged(app, event)
            if app.IsCalibrated
                app.CurrentScale = 'Energy';
                app.SetScaleToChannel.Value = false;
                app.SetScaleToEnergy.Value = true;

                % Wyświetl widmo w nowej skali
                plot_stored_spectrum(app, app.CurrentSpectrumIndex);

                % Jeśli ROI są włączone, dodaj je
                if isprop(app,'ShowROIsCheckBox') && logical(app.ShowROIsCheckBox.Value)
                    plot_sensitivity_for_index(app, app.CurrentSpectrumIndex);
                end
            else
                uialert(app.PolyXCalibrationAppUIFigure, ...
                    'Cannot switch to energy scale - no calibration data available.', ...
                    'No Calibration', 'Icon', 'warning');
                app.SetScaleToChannel.Value = true;
                app.SetScaleToEnergy.Value = false;
                app.CurrentScale = 'Channel';
            end

        end

        % Value changed function: SetScaleToLinear
        function SetScaleToLinearValueChanged(app, event)
            app.CurrentYScale = 'linear';
            app.SetScaleToLinear.Value = true;
            app.SetScaleToLog.Value = false;
            refresh_current_display(app);
        end

        % Value changed function: SetScaleToLog
        function SetScaleToLogValueChanged(app, event)
            app.CurrentYScale = 'log';
            app.SetScaleToLinear.Value = false;
            app.SetScaleToLog.Value = true;
            refresh_current_display(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PolyXCalibrationAppUIFigure = uifigure('Visible', 'off');
            app.PolyXCalibrationAppUIFigure.Name = 'PolyX Calibration App';
            app.PolyXCalibrationAppUIFigure.Icon = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout = uigridlayout(app.PolyXCalibrationAppUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Tag = 'TabBarHTML';
            app.SetCalibrationTab = uitab(app.TabGroup);
            app.SetCalibrationTab.Title = 'Set Calibration';
            app.GridLayout32 = uigridlayout(app.SetCalibrationTab);
            app.CalibrationAppPanel_6 = uipanel(app.GridLayout32);
            app.CalibrationAppPanel_6.Title = 'Calibration App Panel';
            app.GridLayout29 = uigridlayout(app.CalibrationAppPanel_6);
            app.Polyx_theme_LOGO_5 = uiimage(app.GridLayout29);
            app.Polyx_theme_LOGO_5.ImageSource = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout30 = uigridlayout(app.GridLayout29);
            app.Image_9 = uiimage(app.GridLayout30);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'solaris_pionowe_white_eng.png');
            app.Image_10 = uiimage(app.GridLayout30);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'agh_znk_ngt_rgb_150ppi.png');
            app.GridLayout31 = uigridlayout(app.GridLayout29);
            app.ChoosestartingpositionButton = uibutton(app.GridLayout31, 'push');
            app.ChoosestartingpositionButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestartingpositionButtonPushed, true);
            app.ChoosestartingpositionButton.Text = {'Choose starting'; 'position'};
            app.BeamenergyeVPanel = uipanel(app.GridLayout31);
            app.BeamenergyeVPanel.Title = 'Beam energy [eV]';
            app.GridLayout33 = uigridlayout(app.BeamenergyeVPanel);
            app.Beam_energy_edit_field = uieditfield(app.GridLayout33, 'text');
            app.Beam_energy_edit_field.ValueChangingFcn = createCallbackFcn(app, @Beam_energy_edit_fieldValueChanging, true);
            app.Beam_energy_edit_field.Placeholder = '0';
            app.Beam_energy_edit_field.Value = '0';
            app.InitialCoordinatesPanel = uipanel(app.GridLayout31);
            app.InitialCoordinatesPanel.Title = 'Initial Coordinates';
            app.GridLayout34 = uigridlayout(app.InitialCoordinatesPanel);
            app.xmmPanel = uipanel(app.GridLayout34);
            app.xmmPanel.Title = 'x [mm]';
            app.GridLayout36 = uigridlayout(app.xmmPanel);
            app.initial_x_coordinates = uieditfield(app.GridLayout36, 'numeric');
            app.initial_x_coordinates.ValueChangedFcn = createCallbackFcn(app, @initial_x_coordinatesValueChanged, true);
            app.zmmPanel = uipanel(app.GridLayout34);
            app.zmmPanel.Title = 'z [mm]';
            app.GridLayout35 = uigridlayout(app.zmmPanel);
            app.initial_z_coordintates = uieditfield(app.GridLayout35, 'numeric');
            app.initial_z_coordintates.ValueChangedFcn = createCallbackFcn(app, @initial_z_coordintatesValueChanged, true);
            app.StartmeasurementButton = uibutton(app.GridLayout31, 'push');
            app.StartmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartmeasurementButtonPushed, true);
            app.StartmeasurementButton.Text = {'Start'; 'measurement'};
            app.GridLayout45 = uigridlayout(app.GridLayout31);
            app.ROIwidthLabel = uilabel(app.GridLayout45);
            app.ROIwidthLabel.Text = 'ROI width';
            app.ROIwidthDropDown = uidropdown(app.GridLayout45);
            app.ROIwidthDropDown.Items = {'±0.5·σ', '± σ', '± 2·σ', '± 3·σ'};
            app.ROIwidthDropDown.Value = '± σ';
            app.UpdateSensitivitycalibrationButton = uibutton(app.GridLayout31, 'push');
            app.UpdateSensitivitycalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSensitivitycalibrationButtonPushed, true);
            app.UpdateSensitivitycalibrationButton.Text = {'Update Sensitivity'; 'calibration'};
            app.UpdateEnergyFWHMcalibrationButton = uibutton(app.GridLayout31, 'push');
            app.UpdateEnergyFWHMcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEnergyFWHMcalibrationButtonPushed, true);
            app.UpdateEnergyFWHMcalibrationButton.Text = {'Update'; 'Energy & FWHM'; ' calibration'};
            app.GridLayout39 = uigridlayout(app.GridLayout32);
            app.AvailableStandardsPanel = uipanel(app.GridLayout39);
            app.AvailableStandardsPanel.Title = 'Available Standards';
            app.PickalllinesPanel = uipanel(app.AvailableStandardsPanel);
            app.PickalllinesPanel.Title = 'Pick all lines';
            app.pickALL_K_alpha_CheckBox = uicheckbox(app.PickalllinesPanel);
            app.pickALL_K_alpha_CheckBox.ValueChangedFcn = createCallbackFcn(app, @pickALL_K_alpha_CheckBoxValueChanged, true);
            app.pickALL_K_alpha_CheckBox.Text = 'Kα';
            app.pickALL_K_beta_CheckBox = uicheckbox(app.PickalllinesPanel);
            app.pickALL_K_beta_CheckBox.ValueChangedFcn = createCallbackFcn(app, @pickALL_K_beta_CheckBoxValueChanged, true);
            app.pickALL_K_beta_CheckBox.Text = 'Kβ';
            app.pickALL_L_alpha_CheckBox = uicheckbox(app.PickalllinesPanel);
            app.pickALL_L_alpha_CheckBox.ValueChangedFcn = createCallbackFcn(app, @pickALL_L_alpha_CheckBoxValueChanged, true);
            app.pickALL_L_alpha_CheckBox.Text = 'Lα';
            app.pickALL_L_beta_CheckBox = uicheckbox(app.PickalllinesPanel);
            app.pickALL_L_beta_CheckBox.ValueChangedFcn = createCallbackFcn(app, @pickALL_L_beta_CheckBoxValueChanged, true);
            app.pickALL_L_beta_CheckBox.Text = 'Lβ';
            app.pickALL_M_alpha_CheckBox = uicheckbox(app.PickalllinesPanel);
            app.pickALL_M_alpha_CheckBox.ValueChangedFcn = createCallbackFcn(app, @pickALL_M_alpha_CheckBoxValueChanged, true);
            app.pickALL_M_alpha_CheckBox.Text = 'M';
            app.CaButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CaButton.Enable = 'off';
            app.CaButton.Text = 'Ca';
            app.KButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.KButton.Enable = 'off';
            app.KButton.Text = 'K';
            app.ZnButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.ZnButton.Enable = 'off';
            app.ZnButton.Text = 'Zn';
            app.CuButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CuButton.Enable = 'off';
            app.CuButton.Text = 'Cu';
            app.ClButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.ClButton.Enable = 'off';
            app.ClButton.Text = 'Cl';
            app.NiButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.NiButton.Enable = 'off';
            app.NiButton.Text = 'Ni';
            app.SButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.SButton.Enable = 'off';
            app.SButton.Text = 'S';
            app.CoButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CoButton.Enable = 'off';
            app.CoButton.Text = 'Co';
            app.FeButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.FeButton.Enable = 'off';
            app.FeButton.Text = 'Fe';
            app.PButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.PButton.Enable = 'off';
            app.PButton.Text = 'P';
            app.SiButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.SiButton.Enable = 'off';
            app.SiButton.Text = 'Si';
            app.MnButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.MnButton.Enable = 'off';
            app.MnButton.Text = 'Mn';
            app.AlButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.AlButton.Enable = 'off';
            app.AlButton.Text = 'Al';
            app.CrButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CrButton.Enable = 'off';
            app.CrButton.Text = 'Cr';
            app.MgButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.MgButton.Enable = 'off';
            app.MgButton.Text = 'Mg';
            app.VButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.VButton.Enable = 'off';
            app.VButton.Text = 'V';
            app.NaButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.NaButton.Enable = 'off';
            app.NaButton.Text = 'Na';
            app.TiButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.TiButton.Enable = 'off';
            app.TiButton.Text = 'Ti';
            app.GaButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.GaButton.Tag = 'GaButton';
            app.GaButton.Enable = 'off';
            app.GaButton.Text = 'Ga';
            app.AsButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.AsButton.Tag = 'GaButton';
            app.AsButton.Enable = 'off';
            app.AsButton.Text = 'As';
            app.IButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.IButton.Enable = 'off';
            app.IButton.Text = 'I';
            app.CsButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CsButton.Enable = 'off';
            app.CsButton.Text = 'Cs';
            app.CeButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.CeButton.Enable = 'off';
            app.CeButton.Text = 'Ce';
            app.SeButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.SeButton.Enable = 'off';
            app.SeButton.Text = 'Se';
            app.BrButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.BrButton.Enable = 'off';
            app.BrButton.Text = 'Br';
            app.SmButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.SmButton.Enable = 'off';
            app.SmButton.Text = 'Sm';
            app.RbButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.RbButton.Enable = 'off';
            app.RbButton.Text = 'Rb';
            app.DyButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.DyButton.Enable = 'off';
            app.DyButton.Text = 'Dy';
            app.YbButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.YbButton.Enable = 'off';
            app.YbButton.Text = 'Yb';
            app.YButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.YButton.Enable = 'off';
            app.YButton.Text = 'Y';
            app.RhButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.RhButton.Enable = 'off';
            app.RhButton.Text = 'Rh';
            app.WButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.WButton.Enable = 'off';
            app.WButton.Text = 'W';
            app.InButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.InButton.Enable = 'off';
            app.InButton.Text = 'In';
            app.AuButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.AuButton.Enable = 'off';
            app.AuButton.Text = 'Au';
            app.TeButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.TeButton.Enable = 'off';
            app.TeButton.Text = 'Te';
            app.PbButton = uibutton(app.AvailableStandardsPanel, 'push');
            app.PbButton.Enable = 'off';
            app.PbButton.Text = 'Pb';
            app.Lamp_Zn_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Zn_3.Enable = 'off';
            app.Lamp_Zn_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Zn_2.Enable = 'off';
            app.L_alpha_Zn = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Zn.Enable = 'off';
            app.L_alpha_Zn.Text = 'Lα';
            app.K_beta_Zn = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Zn.Enable = 'off';
            app.K_beta_Zn.Text = 'Kβ';
            app.Lamp_Zn_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Zn_1.Enable = 'off';
            app.K_alpha_Zn = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Zn.Enable = 'off';
            app.K_alpha_Zn.Text = 'Kα';
            app.Lamp_Cu_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cu_2.Enable = 'off';
            app.K_beta_Cu = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Cu.Enable = 'off';
            app.K_beta_Cu.Text = 'Kβ';
            app.Lamp_Cu_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cu_1.Enable = 'off';
            app.K_alpha_Cu = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Cu.Enable = 'off';
            app.K_alpha_Cu.Text = 'Kα';
            app.Lamp_Ni_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ni_2.Enable = 'off';
            app.K_beta_Ni = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Ni.Enable = 'off';
            app.K_beta_Ni.Text = 'Kβ';
            app.Lamp_Ni_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ni_1.Enable = 'off';
            app.K_alpha_Ni = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Ni.Enable = 'off';
            app.K_alpha_Ni.Text = 'Kα';
            app.Lamp_Co_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Co_2.Enable = 'off';
            app.K_beta_Co = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Co.Enable = 'off';
            app.K_beta_Co.Text = 'Kβ';
            app.Lamp_Co_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Co_1.Enable = 'off';
            app.K_alpha_Co = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Co.Enable = 'off';
            app.K_alpha_Co.Text = 'Kα';
            app.Lamp_Fe_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Fe_2.Enable = 'off';
            app.K_beta_Fe = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Fe.Enable = 'off';
            app.K_beta_Fe.Text = 'Kβ';
            app.Lamp_Fe_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Fe_1.Enable = 'off';
            app.K_alpha_Fe = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Fe.Enable = 'off';
            app.K_alpha_Fe.Text = 'Kα';
            app.Lamp_Mn_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Mn_2.Enable = 'off';
            app.K_beta_Mn = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Mn.Enable = 'off';
            app.K_beta_Mn.Text = 'Kβ';
            app.Lamp_Mn_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Mn_1.Enable = 'off';
            app.K_alpha_Mn = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Mn.Enable = 'off';
            app.K_alpha_Mn.Text = 'Kα';
            app.Lamp_V_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_V_2.Enable = 'off';
            app.K_beta_V = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_V.Enable = 'off';
            app.K_beta_V.Text = 'Kβ';
            app.Lamp_V_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_V_1.Enable = 'off';
            app.K_alpha_V = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_V.Enable = 'off';
            app.K_alpha_V.Text = 'Kα';
            app.Lamp_Cr_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cr_2.Enable = 'off';
            app.K_beta_Cr = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Cr.Enable = 'off';
            app.K_beta_Cr.Text = 'Kβ';
            app.Lamp_Cr_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cr_1.Enable = 'off';
            app.K_alpha_Cr = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Cr.Enable = 'off';
            app.K_alpha_Cr.Text = 'Kα';
            app.Lamp_Ti_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ti_2.Enable = 'off';
            app.K_beta_Ti = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Ti.Enable = 'off';
            app.K_beta_Ti.Text = 'Kβ';
            app.Lamp_Ti_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ti_1.Enable = 'off';
            app.K_alpha_Ti = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Ti.Enable = 'off';
            app.K_alpha_Ti.Text = 'Kα';
            app.Lamp_Ca_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ca_2.Enable = 'off';
            app.K_beta_Ca = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Ca.Enable = 'off';
            app.K_beta_Ca.Text = 'Kβ';
            app.Lamp_Ca_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ca_1.Enable = 'off';
            app.K_alpha_Ca = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Ca.Enable = 'off';
            app.K_alpha_Ca.Text = 'Kα';
            app.Lamp_K_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_K_2.Enable = 'off';
            app.K_beta_K = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_K.Enable = 'off';
            app.K_beta_K.Text = 'Kβ';
            app.Lamp_K_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_K_1.Enable = 'off';
            app.K_alpha_K = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_K.Enable = 'off';
            app.K_alpha_K.Text = 'Kα';
            app.Lamp_Cl_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cl_2.Enable = 'off';
            app.K_beta_Cl = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Cl.Enable = 'off';
            app.K_beta_Cl.Text = 'Kβ';
            app.Lamp_Cl_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cl_1.Enable = 'off';
            app.K_alpha_Cl = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Cl.Enable = 'off';
            app.K_alpha_Cl.Text = 'Kα';
            app.Lamp_S_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_S_1.Enable = 'off';
            app.K_alpha_S = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_S.Enable = 'off';
            app.K_alpha_S.Text = 'Kα';
            app.Lamp_P_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_P_1.Enable = 'off';
            app.K_alpha_P = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_P.Enable = 'off';
            app.K_alpha_P.Text = 'Kα';
            app.Lamp_Si_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Si_1.Enable = 'off';
            app.K_alpha_Si = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Si.Enable = 'off';
            app.K_alpha_Si.Text = 'Kα';
            app.Lamp_Al_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Al_1.Enable = 'off';
            app.K_alpha_Al = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Al.Enable = 'off';
            app.K_alpha_Al.Text = 'Kα';
            app.Lamp_Mg_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Mg_1.Enable = 'off';
            app.K_alpha_Mg = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Mg.Enable = 'off';
            app.K_alpha_Mg.Text = 'Kα';
            app.Lamp_Na_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Na_1.Enable = 'off';
            app.K_alpha_Na = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Na.Enable = 'off';
            app.K_alpha_Na.Text = 'Kα';
            app.Lamp_Y_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Y_3.Enable = 'off';
            app.Lamp_Y_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Y_2.Enable = 'off';
            app.L_alpha_Y = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Y.Enable = 'off';
            app.L_alpha_Y.Text = 'Lα';
            app.K_beta_Y = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Y.Enable = 'off';
            app.K_beta_Y.Text = 'Kβ';
            app.Lamp_Y_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Y_1.Enable = 'off';
            app.K_alpha_Y = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Y.Enable = 'off';
            app.K_alpha_Y.Text = 'Kα';
            app.Lamp_Rb_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Rb_3.Enable = 'off';
            app.Lamp_Rb_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Rb_2.Enable = 'off';
            app.L_alpha_Rb = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Rb.Enable = 'off';
            app.L_alpha_Rb.Text = 'Lα';
            app.K_beta_Rb = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Rb.Enable = 'off';
            app.K_beta_Rb.Text = 'Kβ';
            app.Lamp_Rb_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Rb_1.Enable = 'off';
            app.K_alpha_Rb = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Rb.Enable = 'off';
            app.K_alpha_Rb.Text = 'Kα';
            app.Lamp_Br_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Br_3.Enable = 'off';
            app.Lamp_Br_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Br_2.Enable = 'off';
            app.L_alpha_Br = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Br.Enable = 'off';
            app.L_alpha_Br.Text = 'Lα';
            app.K_beta_Br = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Br.Enable = 'off';
            app.K_beta_Br.Text = 'Kβ';
            app.Lamp_Br_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Br_1.Enable = 'off';
            app.K_alpha_Br = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Br.Enable = 'off';
            app.K_alpha_Br.Text = 'Kα';
            app.Lamp_Se_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Se_3.Enable = 'off';
            app.Lamp_Se_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Se_2.Enable = 'off';
            app.L_alpha_Se = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Se.Enable = 'off';
            app.L_alpha_Se.Text = 'Lα';
            app.K_beta_Se = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Se.Enable = 'off';
            app.K_beta_Se.Text = 'Kβ';
            app.Lamp_Se_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Se_1.Enable = 'off';
            app.K_alpha_Se = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Se.Enable = 'off';
            app.K_alpha_Se.Text = 'Kα';
            app.Lamp_As_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_As_3.Enable = 'off';
            app.Lamp_As_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_As_2.Enable = 'off';
            app.L_alpha_As = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_As.Enable = 'off';
            app.L_alpha_As.Text = 'Lα';
            app.K_beta_As = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_As.Enable = 'off';
            app.K_beta_As.Text = 'Kβ';
            app.Lamp_As_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_As_1.Enable = 'off';
            app.K_alpha_As = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_As.Enable = 'off';
            app.K_alpha_As.Text = 'Kα';
            app.Lamp_Ga_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ga_3.Enable = 'off';
            app.Lamp_Ga_2 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ga_2.Enable = 'off';
            app.L_alpha_Ga = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Ga.Enable = 'off';
            app.L_alpha_Ga.Text = 'Lα';
            app.K_beta_Ga = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_beta_Ga.Enable = 'off';
            app.K_beta_Ga.Text = 'Kβ';
            app.Lamp_Ga_1 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ga_1.Enable = 'off';
            app.K_alpha_Ga = uibutton(app.AvailableStandardsPanel, 'push');
            app.K_alpha_Ga.Enable = 'off';
            app.K_alpha_Ga.Text = 'Kα';
            app.Lamp_Rh_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Rh_4.Enable = 'off';
            app.L_beta_Rh = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Rh.Enable = 'off';
            app.L_beta_Rh.Text = 'Lβ';
            app.Lamp_Rh_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Rh_3.Enable = 'off';
            app.L_alpha_Rh = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Rh.Enable = 'off';
            app.L_alpha_Rh.Text = 'Lα';
            app.Lamp_In_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_In_4.Enable = 'off';
            app.L_beta_In = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_In.Enable = 'off';
            app.L_beta_In.Text = 'Lβ';
            app.Lamp_In_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_In_3.Enable = 'off';
            app.L_alpha_In = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_In.Enable = 'off';
            app.L_alpha_In.Text = 'Lα';
            app.Lamp_Te_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Te_4.Enable = 'off';
            app.L_beta_Te = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Te.Enable = 'off';
            app.L_beta_Te.Text = 'Lβ';
            app.Lamp_Te_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Te_3.Enable = 'off';
            app.L_alpha_Te = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Te.Enable = 'off';
            app.L_alpha_Te.Text = 'Lα';
            app.Lamp_I_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_I_4.Enable = 'off';
            app.L_beta_I = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_I.Enable = 'off';
            app.L_beta_I.Text = 'Lβ';
            app.Lamp_I_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_I_3.Enable = 'off';
            app.L_alpha_I = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_I.Enable = 'off';
            app.L_alpha_I.Text = 'Lα';
            app.Lamp_Cs_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cs_4.Enable = 'off';
            app.L_beta_Cs = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Cs.Enable = 'off';
            app.L_beta_Cs.Text = 'Lβ';
            app.Lamp_Cs_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Cs_3.Enable = 'off';
            app.L_alpha_Cs = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Cs.Enable = 'off';
            app.L_alpha_Cs.Text = 'Lα';
            app.Lamp_Ce_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ce_5.Enable = 'off';
            app.Lamp_Ce_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ce_4.Enable = 'off';
            app.M_alpha_Ce = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Ce.Enable = 'off';
            app.M_alpha_Ce.Text = 'M';
            app.L_beta_Ce = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Ce.Enable = 'off';
            app.L_beta_Ce.Text = 'Lβ';
            app.Lamp_Ce_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Ce_3.Enable = 'off';
            app.L_alpha_Ce = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Ce.Enable = 'off';
            app.L_alpha_Ce.Text = 'Lα';
            app.Lamp_Sm_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Sm_5.Enable = 'off';
            app.Lamp_Sm_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Sm_4.Enable = 'off';
            app.M_alpha_Sm = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Sm.Enable = 'off';
            app.M_alpha_Sm.Text = 'M';
            app.L_beta_Sm = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Sm.Enable = 'off';
            app.L_beta_Sm.Text = 'Lβ';
            app.Lamp_Sm_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Sm_3.Enable = 'off';
            app.L_alpha_Sm = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Sm.Enable = 'off';
            app.L_alpha_Sm.Text = 'Lα';
            app.Lamp_Dy_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Dy_5.Enable = 'off';
            app.Lamp_Dy_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Dy_4.Enable = 'off';
            app.M_alpha_Dy = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Dy.Enable = 'off';
            app.M_alpha_Dy.Text = 'M';
            app.L_beta_Dy = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Dy.Enable = 'off';
            app.L_beta_Dy.Text = 'Lβ';
            app.Lamp_Dy_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Dy_3.Enable = 'off';
            app.L_alpha_Dy = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Dy.Enable = 'off';
            app.L_alpha_Dy.Text = 'Lα';
            app.Lamp_Yb_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Yb_5.Enable = 'off';
            app.Lamp_Yb_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Yb_4.Enable = 'off';
            app.M_alpha_Yb = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Yb.Enable = 'off';
            app.M_alpha_Yb.Text = 'M';
            app.L_beta_Yb = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Yb.Enable = 'off';
            app.L_beta_Yb.Text = 'Lβ';
            app.Lamp_Yb_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Yb_3.Enable = 'off';
            app.L_alpha_Yb = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Yb.Enable = 'off';
            app.L_alpha_Yb.Text = 'Lα';
            app.Lamp_W_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_W_5.Enable = 'off';
            app.Lamp_W_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_W_4.Enable = 'off';
            app.M_alpha_W = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_W.Enable = 'off';
            app.M_alpha_W.Text = 'M';
            app.L_beta_W = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_W.Enable = 'off';
            app.L_beta_W.Text = 'Lβ';
            app.Lamp_W_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_W_3.Enable = 'off';
            app.L_alpha_W = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_W.Enable = 'off';
            app.L_alpha_W.Text = 'Lα';
            app.Lamp_Au_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Au_5.Enable = 'off';
            app.Lamp_Au_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Au_4.Enable = 'off';
            app.M_alpha_Au = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Au.Enable = 'off';
            app.M_alpha_Au.Text = 'M';
            app.L_beta_Au = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Au.Enable = 'off';
            app.L_beta_Au.Text = 'Lβ';
            app.Lamp_Au_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Au_3.Enable = 'off';
            app.L_alpha_Au = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Au.Enable = 'off';
            app.L_alpha_Au.Text = 'Lα';
            app.Lamp_Pb_5 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Pb_5.Enable = 'off';
            app.Lamp_Pb_4 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Pb_4.Enable = 'off';
            app.M_alpha_Pb = uibutton(app.AvailableStandardsPanel, 'push');
            app.M_alpha_Pb.Enable = 'off';
            app.M_alpha_Pb.Text = 'M';
            app.L_beta_Pb = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_beta_Pb.Enable = 'off';
            app.L_beta_Pb.Text = 'Lβ';
            app.Lamp_Pb_3 = uilamp(app.AvailableStandardsPanel);
            app.Lamp_Pb_3.Enable = 'off';
            app.L_alpha_Pb = uibutton(app.AvailableStandardsPanel, 'push');
            app.L_alpha_Pb.Enable = 'off';
            app.L_alpha_Pb.Text = 'Lα';
            app.GridLayout40 = uigridlayout(app.GridLayout39);
            app.GridLayout41 = uigridlayout(app.GridLayout40);
            app.GAIN_1texteVLabel = uilabel(app.GridLayout41);
            app.GAIN_1texteVLabel.Interpreter = 'latex';
            app.GAIN_1texteVLabel.Text = 'GAIN$_{1}$ [eV]$=$';
            app.GAIN1_sensitivity_tab_value = uieditfield(app.GridLayout41, 'text');
            app.GAIN1_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @GAIN1_sensitivity_tab_valueValueChanging, true);
            app.ZERO_1eVLabel = uilabel(app.GridLayout41);
            app.ZERO_1eVLabel.Interpreter = 'latex';
            app.ZERO_1eVLabel.Text = 'ZERO$_{1}$ [eV]$=$';
            app.ZERO1_sensitivity_tab_value = uieditfield(app.GridLayout41, 'text');
            app.ZERO1_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @ZERO1_sensitivity_tab_valueValueChanging, true);
            app.GridLayout42 = uigridlayout(app.GridLayout40);
            app.GAIN_1texteVLabel_2 = uilabel(app.GridLayout42);
            app.GAIN_1texteVLabel_2.Interpreter = 'latex';
            app.GAIN_1texteVLabel_2.Text = 'GAIN$_{2}$ [eV]$=$';
            app.GAIN2_sensitivity_tab_value = uieditfield(app.GridLayout42, 'text');
            app.GAIN2_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @GAIN2_sensitivity_tab_valueValueChanging, true);
            app.ZERO_2texteVLabel = uilabel(app.GridLayout42);
            app.ZERO_2texteVLabel.Interpreter = 'latex';
            app.ZERO_2texteVLabel.Text = 'ZERO$_{2}$ [eV]$=$';
            app.ZERO2_sensitivity_tab_value = uieditfield(app.GridLayout42, 'text');
            app.ZERO2_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @ZERO2_sensitivity_tab_valueValueChanging, true);
            app.GridLayout43 = uigridlayout(app.GridLayout40);
            app.GAIN_1texteVLabel_3 = uilabel(app.GridLayout43);
            app.GAIN_1texteVLabel_3.Interpreter = 'latex';
            app.GAIN_1texteVLabel_3.Text = 'NOISE$_{1}$ [eV]$=$';
            app.NOISE1_sensitivity_tab_value = uieditfield(app.GridLayout43, 'text');
            app.NOISE1_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @NOISE1_sensitivity_tab_valueValueChanging, true);
            app.GAIN_1texteVLabel_5 = uilabel(app.GridLayout43);
            app.GAIN_1texteVLabel_5.Interpreter = 'latex';
            app.GAIN_1texteVLabel_5.Text = 'FANO$_{1}=$';
            app.FANO1_sensitivity_tab_value = uieditfield(app.GridLayout43, 'text');
            app.FANO1_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @FANO1_sensitivity_tab_valueValueChanging, true);
            app.GridLayout44 = uigridlayout(app.GridLayout40);
            app.GAIN_1texteVLabel_4 = uilabel(app.GridLayout44);
            app.GAIN_1texteVLabel_4.Interpreter = 'latex';
            app.GAIN_1texteVLabel_4.Text = 'NOISE$_{2}$ [eV]$=$';
            app.NOISE2_sensitivity_tab_value = uieditfield(app.GridLayout44, 'text');
            app.NOISE2_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @NOISE2_sensitivity_tab_valueValueChanging, true);
            app.GAIN_1texteVLabel_6 = uilabel(app.GridLayout44);
            app.GAIN_1texteVLabel_6.Interpreter = 'latex';
            app.GAIN_1texteVLabel_6.Text = 'FANO$_{2}=$';
            app.FANO2_sensitivity_tab_value = uieditfield(app.GridLayout44, 'text');
            app.FANO2_sensitivity_tab_value.ValueChangingFcn = createCallbackFcn(app, @FANO2_sensitivity_tab_valueValueChanging, true);
            app.ViewDataTab = uitab(app.TabGroup);
            app.ViewDataTab.Title = 'View Data';
            app.Grid_Panel_reszta = uigridlayout(app.ViewDataTab);
            app.CalibrationAppPanel = uipanel(app.Grid_Panel_reszta);
            app.CalibrationAppPanel.Title = 'Calibration App Panel';
            app.GridLayout13 = uigridlayout(app.CalibrationAppPanel);
            app.Polyx_theme_LOGO = uiimage(app.GridLayout13);
            app.Polyx_theme_LOGO.Tag = 'Unicorn_MainPanel';
            app.Polyx_theme_LOGO.ImageSource = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.Image_4 = uiimage(app.GridLayout15);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'agh_znk_ngt_rgb_150ppi.png');
            app.Image_3 = uiimage(app.GridLayout15);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'solaris_pionowe_white_eng.png');
            app.GridLayout16 = uigridlayout(app.GridLayout13);
            app.LoadDirectoryButton = uibutton(app.GridLayout16, 'push');
            app.LoadDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDirectoryButtonPushed, true);
            app.LoadDirectoryButton.Text = {'Load'; 'Directory'};
            app.UpdateCalibrationButton = uibutton(app.GridLayout16, 'push');
            app.UpdateCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCalibrationButtonPushed, true);
            app.UpdateCalibrationButton.Text = 'Update Calibration';
            app.GridLayout65 = uigridlayout(app.GridLayout16);
            app.Prev_sp_button = uibutton(app.GridLayout65, 'push');
            app.Prev_sp_button.ButtonPushedFcn = createCallbackFcn(app, @Prev_sp_buttonButtonPushed, true);
            app.Prev_sp_button.Text = '◄';
            app.Next_sp_button = uibutton(app.GridLayout65, 'push');
            app.Next_sp_button.ButtonPushedFcn = createCallbackFcn(app, @Next_sp_buttonButtonPushed, true);
            app.Next_sp_button.Text = '►';
            app.SetscalePanel = uipanel(app.GridLayout16);
            app.SetscalePanel.Title = 'Set scale';
            app.GridLayout66 = uigridlayout(app.SetscalePanel);
            app.SetScaleToChannel = uibutton(app.GridLayout66, 'state');
            app.SetScaleToChannel.ValueChangedFcn = createCallbackFcn(app, @SetScaleToChannelValueChanged, true);
            app.SetScaleToChannel.Text = 'Channel';
            app.SetScaleToEnergy = uibutton(app.GridLayout66, 'state');
            app.SetScaleToEnergy.ValueChangedFcn = createCallbackFcn(app, @SetScaleToEnergyValueChanged, true);
            app.SetScaleToEnergy.Text = 'Energy';
            app.SetScaleToLinear = uibutton(app.GridLayout66, 'state');
            app.SetScaleToLinear.ValueChangedFcn = createCallbackFcn(app, @SetScaleToLinearValueChanged, true);
            app.SetScaleToLinear.Text = 'Linear';
            app.SetScaleToLog = uibutton(app.GridLayout66, 'state');
            app.SetScaleToLog.ValueChangedFcn = createCallbackFcn(app, @SetScaleToLogValueChanged, true);
            app.SetScaleToLog.Text = 'Log';
            app.ShowROIsCheckBox = uicheckbox(app.GridLayout16);
            app.ShowROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROIsCheckBoxValueChanged, true);
            app.ShowROIsCheckBox.Text = 'Show ROIs';
            app.Panel = uipanel(app.Grid_Panel_reszta);
            app.GridLayout69 = uigridlayout(app.Panel);
            app.Detector1Panel = uipanel(app.GridLayout69);
            app.Detector1Panel.Title = 'Detector 1';
            app.GridLayout67 = uigridlayout(app.Detector1Panel);
            app.MeanPINLabel_4 = uilabel(app.GridLayout67);
            app.MeanPINLabel_4.Interpreter = 'latex';
            app.MeanPINLabel_4.Text = 'Live time [s]:';
            app.text_live_time_d1 = uitextarea(app.GridLayout67);
            app.text_live_time_d1.Editable = 'off';
            app.MeanPINLabel_5 = uilabel(app.GridLayout67);
            app.MeanPINLabel_5.Interpreter = 'latex';
            app.MeanPINLabel_5.Text = 'ICR [cps]:';
            app.text_ICR_sum_d1 = uitextarea(app.GridLayout67);
            app.text_ICR_sum_d1.Editable = 'off';
            app.MeanPINLabel_11 = uilabel(app.GridLayout67);
            app.MeanPINLabel_11.Interpreter = 'latex';
            app.MeanPINLabel_11.Text = 'OCR [cps]:';
            app.text_OCR_sum_d1 = uitextarea(app.GridLayout67);
            app.text_OCR_sum_d1.Editable = 'off';
            app.MeanPINLabel_3 = uilabel(app.GridLayout67);
            app.MeanPINLabel_3.Interpreter = 'latex';
            app.MeanPINLabel_3.Text = 'Real Time [s]:';
            app.text_real_time_d1 = uitextarea(app.GridLayout67);
            app.text_real_time_d1.Editable = 'off';
            app.GridLayout70 = uigridlayout(app.GridLayout69);
            app.MeanPINLabel_13 = uilabel(app.GridLayout70);
            app.MeanPINLabel_13.Interpreter = 'latex';
            app.MeanPINLabel_13.Text = '$\overline{PIN}$ value [V]: ';
            app.text_mean_PIN = uitextarea(app.GridLayout70);
            app.text_mean_PIN.Editable = 'off';
            app.MeanPINLabel = uilabel(app.GridLayout70);
            app.MeanPINLabel.Interpreter = 'latex';
            app.MeanPINLabel.Text = '$\overline{I_0}$ value [V]: ';
            app.text_mean_I0 = uitextarea(app.GridLayout70);
            app.text_mean_I0.Editable = 'off';
            app.Detector2Panel = uipanel(app.GridLayout69);
            app.Detector2Panel.Title = 'Detector 2';
            app.GridLayout58 = uigridlayout(app.Detector2Panel);
            app.MeanPINLabel_8 = uilabel(app.GridLayout58);
            app.MeanPINLabel_8.Interpreter = 'latex';
            app.MeanPINLabel_8.Text = 'Live time [s]:';
            app.text_live_time_d2 = uitextarea(app.GridLayout58);
            app.text_live_time_d2.Editable = 'off';
            app.MeanPINLabel_9 = uilabel(app.GridLayout58);
            app.MeanPINLabel_9.Interpreter = 'latex';
            app.MeanPINLabel_9.Text = 'ICR [cps]:';
            app.text_ICR_sum_d2 = uitextarea(app.GridLayout58);
            app.text_ICR_sum_d2.Editable = 'off';
            app.MeanPINLabel_12 = uilabel(app.GridLayout58);
            app.MeanPINLabel_12.Interpreter = 'latex';
            app.MeanPINLabel_12.Text = 'OCR [cps]:';
            app.text_OCR_sum_d2 = uitextarea(app.GridLayout58);
            app.text_OCR_sum_d2.Editable = 'off';
            app.MeanPINLabel_7 = uilabel(app.GridLayout58);
            app.MeanPINLabel_7.Interpreter = 'latex';
            app.MeanPINLabel_7.Text = 'Real Time [s]:';
            app.text_real_time_d2 = uitextarea(app.GridLayout58);
            app.text_real_time_d2.Editable = 'off';
            app.GridLayout68 = uigridlayout(app.Grid_Panel_reszta);
            app.UIAxes_det_2 = uiaxes(app.GridLayout68);
            xlabel(app.UIAxes_det_2, 'Channel')
            ylabel(app.UIAxes_det_2, 'Counts')
            zlabel(app.UIAxes_det_2, 'Z')
            app.UIAxes_det_1 = uiaxes(app.GridLayout68);
            xlabel(app.UIAxes_det_1, 'Channel')
            ylabel(app.UIAxes_det_1, 'Counts')
            zlabel(app.UIAxes_det_1, 'Z')
            app.CurrentFolderLabel = uilabel(app.GridLayout68);
            app.CurrentFolderLabel.Text = 'CurrentFolderLabel';
            app.SensitivityResultsTab = uitab(app.TabGroup);
            app.SensitivityResultsTab.Title = 'Sensitivity Results';
            app.GridLayout46 = uigridlayout(app.SensitivityResultsTab);
            app.CalibrationAppPanel_7 = uipanel(app.GridLayout46);
            app.CalibrationAppPanel_7.Title = 'Calibration App Panel';
            app.GridLayout18_2 = uigridlayout(app.CalibrationAppPanel_7);
            app.Polyx_theme_LOGO_6 = uiimage(app.GridLayout18_2);
            app.Polyx_theme_LOGO_6.ImageSource = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout19_2 = uigridlayout(app.GridLayout18_2);
            app.Image_11 = uiimage(app.GridLayout19_2);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'solaris_pionowe_white_eng.png');
            app.Image_12 = uiimage(app.GridLayout19_2);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'agh_znk_ngt_rgb_150ppi.png');
            app.GridLayout20_2 = uigridlayout(app.GridLayout18_2);
            app.GridLayout21_2 = uigridlayout(app.GridLayout20_2);
            app.Previous_SensitivityLine_results = uibutton(app.GridLayout21_2, 'push');
            app.Previous_SensitivityLine_results.ButtonPushedFcn = createCallbackFcn(app, @Previous_SensitivityLine_resultsButtonPushed, true);
            app.Previous_SensitivityLine_results.Text = '◄';
            app.Next_SensitivityLine_results = uibutton(app.GridLayout21_2, 'push');
            app.Next_SensitivityLine_results.ButtonPushedFcn = createCallbackFcn(app, @Next_SensitivityLine_resultsButtonPushed, true);
            app.Next_SensitivityLine_results.Text = '►';
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.UIAxes_Sensitivity2 = uiaxes(app.GridLayout47);
            xlabel(app.UIAxes_Sensitivity2, 'Atomic Number (Z)')
            ylabel(app.UIAxes_Sensitivity2, 'Sensitivity [counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]')
            zlabel(app.UIAxes_Sensitivity2, 'Z')
            app.UIAxes_Sensitivity3 = uiaxes(app.GridLayout47);
            xlabel(app.UIAxes_Sensitivity3, 'Energy [keV]')
            ylabel(app.UIAxes_Sensitivity3, 'Sensitivity [counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]')
            zlabel(app.UIAxes_Sensitivity3, 'Z')
            app.UIAxes_Sensitivity1 = uiaxes(app.GridLayout47);
            xlabel(app.UIAxes_Sensitivity1, 'Atomic Number (Z)')
            ylabel(app.UIAxes_Sensitivity1, 'Sensitivity [counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]')
            zlabel(app.UIAxes_Sensitivity1, 'Z')
            app.UIAxes_Sensitivity4 = uiaxes(app.GridLayout47);
            xlabel(app.UIAxes_Sensitivity4, 'Energy [keV]')
            ylabel(app.UIAxes_Sensitivity4, 'Sensitivity [counts·s⁻¹·V⁻¹·(μg·cm⁻²)⁻¹]')
            zlabel(app.UIAxes_Sensitivity4, 'Z')
            app.EnergyCalibrationTab = uitab(app.TabGroup);
            app.EnergyCalibrationTab.Title = 'Energy Calibration';
            app.GridPanel_reszta_etab = uigridlayout(app.EnergyCalibrationTab);
            app.CalibrationAppPanel_3 = uipanel(app.GridPanel_reszta_etab);
            app.CalibrationAppPanel_3.Title = 'Calibration App Panel';
            app.GridLayout22 = uigridlayout(app.CalibrationAppPanel_3);
            app.Polyx_theme_LOGO_3 = uiimage(app.GridLayout22);
            app.Polyx_theme_LOGO_3.ImageSource = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.Image_5 = uiimage(app.GridLayout23);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'solaris_pionowe_white_eng.png');
            app.Image_6 = uiimage(app.GridLayout23);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'agh_znk_ngt_rgb_150ppi.png');
            app.GridLayout48 = uigridlayout(app.GridLayout22);
            app.simulatelivemeasurementButton = uibutton(app.GridLayout48, 'push');
            app.simulatelivemeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @simulatelivemeasurementButtonPushed, true);
            app.simulatelivemeasurementButton.Visible = 'off';
            app.simulatelivemeasurementButton.Text = 'simulate live measurement';
            app.Grid_wykresy_energytab = uigridlayout(app.GridPanel_reszta_etab);
            app.UIAxes_E_cal_d1 = uiaxes(app.Grid_wykresy_energytab);
            title(app.UIAxes_E_cal_d1, 'Detector 1 Calibration')
            xlabel(app.UIAxes_E_cal_d1, 'Channel')
            ylabel(app.UIAxes_E_cal_d1, 'Energy')
            zlabel(app.UIAxes_E_cal_d1, 'Z')
            app.UIAxes_error_d2 = uiaxes(app.Grid_wykresy_energytab);
            title(app.UIAxes_error_d2, 'Detector 1 Calibration')
            ylabel(app.UIAxes_error_d2, 'Energy')
            zlabel(app.UIAxes_error_d2, 'Z')
            app.UIAxes_error_d1 = uiaxes(app.Grid_wykresy_energytab);
            title(app.UIAxes_error_d1, 'Detector 1 Calibration')
            ylabel(app.UIAxes_error_d1, 'Energy')
            zlabel(app.UIAxes_error_d1, 'Z')
            app.UIAxes_E_cal_d2 = uiaxes(app.Grid_wykresy_energytab);
            title(app.UIAxes_E_cal_d2, 'Detector 2 Calibration')
            xlabel(app.UIAxes_E_cal_d2, 'Channel')
            ylabel(app.UIAxes_E_cal_d2, 'Energy')
            zlabel(app.UIAxes_E_cal_d2, 'Z')
            app.GridLayout6 = uigridlayout(app.Grid_wykresy_energytab);
            app.Calib_Function_text_d1 = uitextarea(app.GridLayout6);
            app.Calib_Function_text_d1.Editable = 'off';
            app.Calib_Function_label_d1 = uilabel(app.GridLayout6);
            app.Calib_Function_label_d1.Interpreter = 'latex';
            app.Calib_Function_label_d1.Text = '$E_{1} \text{ [keV]} =$';
            app.E_Calib_Function_label_d1 = uilabel(app.GridLayout6);
            app.E_Calib_Function_label_d1.Interpreter = 'latex';
            app.E_Calib_Function_label_d1.Text = '';
            app.GridLayout6_2 = uigridlayout(app.Grid_wykresy_energytab);
            app.Calib_Function_text_d2 = uitextarea(app.GridLayout6_2);
            app.Calib_Function_text_d2.Editable = 'off';
            app.Calib_Function_label_d2 = uilabel(app.GridLayout6_2);
            app.Calib_Function_label_d2.Interpreter = 'latex';
            app.Calib_Function_label_d2.Text = '$E_{2} \text{ [keV]} =$';
            app.E_Calib_Function_label_d2 = uilabel(app.GridLayout6_2);
            app.E_Calib_Function_label_d2.Interpreter = 'latex';
            app.E_Calib_Function_label_d2.Text = '';
            app.FWHMCalibrationTab = uitab(app.TabGroup);
            app.FWHMCalibrationTab.Title = 'FWHM Calibration';
            app.GridLayout7 = uigridlayout(app.FWHMCalibrationTab);
            app.CalibrationAppPanel_4 = uipanel(app.GridLayout7);
            app.CalibrationAppPanel_4.Title = 'Calibration App Panel';
            app.GridLayout24 = uigridlayout(app.CalibrationAppPanel_4);
            app.Polyx_theme_LOGO_4 = uiimage(app.GridLayout24);
            app.Polyx_theme_LOGO_4.ImageSource = fullfile(pathToMLAPP, 'unicorn_logo.png');
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.Image_7 = uiimage(app.GridLayout25);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'solaris_pionowe_white_eng.png');
            app.Image_8 = uiimage(app.GridLayout25);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'agh_znk_ngt_rgb_150ppi.png');
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.UIAxes_FWHM_cal_d1 = uiaxes(app.GridLayout8);
            title(app.UIAxes_FWHM_cal_d1, 'Detector 1 Calibration')
            xlabel(app.UIAxes_FWHM_cal_d1, 'Channel')
            ylabel(app.UIAxes_FWHM_cal_d1, 'Energy')
            zlabel(app.UIAxes_FWHM_cal_d1, 'Z')
            app.UIAxes_FWHM_error_d2 = uiaxes(app.GridLayout8);
            title(app.UIAxes_FWHM_error_d2, 'Detector 1 Calibration')
            ylabel(app.UIAxes_FWHM_error_d2, 'Energy')
            zlabel(app.UIAxes_FWHM_error_d2, 'Z')
            app.UIAxes_FWHM_error_d1 = uiaxes(app.GridLayout8);
            title(app.UIAxes_FWHM_error_d1, 'Detector 1 Calibration')
            ylabel(app.UIAxes_FWHM_error_d1, 'Energy')
            zlabel(app.UIAxes_FWHM_error_d1, 'Z')
            app.UIAxes_FWHM_cal_d2 = uiaxes(app.GridLayout8);
            title(app.UIAxes_FWHM_cal_d2, 'Detector 1 Calibration')
            xlabel(app.UIAxes_FWHM_cal_d2, 'Channel')
            ylabel(app.UIAxes_FWHM_cal_d2, 'Energy')
            zlabel(app.UIAxes_FWHM_cal_d2, 'Z')
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.FWHM_Calib_Function_label_d1 = uilabel(app.GridLayout9);
            app.FWHM_Calib_Function_label_d1.Interpreter = 'latex';
            app.FWHM_Calib_Function_label_d1.Text = '$FWHM \text{ [keV]}=$';
            app.FWHM_Calib_Function_text_d1 = uitextarea(app.GridLayout9);
            app.FWHM_Calib_Function_text_d1.Editable = 'off';
            app.FWHM_Function_label_d1 = uilabel(app.GridLayout9);
            app.FWHM_Function_label_d1.Interpreter = 'latex';
            app.FWHM_Function_label_d1.Text = '';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.FWHM_Calib_Function_text_d2 = uitextarea(app.GridLayout10);
            app.FWHM_Calib_Function_text_d2.Editable = 'off';
            app.FWHM_Calib_Function_label_d2 = uilabel(app.GridLayout10);
            app.FWHM_Calib_Function_label_d2.Interpreter = 'latex';
            app.FWHM_Calib_Function_label_d2.Text = '$FWHM \text{ [keV]}=$';
            app.FWHM_Function_label_d2 = uilabel(app.GridLayout10);
            app.FWHM_Function_label_d2.Interpreter = 'latex';
            app.FWHM_Function_label_d2.Text = '';
            app.ContextMenu = uicontextmenu(app.PolyXCalibrationAppUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.PolyXCalibrationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroXRF_APP_26_03_2026_WERSJA_LINIOWA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolyXCalibrationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolyXCalibrationAppUIFigure)
        end
    end
end